classdef BiomassAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IdiomaLanguageMenu       matlab.ui.container.Menu
        EnglishMenu              matlab.ui.container.Menu
        EspaolMenu               matlab.ui.container.Menu
        Panel                    matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        Image2                   matlab.ui.control.Image
        Hyperlink                matlab.ui.control.Hyperlink
        AccederButton            matlab.ui.control.StateButton
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        AnalizadordelRecursodeBiomasaBiomassanalyser10Label  matlab.ui.control.Label
        Image_3                  matlab.ui.control.Image
        Image_2                  matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        AccederLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        idioma;
        Inicioapp;

    end
    
    methods (Access = private)
        
        function guardaridiomas(app,idioma)


              app.AccederLabel.Text = buscador(app, idioma, 'tituloAcceder');
              app.ContraseaEditFieldLabel.Text = buscador(app, idioma, 'contraField'); 
              app.Hyperlink.Text = buscador(app, idioma, 'hiper');
              app.AccederButton.Text = buscador(app, idioma, 'botonAcceder');
              app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label.Text = buscador(app, idioma, 'titulo');
              

        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idioma)
            app.idioma = "Español";
            guardaridiomas(app,app.idioma);

        end

        % Callback function
        function SiButtonPushed(app, event)

        end

        % Callback function
        function NoButtonPushed(app, event)

        end

        % Callback function
        function BiomasaPecuariayAgricolaButtonPushed(app, event)

        end

        % Callback function
        function InformacionButtonPushed(app, event)

        end

        % Callback function
        function BiomasaForestalButtonPushed(app, event)

        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";    

        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";

        end

        % Value changed function: AccederButton
        function LoginButton(app, event)
    ps = app.ContraseaEditField.Value;
    prePS = ['BIOMASA',num2str(23)];
    if strcmp(ps,prePS)
        app.Inicioapp = Inicio(app,app.idioma);
         delete(app);
    else
        msgbox('Incorrect password.'); %if incorrect
    end
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IdiomaLanguageMenu = uimenu(app.UIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Checked = 'on';
            app.EspaolMenu.Text = 'Español';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.AccederLabel = uilabel(app.Tab);
            app.AccederLabel.Text = {'Ingrese la contraseña asignada en caso de no tenerla '; 'ingrese al link ubicado en la parte superior de la ventana.'};
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'UDistrital.png');
            app.Image_2 = uiimage(app.Tab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'ingelectrica.png');
            app.Image_3 = uiimage(app.Tab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'images', 'lifae.png');
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label = uilabel(app.Tab);
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label.Text = {'Analizador del Recurso de Biomasa'; '(Biomass analyser 1.0)'};
            app.ContraseaEditFieldLabel = uilabel(app.Tab);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.Tab, 'text');
            app.AccederButton = uibutton(app.Tab, 'state');
            app.AccederButton.ValueChangedFcn = createCallbackFcn(app, @LoginButton, true);
            app.AccederButton.Text = 'Acceder';
            app.Hyperlink = uihyperlink(app.Tab);
            app.Hyperlink.URL = 'https://forms.gle/yLM9rC6PfHZJBNes9';
            app.Hyperlink.Text = 'OBTENER CONTRASEÑA';
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BiomassAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end