classdef Inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IdiomaLanguageMenu              matlab.ui.container.Menu
        EnglishMenu                     matlab.ui.container.Menu
        EspaolMenu                      matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        AnalizadordelRecursodeBiomasaBiomassanalyser10Label  matlab.ui.control.Label
        InstructivoButton               matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        NoButton                        matlab.ui.control.Button
        SiButton                        matlab.ui.control.Button
        BienvenidousuariEslaprimeravezusandoelaplicativoLabel  matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        InstructivoButton_2             matlab.ui.control.Button
        VolverButton                    matlab.ui.control.Button
        SalirButton                     matlab.ui.control.Button
        GasificadorLabel                matlab.ui.control.Label
        BiodigestorLabel                matlab.ui.control.Label
        InformacionButton               matlab.ui.control.Button
        BiomasaPecuariayAgricolaButton  matlab.ui.control.Button
        AnalizadordelRecursodeBiomasaBiomassanalyser10Label_2  matlab.ui.control.Label
        BiomasaForestalButton           matlab.ui.control.Button
        Image_6                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        idioma;
        Informacionapp;
        Instructivo2app;
        Proceso_Biodigestorapp;
        Proceso_Gasificadorapp;
        Accesoapp;
    end
    
    methods (Access = private)
        
        function guardaridiomas(app,idioma)

            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label.Text = buscador(app, idioma, 'titulo');
            app.InstructivoButton.Text = buscador(app, idioma, 'botonInstructivo');
            app.NoButton.Text = buscador(app, idioma, 'botonNo');
            app.SiButton.Text = buscador(app, idioma, 'botonSi');
            app.BienvenidousuariEslaprimeravezusandoelaplicativoLabel.Text = buscador(app, idioma, 'labelBienvenida');
            app.InformacionButton.Text = buscador(app, idioma, 'botonInformacion');
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label_2.Text = buscador(app, idioma, 'titulo');
            app.BiomasaPecuariayAgricolaButton.Text = buscador(app, idioma, 'botonBiomasaPecuariaAgricola3');
            app.BiomasaForestalButton.Text = buscador(app, idioma, 'botonBiomasaForestal');
            app.BiodigestorLabel.Text = buscador(app,idioma, 'tituloBiodigestor');
            app.GasificadorLabel.Text = buscador(app,idioma, 'tituloGasificador');
            app.SalirButton.Text = buscador(app,idioma, 'botonSalir1');
            app.VolverButton.Text = buscador(app,idioma, 'botonVolver1');
            app.InstructivoButton_2.Text = buscador(app, idioma, 'botonInstructivo');


        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, accesoapp, idioma)
            %app.idioma = "Español";
            %guardaridiomas(app,app.idioma);


            app.idioma = idioma;
            app.Accesoapp = accesoapp;
            if app.idioma == "Español"
                app.EspaolMenu.Checked = "on";
               app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if app.idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end

        end

        % Button pushed function: InstructivoButton, SiButton
        function SiButtonPushed(app, event)
     %        Instructivo2
     app.TabGroup.SelectedTab=app.Tab2;
     app.Instructivo2app = Instructivo2(app,app.idioma);
     
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Button pushed function: BiomasaPecuariayAgricolaButton
        function BiomasaPecuariayAgricolaButtonPushed(app, event)

            app.Proceso_Biodigestorapp = Proceso_Biodigestor(app,app.idioma);
        end

        % Button pushed function: InformacionButton
        function InformacionButtonPushed(app, event)

            app.Informacionapp = informacion(app,app.idioma);
        end

        % Button pushed function: BiomasaForestalButton
        function BiomasaForestalButtonPushed(app, event)

            app.Proceso_Gasificadorapp = Proceso_gasificador(app,app.idioma);    

        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";    

        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";

        end

        % Button pushed function: SalirButton
        function salirbuton(app, event)
                        delete(app)
        end

        % Button pushed function: VolverButton
        function volverbuton(app, event)
        app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: InstructivoButton_2
        function instructivobuton2(app, event)
            app.Instructivo2app = Instructivo2(app,app.idioma);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IdiomaLanguageMenu = uimenu(app.UIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Checked = 'on';
            app.EspaolMenu.Text = 'Español';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.BienvenidousuariEslaprimeravezusandoelaplicativoLabel = uilabel(app.Tab);
            app.BienvenidousuariEslaprimeravezusandoelaplicativoLabel.Text = 'Bienvenido usuari@ ¿Es la primera vez usando el aplicativo?';
            app.SiButton = uibutton(app.Tab, 'push');
            app.SiButton.ButtonPushedFcn = createCallbackFcn(app, @SiButtonPushed, true);
            app.SiButton.Text = 'Si';
            app.NoButton = uibutton(app.Tab, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'UDistrital.png');
            app.Image_2 = uiimage(app.Tab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'ingelectrica.png');
            app.Image_3 = uiimage(app.Tab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'images', 'lifae.png');
            app.InstructivoButton = uibutton(app.Tab, 'push');
            app.InstructivoButton.ButtonPushedFcn = createCallbackFcn(app, @SiButtonPushed, true);
            app.InstructivoButton.Icon = fullfile(pathToMLAPP, 'images', 'instrucciones.png');
            app.InstructivoButton.Text = 'Instructivo';
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label = uilabel(app.Tab);
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label.Text = {'Analizador del Recurso de Biomasa'; '(Biomass analyser 1.0)'};
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Image_4 = uiimage(app.Tab2);
            app.Image_4.ImageSource = 'UDistrital.png';
            app.Image_5 = uiimage(app.Tab2);
            app.Image_5.ImageSource = 'ingelectrica.png';
            app.Image_6 = uiimage(app.Tab2);
            app.Image_6.ImageSource = 'lifae.png';
            app.BiomasaForestalButton = uibutton(app.Tab2, 'push');
            app.BiomasaForestalButton.ButtonPushedFcn = createCallbackFcn(app, @BiomasaForestalButtonPushed, true);
            app.BiomasaForestalButton.Text = 'Biomasa Forestal';
            app.BiomasaForestalButton.Icon = fullfile(pathToMLAPP, 'forestal.png');
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label_2 = uilabel(app.Tab2);
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label_2.Text = {'Analizador del Recurso de Biomasa'; '(Biomass analyser 1.0)'};
            app.BiomasaPecuariayAgricolaButton = uibutton(app.Tab2, 'push');
            app.BiomasaPecuariayAgricolaButton.ButtonPushedFcn = createCallbackFcn(app, @BiomasaPecuariayAgricolaButtonPushed, true);
            app.BiomasaPecuariayAgricolaButton.Text = 'Biomasa Pecuaria y Agricola';
            app.BiomasaPecuariayAgricolaButton.Icon = fullfile(pathToMLAPP, 'agricolaypecuario.png');
            app.InformacionButton = uibutton(app.Tab2, 'push');
            app.InformacionButton.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InformacionButton.Text = 'Informacion';
            app.InformacionButton.Icon = fullfile(pathToMLAPP, 'info.png');
            app.BiodigestorLabel = uilabel(app.Tab2);
            app.BiodigestorLabel.Text = 'Biodigestor';
            app.GasificadorLabel = uilabel(app.Tab2);
            app.GasificadorLabel.Text = 'Gasificador';
            app.SalirButton = uibutton(app.Tab2, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @salirbuton, true);
            app.SalirButton.Text = 'Salir';
            app.SalirButton.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.VolverButton = uibutton(app.Tab2, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @volverbuton, true);
            app.VolverButton.Text = 'Volver';
            app.VolverButton.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.InstructivoButton_2 = uibutton(app.Tab2, 'push');
            app.InstructivoButton_2.ButtonPushedFcn = createCallbackFcn(app, @instructivobuton2, true);
            app.InstructivoButton_2.Text = 'Instructivo';
            app.InstructivoButton_2.Icon = fullfile(pathToMLAPP, 'instrucciones.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inicio(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end