classdef Instructivo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IdiomaLanguageMenu             matlab.ui.container.Menu
        EnglishMenu                    matlab.ui.container.Menu
        EspaolMenu                     matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        SalirButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        InformacionTab                 matlab.ui.container.Tab
        Image2_2                       matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        BienvenidousuarioesteaplicativotienecomofinalidadmostrarLabel  matlab.ui.control.Label
        Seleccioninicial               matlab.ui.container.Tab
        Image2_3                       matlab.ui.control.Image
        BiomasaForestalLabel           matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        BiomasaPecuariayAgricolaLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        seleccioninicialtext           matlab.ui.control.Label
        Casobio                        matlab.ui.container.Tab
        Image2_4                       matlab.ui.control.Image
        BiomasaPecuariayAgricola       matlab.ui.control.Label
        BiomasaAgricola                matlab.ui.control.Label
        BiomasaPecuaria                matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        casobiotext                    matlab.ui.control.Label
        Cantidadrecurso                matlab.ui.container.Tab
        Image2_5                       matlab.ui.control.Image
        Image8                         matlab.ui.control.Image
        SeleccioneeindiquelacantidadLabel  matlab.ui.control.Label
        SeleccioneeindiquelacantidaddecabezasdeganadoLabel  matlab.ui.control.Label
        Image7                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        cantidadrecursotext            matlab.ui.control.Label
        Temperatura                    matlab.ui.container.Tab
        Image2_6                       matlab.ui.control.Image
        indiquetemp                    matlab.ui.control.Label
        Image9                         matlab.ui.control.Image
        temptext                       matlab.ui.control.Label
        calcularbio                    matlab.ui.container.Tab
        Image2_7                       matlab.ui.control.Image
        Image11                        matlab.ui.control.Image
        calcularbiotext                matlab.ui.control.Label
        CalcularLabel                  matlab.ui.control.Label
        Image10                        matlab.ui.control.Image
        sugerencia                     matlab.ui.container.Tab
        Image2_8                       matlab.ui.control.Image
        SugerenciaelementosbiodigestorLabel  matlab.ui.control.Label
        sugerenciatext                 matlab.ui.control.Label
        Image13                        matlab.ui.control.Image
        Image12                        matlab.ui.control.Image
        potencialbio                   matlab.ui.container.Tab
        Image2_18                      matlab.ui.control.Image
        Image15                        matlab.ui.control.Image
        PotencialEnergeticoLabel       matlab.ui.control.Label
        Image14                        matlab.ui.control.Image
        potencialbiotext               matlab.ui.control.Label
        casogas                        matlab.ui.container.Tab
        Image2_9                       matlab.ui.control.Image
        BiomasaForestal                matlab.ui.control.Label
        Image16                        matlab.ui.control.Image
        casogastext                    matlab.ui.control.Label
        cantidadgas                    matlab.ui.container.Tab
        Image2_10                      matlab.ui.control.Image
        cantidadgastext_2              matlab.ui.control.Label
        cantidadgastext                matlab.ui.control.Label
        Image17                        matlab.ui.control.Image
        calculargas                    matlab.ui.container.Tab
        Image2_11                      matlab.ui.control.Image
        Image18                        matlab.ui.control.Image
        CalcularLabel_2                matlab.ui.control.Label
        Image10_2                      matlab.ui.control.Image
        calcularbiotext_2              matlab.ui.control.Label
        potgas                         matlab.ui.container.Tab
        Image2_12                      matlab.ui.control.Image
        Image19                        matlab.ui.control.Image
        PotencialEnergeticoLabel_2     matlab.ui.control.Label
        Image14_2                      matlab.ui.control.Image
        potencialbiotext_2             matlab.ui.control.Label
        foragricola                    matlab.ui.container.Tab
        Image2_13                      matlab.ui.control.Image
        FormulaparacalcularelpotencialenergeticoLabel  matlab.ui.control.Label
        Image21                        matlab.ui.control.Image
        Image20                        matlab.ui.control.Image
        forpecuaria                    matlab.ui.container.Tab
        Image2_14                      matlab.ui.control.Image
        Image23                        matlab.ui.control.Image
        Image22                        matlab.ui.control.Image
        FormulaparacalcularelpotencialenergeticoLabel_2  matlab.ui.control.Label
        forforestal                    matlab.ui.container.Tab
        Image2_15                      matlab.ui.control.Image
        Image24                        matlab.ui.control.Image
        FormulaparacalcularelpotencialenergeticoLabel_3  matlab.ui.control.Label
        unidades                       matlab.ui.container.Tab
        Image2_16                      matlab.ui.control.Image
        ConversiondeunidadesLabel      matlab.ui.control.Label
        Image25                        matlab.ui.control.Image
        creadores                      matlab.ui.container.Tab
        Image2_17                      matlab.ui.control.Image
        AutoresJulianLabel             matlab.ui.control.Label
        referencias                    matlab.ui.container.Tab
        ReferenciasLabel_3             matlab.ui.control.Label
        ReferenciasLabel_2             matlab.ui.control.Label
        ReferenciasImgLabel            matlab.ui.control.Label
        ReferenciasLabel               matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        InformacinNode                 matlab.ui.container.TreeNode
        InstructivoNode                matlab.ui.container.TreeNode
        SeleccioninicialNode           matlab.ui.container.TreeNode
        Biodigestor                    matlab.ui.container.TreeNode
        Paso1Node                      matlab.ui.container.TreeNode
        Paso2Node                      matlab.ui.container.TreeNode
        Paso3Node                      matlab.ui.container.TreeNode
        Paso4Node                      matlab.ui.container.TreeNode
        Paso5Node                      matlab.ui.container.TreeNode
        Paso6Node                      matlab.ui.container.TreeNode
        GasificadorNode                matlab.ui.container.TreeNode
        Paso7Node_2                    matlab.ui.container.TreeNode
        Paso8Node                      matlab.ui.container.TreeNode
        Paso9Node                      matlab.ui.container.TreeNode
        Paso10Node                     matlab.ui.container.TreeNode
        FormularioGlosarioNode         matlab.ui.container.TreeNode
        FormulacionagricolaNode_2      matlab.ui.container.TreeNode
        FormulacionpecuariaNode        matlab.ui.container.TreeNode
        FormulacionforestalNode        matlab.ui.container.TreeNode
        UnidadesNode                   matlab.ui.container.TreeNode
        SoporteNode                    matlab.ui.container.TreeNode
        AutoresNode                    matlab.ui.container.TreeNode
        ReferenciasNode                matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        idioma;
        Inicioapp
    end
 
    methods (Access = private)
        
        function guardaridiomas(app,idioma)
            app.SalirButton.Text = buscador(app,idioma, 'botonSalir1');
            app.BienvenidousuarioesteaplicativotienecomofinalidadmostrarLabel.Text = buscador(app,idioma, 'labelInformacion');
            app.InformacinNode.Text = buscador(app,idioma, 'nodeInformacion');
            app.InstructivoNode.Text = buscador(app,idioma, 'nodeInstructivo');
            app.SeleccioninicialNode.Text = buscador(app,idioma, 'nodeSeleccionInicial');
            app.Biodigestor.Text = buscador(app,idioma, 'nodeBiodigestor');
            app.Paso1Node.Text = buscador(app,idioma, 'nodePaso1');
            app.Paso2Node.Text = buscador(app,idioma, 'nodePaso2');
            app.Paso3Node.Text = buscador(app,idioma, 'nodePaso3');
            app.Paso4Node.Text = buscador(app,idioma, 'nodePaso4');
            app.Paso5Node.Text = buscador(app,idioma, 'nodePaso5');
            app.Paso6Node.Text = buscador(app,idioma, 'nodePaso6');
            app.GasificadorNode.Text = buscador(app,idioma, 'nodeGasificador');
            app.Paso7Node_2.Text = buscador(app,idioma, 'nodePaso7');
            app.Paso8Node.Text = buscador(app,idioma, 'nodePaso8');
            app.Paso9Node.Text = buscador(app,idioma, 'nodePaso9');
            app.Paso10Node.Text = buscador(app,idioma, 'nodePaso10');
            app.FormularioGlosarioNode.Text = buscador(app,idioma, 'nodeFormulario');
            app.FormulacionagricolaNode_2.Text = buscador(app,idioma, 'nodeFormAgricola');
            app.FormulacionpecuariaNode.Text = buscador(app,idioma, 'nodeFormPecuaria');
            app.FormulacionforestalNode.Text = buscador(app,idioma, 'nodeFormForestal');
            app.UnidadesNode.Text = buscador(app,idioma, 'nodeUnidades');
            app.SoporteNode.Text = buscador(app,idioma, 'nodeSoporte');
            app.AutoresNode.Text = buscador(app,idioma, 'nodeAutores');
            app.ReferenciasNode.Text = buscador(app,idioma, 'nodeReferencias');

            app.BiomasaForestalLabel.Text = buscador(app,idioma, 'BiomasaForestalLabel1');
            app.BiomasaPecuariayAgricolaLabel.Text = buscador(app,idioma, 'BiomasaPecuariaAgricola1');
            app.seleccioninicialtext.Text = buscador(app,idioma, 'TituloSeleccionarFuenteBiomasaForestalPecAgr');
            
            app.BiomasaPecuariayAgricola.Text = buscador(app,idioma, 'BiomasaPecuariaAgricolaLabelInstru');
            app.BiomasaAgricola.Text = buscador(app,idioma, 'BiomasaAgricolaLabelInstru');
            app.BiomasaPecuaria.Text = buscador(app,idioma, 'BiomasaPecuariaLabelInstru');
            app.casobiotext.Text = buscador(app,idioma, 'TituloSeleccionBiomasaCasoBiodigestor');

            app.SeleccioneeindiquelacantidadLabel.Text = buscador(app,idioma, 'SeleccionCantidadPeso');
            app.SeleccioneeindiquelacantidaddecabezasdeganadoLabel.Text = buscador(app,idioma, 'SeleccionCantidadCabezas');
            app.cantidadrecursotext.Text = buscador(app,idioma, 'cantidadrecursoDisponible');

            app.indiquetemp.Text = buscador(app,idioma, 'IndiqueTemLabel');
            app.temptext.Text = buscador(app,idioma, 'temptextlabel');

            app.calcularbiotext.Text = buscador(app,idioma, 'CalcularText');
            app.CalcularLabel.Text = buscador(app,idioma, 'CalcularTitle');

            app.SugerenciaelementosbiodigestorLabel.Text = buscador(app,idioma, 'SugerenciaBioTitle');
            app.sugerenciatext.Text = buscador(app,idioma, 'SugerenciaBioText');

            app.PotencialEnergeticoLabel.Text = buscador(app,idioma, 'potEnergeticoTitle');
            app.potencialbiotext.Text = buscador(app,idioma, 'potEnergeticoText');

            app.BiomasaForestal.Text = buscador(app,idioma, 'biomasaForesTitle');
            app.casogastext.Text = buscador(app,idioma, 'biomasaForesText');

            app.cantidadgastext_2.Text = buscador(app,idioma, 'CantidadRdispoText');
            app.cantidadgastext.Text = buscador(app,idioma, 'CantidadRdispoTitle');

            app.CalcularLabel_2.Text = buscador(app,idioma, 'CalcularTitle2');
            app.calcularbiotext_2.Text = buscador(app,idioma, 'CalcularText2');

            app.PotencialEnergeticoLabel_2.Text = buscador(app,idioma, 'potEnergeticoTitle2');
            app.potencialbiotext_2.Text = buscador(app,idioma, 'potEnergeticoText2');

            app.FormulaparacalcularelpotencialenergeticoLabel.Text = buscador(app,idioma, 'FormulaPotEnergetico');

            app.FormulaparacalcularelpotencialenergeticoLabel_2.Text = buscador(app,idioma, 'FormulaPotEnergetico2');

            app.FormulaparacalcularelpotencialenergeticoLabel_3.Text = buscador(app,idioma, 'FormulaPotEnergetico3');

            app.ConversiondeunidadesLabel.Text = buscador(app,idioma, 'ConversionUnidades');

            app.AutoresJulianLabel.Text = buscador(app,idioma, 'Autores');

            app.ReferenciasLabel.Text = buscador(app,idioma, 'ReferenciasIEEE');
            app.ReferenciasImgLabel.Text = buscador(app,idioma, 'imgIEEE');
            app.ReferenciasLabel_2.Text = buscador(app,idioma, 'Ref');
            app.ReferenciasLabel_3.Text = buscador(app,idioma, 'Ref2');

        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inicioapp, idioma)
            app.idioma = idioma;
            app.Inicioapp = inicioapp;
            if app.idioma == "Español"
                app.EspaolMenu.Checked = "on";
               app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if app.idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end
            app.Tree.SelectedNodes = app.InformacinNode;
            expand(app.InstructivoNode);
            expand(app.Biodigestor);
            expand(app.GasificadorNode);
            expand(app.FormularioGlosarioNode);
            expand(app.SoporteNode);
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged2(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            switch selectedNodes.NodeData
                case 0
                    app.TabGroup.SelectedTab = app.InformacionTab;
                case 1
                    app.TabGroup.SelectedTab = app.Seleccioninicial;
                case 2
                    app.TabGroup.SelectedTab = app.Casobio;
                case 3
                    app.TabGroup.SelectedTab = app.Cantidadrecurso;
                case 4
                    app.TabGroup.SelectedTab = app.Temperatura;
                case 5
                    app.TabGroup.SelectedTab = app.calcularbio;
                case 6
                    app.TabGroup.SelectedTab = app.sugerencia;
                case 7
                    app.TabGroup.SelectedTab = app.potencialbio;
                case 8
                    app.TabGroup.SelectedTab = app.casogas;
                case 9
                    app.TabGroup.SelectedTab = app.cantidadgas;
                case 10
                    app.TabGroup.SelectedTab = app.calculargas;
                case 11
                    app.TabGroup.SelectedTab = app.potgas;
                case 12
                    app.TabGroup.SelectedTab = app.foragricola;
                case 13
                    app.TabGroup.SelectedTab = app.forpecuaria;
                case 14
                    app.TabGroup.SelectedTab = app.forforestal;
                case 15
                    app.TabGroup.SelectedTab = app.unidades;
                case 16
                    app.TabGroup.SelectedTab = app.creadores;
                case 17
                    app.TabGroup.SelectedTab = app.referencias;
            end    
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";    

        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";

        end

        % Button pushed function: SalirButton
        function SalirButon(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IdiomaLanguageMenu = uimenu(app.UIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Text = 'Español';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Tree = uitree(app.Panel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged2, true);
            app.InformacinNode = uitreenode(app.Tree);
            app.InformacinNode.NodeData = 0;
            app.InformacinNode.Text = 'Información';
            app.InstructivoNode = uitreenode(app.Tree);
            app.InstructivoNode.NodeData = 'info';
            app.InstructivoNode.Text = 'Instructivo';
            app.SeleccioninicialNode = uitreenode(app.InstructivoNode);
            app.SeleccioninicialNode.NodeData = 1;
            app.SeleccioninicialNode.Text = 'Seleccion inicial';
            app.Biodigestor = uitreenode(app.InstructivoNode);
            app.Biodigestor.Text = 'Biodigestor';
            app.Biodigestor.Icon = fullfile(pathToMLAPP, 'pecuario.png');
            app.Paso1Node = uitreenode(app.Biodigestor);
            app.Paso1Node.NodeData = 2;
            app.Paso1Node.Text = 'Paso 1';
            app.Paso1Node.Icon = fullfile(pathToMLAPP, 'pecuario.png');
            app.Paso2Node = uitreenode(app.Biodigestor);
            app.Paso2Node.NodeData = 3;
            app.Paso2Node.Text = 'Paso 2';
            app.Paso2Node.Icon = fullfile(pathToMLAPP, 'selecbioamasa.png');
            app.Paso3Node = uitreenode(app.Biodigestor);
            app.Paso3Node.NodeData = 4;
            app.Paso3Node.Text = 'Paso 3';
            app.Paso3Node.Icon = fullfile(pathToMLAPP, 'temperatura.png');
            app.Paso4Node = uitreenode(app.Biodigestor);
            app.Paso4Node.NodeData = 5;
            app.Paso4Node.Text = 'Paso4';
            app.Paso4Node.Icon = fullfile(pathToMLAPP, 'calcular.png');
            app.Paso5Node = uitreenode(app.Biodigestor);
            app.Paso5Node.NodeData = 6;
            app.Paso5Node.Text = 'Paso 5';
            app.Paso5Node.Icon = fullfile(pathToMLAPP, 'biogas.png');
            app.Paso6Node = uitreenode(app.Biodigestor);
            app.Paso6Node.NodeData = 7;
            app.Paso6Node.Text = 'Paso 6';
            app.Paso6Node.Icon = fullfile(pathToMLAPP, 'potencial_energetico.png');
            app.GasificadorNode = uitreenode(app.InstructivoNode);
            app.GasificadorNode.Text = 'Gasificador';
            app.GasificadorNode.Icon = fullfile(pathToMLAPP, 'forestal.png');
            app.Paso7Node_2 = uitreenode(app.GasificadorNode);
            app.Paso7Node_2.NodeData = 8;
            app.Paso7Node_2.Text = 'Paso 7';
            app.Paso7Node_2.Icon = fullfile(pathToMLAPP, 'forestal.png');
            app.Paso8Node = uitreenode(app.GasificadorNode);
            app.Paso8Node.NodeData = 9;
            app.Paso8Node.Text = 'Paso 8';
            app.Paso8Node.Icon = fullfile(pathToMLAPP, 'cantgas.png');
            app.Paso9Node = uitreenode(app.GasificadorNode);
            app.Paso9Node.NodeData = 10;
            app.Paso9Node.Text = 'Paso 9';
            app.Paso9Node.Icon = fullfile(pathToMLAPP, 'calcular.png');
            app.Paso10Node = uitreenode(app.GasificadorNode);
            app.Paso10Node.NodeData = 11;
            app.Paso10Node.Text = 'Paso 10';
            app.Paso10Node.Icon = fullfile(pathToMLAPP, 'potencial_energetico.png');
            app.FormularioGlosarioNode = uitreenode(app.InstructivoNode);
            app.FormularioGlosarioNode.NodeData = 12;
            app.FormularioGlosarioNode.Text = 'Formulario-Glosario';
            app.FormularioGlosarioNode.Icon = fullfile(pathToMLAPP, 'instrucciones.png');
            app.FormulacionagricolaNode_2 = uitreenode(app.FormularioGlosarioNode);
            app.FormulacionagricolaNode_2.NodeData = 12;
            app.FormulacionagricolaNode_2.Text = 'Formulacion agricola';
            app.FormulacionagricolaNode_2.Icon = fullfile(pathToMLAPP, 'agricola.png');
            app.FormulacionpecuariaNode = uitreenode(app.FormularioGlosarioNode);
            app.FormulacionpecuariaNode.NodeData = 13;
            app.FormulacionpecuariaNode.Text = 'Formulacion pecuaria';
            app.FormulacionpecuariaNode.Icon = fullfile(pathToMLAPP, 'pecuario.png');
            app.FormulacionforestalNode = uitreenode(app.FormularioGlosarioNode);
            app.FormulacionforestalNode.NodeData = 14;
            app.FormulacionforestalNode.Text = 'Formulacion forestal';
            app.FormulacionforestalNode.Icon = fullfile(pathToMLAPP, 'forestal.png');
            app.UnidadesNode = uitreenode(app.FormularioGlosarioNode);
            app.UnidadesNode.NodeData = 15;
            app.UnidadesNode.Text = 'Unidades';
            app.SoporteNode = uitreenode(app.InstructivoNode);
            app.SoporteNode.Text = 'Soporte';
            app.AutoresNode = uitreenode(app.SoporteNode);
            app.AutoresNode.NodeData = 16;
            app.AutoresNode.Text = 'Autores';
            app.ReferenciasNode = uitreenode(app.SoporteNode);
            app.ReferenciasNode.NodeData = 17;
            app.ReferenciasNode.Text = 'Referencias';
            app.TabGroup = uitabgroup(app.Panel);
            app.InformacionTab = uitab(app.TabGroup);
            app.InformacionTab.Title = 'Informacion';
            app.BienvenidousuarioesteaplicativotienecomofinalidadmostrarLabel = uilabel(app.InformacionTab);
            app.BienvenidousuarioesteaplicativotienecomofinalidadmostrarLabel.Text = {'Bienvenido usuario este aplicativo tiene como finalidad mostrar el proceso'; ' de seleccion de un modelo de biodigestor, a partir de biomasa tanto biomasa'; ' pecuaria o biomasa agricola y un modelo de gasificador, a partir de biomasa forestal'};
            app.Image2 = uiimage(app.InformacionTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'instrucciones.png');
            app.Image2_2 = uiimage(app.InformacionTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.Seleccioninicial = uitab(app.TabGroup);
            app.Seleccioninicial.Title = 'Tab';
            app.seleccioninicialtext = uilabel(app.Seleccioninicial);
            app.seleccioninicialtext.Text = {'Seleccione la fuente de biomasa que desea implementar'; '(biomasa pecuaria y biomasa agricola) o (biomasa forestal)'};
            app.Image = uiimage(app.Seleccioninicial);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'agricolaypecuario.png');
            app.BiomasaPecuariayAgricolaLabel = uilabel(app.Seleccioninicial);
            app.BiomasaPecuariayAgricolaLabel.Text = 'Biomasa Pecuaria y Agricola';
            app.Image_2 = uiimage(app.Seleccioninicial);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'forestal.png');
            app.BiomasaForestalLabel = uilabel(app.Seleccioninicial);
            app.BiomasaForestalLabel.Text = 'Biomasa Forestal';
            app.Image2_3 = uiimage(app.Seleccioninicial);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.Casobio = uitab(app.TabGroup);
            app.Casobio.Title = 'Tab2';
            app.casobiotext = uilabel(app.Casobio);
            app.casobiotext.Text = 'Seleccione el tipo de biomasa a implementar(Caso Biodigestor)';
            app.Image3 = uiimage(app.Casobio);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'agricola.png');
            app.Image4 = uiimage(app.Casobio);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'pecuario.png');
            app.Image5 = uiimage(app.Casobio);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'images', 'agricolaypecuario.png');
            app.BiomasaPecuaria = uilabel(app.Casobio);
            app.BiomasaPecuaria.Text = 'Biomasa Pecuaria';
            app.BiomasaAgricola = uilabel(app.Casobio);
            app.BiomasaAgricola.Text = 'Biomasa Agricola';
            app.BiomasaPecuariayAgricola = uilabel(app.Casobio);
            app.BiomasaPecuariayAgricola.Text = 'Biomasa Pecuaria y Agricola';
            app.Image2_4 = uiimage(app.Casobio);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.Cantidadrecurso = uitab(app.TabGroup);
            app.Cantidadrecurso.Title = 'Tab3';
            app.cantidadrecursotext = uilabel(app.Cantidadrecurso);
            app.cantidadrecursotext.Text = 'Indique la cantidad del recurso disponible';
            app.Image6 = uiimage(app.Cantidadrecurso);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'images', 'selecbioamasa.png');
            app.Image7 = uiimage(app.Cantidadrecurso);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'images', 'selecagricola.png');
            app.SeleccioneeindiquelacantidaddecabezasdeganadoLabel = uilabel(app.Cantidadrecurso);
            app.SeleccioneeindiquelacantidaddecabezasdeganadoLabel.Text = {'Seleccione e indique la cantidad'; ' de cabezas de ganado'};
            app.SeleccioneeindiquelacantidadLabel = uilabel(app.Cantidadrecurso);
            app.SeleccioneeindiquelacantidadLabel.Text = {'Seleccione e indique la cantidad'; ' segun las unidades'; ' (Toneladas ó arrobas)'};
            app.Image8 = uiimage(app.Cantidadrecurso);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'images', 'unidadesmasa.png');
            app.Image2_5 = uiimage(app.Cantidadrecurso);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.Temperatura = uitab(app.TabGroup);
            app.Temperatura.Title = 'Tab4';
            app.temptext = uilabel(app.Temperatura);
            app.temptext.Text = {'Indique la temperatura promedio en donde se realizara '; 'la implementacion'};
            app.Image9 = uiimage(app.Temperatura);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'images', 'seletemperatura .png');
            app.indiquetemp = uilabel(app.Temperatura);
            app.indiquetemp.Text = {'Indique la temperatura promedio en donde se realizara '; 'la implementacion, en caso de no conocerla se puede ir '; 'al mapa y mirar la temperatura en la escala'; ''; ''};
            app.Image2_6 = uiimage(app.Temperatura);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.calcularbio = uitab(app.TabGroup);
            app.calcularbio.Title = 'Tab5';
            app.Image10 = uiimage(app.calcularbio);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'images', 'calcular.png');
            app.CalcularLabel = uilabel(app.calcularbio);
            app.CalcularLabel.Text = 'Calcular';
            app.calcularbiotext = uilabel(app.calcularbio);
            app.calcularbiotext.Text = {'Seleccione el boton de "Calcular" para mostrar el volumen del '; 'Biodigestor(lt)'; ''; ''};
            app.Image11 = uiimage(app.calcularbio);
            app.Image11.ImageSource = fullfile(pathToMLAPP, 'images', 'volumenbio.png');
            app.Image2_7 = uiimage(app.calcularbio);
            app.Image2_7.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.sugerencia = uitab(app.TabGroup);
            app.sugerencia.Title = 'Tab6';
            app.Image12 = uiimage(app.sugerencia);
            app.Image12.ImageSource = fullfile(pathToMLAPP, 'images', 'biogas.png');
            app.Image13 = uiimage(app.sugerencia);
            app.Image13.ImageSource = fullfile(pathToMLAPP, 'images', 'volumenesinstru.png');
            app.sugerenciatext = uilabel(app.sugerencia);
            app.sugerenciatext.Text = {'Seleccione el boton "Sugerencia elementos biodigestor", para'; 'mostrar el volumen de cada uno de los elementos'; ''; ''; ''};
            app.SugerenciaelementosbiodigestorLabel = uilabel(app.sugerencia);
            app.SugerenciaelementosbiodigestorLabel.Text = {'Sugerencia elementos '; 'biodigestor'};
            app.Image2_8 = uiimage(app.sugerencia);
            app.Image2_8.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.potencialbio = uitab(app.TabGroup);
            app.potencialbio.Title = 'Tab7';
            app.potencialbiotext = uilabel(app.potencialbio);
            app.potencialbiotext.Text = {'Seleccione el boton "Potencial Energetico", para'; ' mostrar el potencial por cada una de las categorias '; 'asi como el potencial de la fuente y el recurso'; ''; ''; ''};
            app.Image14 = uiimage(app.potencialbio);
            app.Image14.ImageSource = fullfile(pathToMLAPP, 'images', 'potencial_energetico.png');
            app.PotencialEnergeticoLabel = uilabel(app.potencialbio);
            app.PotencialEnergeticoLabel.Text = 'Potencial Energetico';
            app.Image15 = uiimage(app.potencialbio);
            app.Image15.ImageSource = fullfile(pathToMLAPP, 'images', 'finalselec.png');
            app.Image2_18 = uiimage(app.potencialbio);
            app.Image2_18.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.casogas = uitab(app.TabGroup);
            app.casogas.Title = 'Tab8';
            app.casogastext = uilabel(app.casogas);
            app.casogastext.Text = 'Seleccione el tipo de biomasa a implementar (Caso Gasificador)';
            app.Image16 = uiimage(app.casogas);
            app.Image16.ImageSource = fullfile(pathToMLAPP, 'images', 'forestal.png');
            app.BiomasaForestal = uilabel(app.casogas);
            app.BiomasaForestal.Text = 'Biomasa Forestal';
            app.Image2_9 = uiimage(app.casogas);
            app.Image2_9.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.cantidadgas = uitab(app.TabGroup);
            app.cantidadgas.Title = 'Tab9';
            app.Image17 = uiimage(app.cantidadgas);
            app.Image17.ImageSource = fullfile(pathToMLAPP, 'images', 'cantgas.png');
            app.cantidadgastext = uilabel(app.cantidadgas);
            app.cantidadgastext.Text = 'Indique la cantidad del recurso disponible';
            app.cantidadgastext_2 = uilabel(app.cantidadgas);
            app.cantidadgastext_2.Text = 'Seleccione e indique la cantidad del recurso disponible en kg y la humedad relativa';
            app.Image2_10 = uiimage(app.cantidadgas);
            app.Image2_10.ImageSource = fullfile(pathToMLAPP, 'images', 'logo.png');
            app.calculargas = uitab(app.TabGroup);
            app.calculargas.Title = 'Tab10';
            app.calcularbiotext_2 = uilabel(app.calculargas);
            app.calcularbiotext_2.Text = {'Seleccione el boton de "Calcular" para mostrar el potencial por cada'; 'uno de los tipos de madera en la grafica'; ''; ''};
            app.Image10_2 = uiimage(app.calculargas);
            app.Image10_2.ImageSource = fullfile(pathToMLAPP, 'images', 'calcular.png');
            app.CalcularLabel_2 = uilabel(app.calculargas);
            app.CalcularLabel_2.Text = 'Calcular';
            app.Image18 = uiimage(app.calculargas);
            app.Image18.ImageSource = fullfile(pathToMLAPP, 'images', 'calculargas.png');
            app.Image2_11 = uiimage(app.calculargas);
            app.Image2_11.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.potgas = uitab(app.TabGroup);
            app.potgas.Title = 'Tab11';
            app.potencialbiotext_2 = uilabel(app.potgas);
            app.potencialbiotext_2.Text = {'Seleccione el boton "Potencial Energetico", para'; 'mostrar el potencial por cada una de las categorias '; 'asi como el potencial de la fuente y el recurso'; ''; ''; ''};
            app.Image14_2 = uiimage(app.potgas);
            app.Image14_2.ImageSource = fullfile(pathToMLAPP, 'images', 'potencial_energetico.png');
            app.PotencialEnergeticoLabel_2 = uilabel(app.potgas);
            app.PotencialEnergeticoLabel_2.Text = 'Potencial Energetico';
            app.Image19 = uiimage(app.potgas);
            app.Image19.ImageSource = fullfile(pathToMLAPP, 'images', 'potgas.png');
            app.Image2_12 = uiimage(app.potgas);
            app.Image2_12.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.foragricola = uitab(app.TabGroup);
            app.foragricola.Title = 'Tab12';
            app.Image20 = uiimage(app.foragricola);
            app.Image20.ImageSource = fullfile(pathToMLAPP, 'images', 'formulaagricola1.jpeg');
            app.Image21 = uiimage(app.foragricola);
            app.Image21.ImageSource = fullfile(pathToMLAPP, 'images', 'formulaagricola2.jpeg');
            app.FormulaparacalcularelpotencialenergeticoLabel = uilabel(app.foragricola);
            app.FormulaparacalcularelpotencialenergeticoLabel.Text = 'Formula para calcular el potencial energetico';
            app.Image2_13 = uiimage(app.foragricola);
            app.Image2_13.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.forpecuaria = uitab(app.TabGroup);
            app.forpecuaria.Title = 'Tab13';
            app.FormulaparacalcularelpotencialenergeticoLabel_2 = uilabel(app.forpecuaria);
            app.FormulaparacalcularelpotencialenergeticoLabel_2.Text = 'Formula para calcular el potencial energetico';
            app.Image22 = uiimage(app.forpecuaria);
            app.Image22.ImageSource = fullfile(pathToMLAPP, 'images', 'formulapecuario1.jpeg');
            app.Image23 = uiimage(app.forpecuaria);
            app.Image23.ImageSource = fullfile(pathToMLAPP, 'images', 'formulapecuario2.jpeg');
            app.Image2_14 = uiimage(app.forpecuaria);
            app.Image2_14.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.forforestal = uitab(app.TabGroup);
            app.forforestal.Title = 'Tab14';
            app.FormulaparacalcularelpotencialenergeticoLabel_3 = uilabel(app.forforestal);
            app.FormulaparacalcularelpotencialenergeticoLabel_3.Text = 'Formula para calcular el potencial energetico';
            app.Image24 = uiimage(app.forforestal);
            app.Image24.ImageSource = fullfile(pathToMLAPP, 'images', 'formulaforestal.jpeg');
            app.Image2_15 = uiimage(app.forforestal);
            app.Image2_15.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.unidades = uitab(app.TabGroup);
            app.unidades.Title = 'Tab15';
            app.Image25 = uiimage(app.unidades);
            app.Image25.ImageSource = fullfile(pathToMLAPP, 'images', 'unidades.jpeg');
            app.ConversiondeunidadesLabel = uilabel(app.unidades);
            app.ConversiondeunidadesLabel.Text = 'Conversion de unidades';
            app.Image2_16 = uiimage(app.unidades);
            app.Image2_16.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.creadores = uitab(app.TabGroup);
            app.creadores.Title = 'Tab16';
            app.AutoresJulianLabel = uilabel(app.creadores);
            app.AutoresJulianLabel.Text = {'Autores'; ''; 'Ing. Julian Santiago Mahecha Ramirez'; 'Ing. William Alberto Sanchez Lucuara'; 'PhD Diego Julian Rodriguez Patarroyo'; ''; 'Universidad Distrital Francisco Jose De Caldas'; 'Ingenieria Electrica'; ''; 'LIFAE'; ''; '2023'};
            app.Image2_17 = uiimage(app.creadores);
            app.Image2_17.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2.png');
            app.referencias = uitab(app.TabGroup);
            app.referencias.Title = 'Tab17';
            app.ReferenciasLabel = uilabel(app.referencias);
            app.ReferenciasLabel.Text = 'Referencias';
            app.ReferenciasImgLabel = uilabel(app.referencias);
            app.ReferenciasImgLabel.Text = 'Referencias Img';
            app.ReferenciasLabel_2 = uilabel(app.referencias);
            app.ReferenciasLabel_2.Text = 'Referencias';
            app.ReferenciasLabel_3 = uilabel(app.referencias);
            app.ReferenciasLabel_3.Text = 'Referencias';
            app.SalirButton = uibutton(app.Panel, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButon, true);
            app.SalirButton.Text = 'Salir';
            app.SalirButton.Icon = fullfile(pathToMLAPP, 'Salir.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Instructivo2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end