classdef Proceso_Biodigestor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IdiomaLanguageMenu              matlab.ui.container.Menu
        EnglishMenu                     matlab.ui.container.Menu
        EspaolMenu                      matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        SalirButton                     matlab.ui.control.Button
        VolverButton_4                  matlab.ui.control.Button
        InfoButton                      matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        AnalizadordelRecursodeBiomasaBiomassanalyser10Label  matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        BiomasaPecuariayAgricolaButton  matlab.ui.control.Button
        SeleccionelafuentedebiomasaquedeseaimplementarLabel  matlab.ui.control.Label
        BiomasaAgricolaButton           matlab.ui.control.Button
        BiomasaPecuariaButton           matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        CerrartablaButton               matlab.ui.control.Button
        VertablacompletaButton          matlab.ui.control.Button
        InfoButton_2                    matlab.ui.control.Button
        Image2_3                        matlab.ui.control.Image
        UnidadesdemasaButtonGroup       matlab.ui.container.ButtonGroup
        ArrobasaoButton                 matlab.ui.control.RadioButton
        TonaoButton                     matlab.ui.control.RadioButton
        UITable2_2                      matlab.ui.control.Table
        ContinuarButton                 matlab.ui.control.Button
        SeleccionbiomasapecuariaButton  matlab.ui.control.Button
        CANTIDADDETONELADASPORAODEBIOMASAAGRICOLALabel  matlab.ui.control.Label
        YucaCheckBox                    matlab.ui.control.CheckBox
        TrigoCheckBox                   matlab.ui.control.CheckBox
        SoyaCheckBox                    matlab.ui.control.CheckBox
        PalmaAceiteCheckBox             matlab.ui.control.CheckBox
        ManiCheckBox                    matlab.ui.control.CheckBox
        MaizCheckBox                    matlab.ui.control.CheckBox
        FrijolCheckBox                  matlab.ui.control.CheckBox
        CocoCheckBox                    matlab.ui.control.CheckBox
        CafeCheckBox                    matlab.ui.control.CheckBox
        CaaPaneleraCheckBox             matlab.ui.control.CheckBox
        CaaAzucarCheckBox               matlab.ui.control.CheckBox
        PlatanoCheckBox                 matlab.ui.control.CheckBox
        BananoCheckBox                  matlab.ui.control.CheckBox
        AlgodonCheckBox                 matlab.ui.control.CheckBox
        ArrozCheckBox                   matlab.ui.control.CheckBox
        VolverButton                    matlab.ui.control.Button
        YucaSpinner                     matlab.ui.control.Spinner
        TrigoSpinner                    matlab.ui.control.Spinner
        SoyaSpinner                     matlab.ui.control.Spinner
        PalmaAceiteSpinner              matlab.ui.control.Spinner
        ManiSpinner                     matlab.ui.control.Spinner
        MaizSpinner                     matlab.ui.control.Spinner
        FrijolSpinner                   matlab.ui.control.Spinner
        CocoSpinner                     matlab.ui.control.Spinner
        CafeSpinner                     matlab.ui.control.Spinner
        CaaPaneleraSpinner              matlab.ui.control.Spinner
        CaaazucarSpinner                matlab.ui.control.Spinner
        PlatanoSpinner                  matlab.ui.control.Spinner
        BananoSpinner                   matlab.ui.control.Spinner
        AlgodnSpinner                   matlab.ui.control.Spinner
        Spinner                         matlab.ui.control.Spinner
        SpinnerLabel                    matlab.ui.control.Label
        graficoagricola                 matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        CategoriasButtonGroup           matlab.ui.container.ButtonGroup
        DRButton                        matlab.ui.control.RadioButton
        Biogasm3kgButton                matlab.ui.control.RadioButton
        CNButton                        matlab.ui.control.RadioButton
        MOSButton                       matlab.ui.control.RadioButton
        MSButton                        matlab.ui.control.RadioButton
        EstiercolkgdiaButton            matlab.ui.control.RadioButton
        CerrartablaButton_2             matlab.ui.control.Button
        VertablacompletaButton_2        matlab.ui.control.Button
        InfoButton_3                    matlab.ui.control.Button
        Image2_4                        matlab.ui.control.Image
        UITable2_3                      matlab.ui.control.Table
        SeleccionbiomasaagricolaButton  matlab.ui.control.Button
        ContinuarButton_2               matlab.ui.control.Button
        CABEZASDEGANADOLabel            matlab.ui.control.Label
        AvicolaCheckBox                 matlab.ui.control.CheckBox
        EquinaCheckBox                  matlab.ui.control.CheckBox
        CaprinaCheckBox                 matlab.ui.control.CheckBox
        OvinaCheckBox                   matlab.ui.control.CheckBox
        PorcinaCheckBox                 matlab.ui.control.CheckBox
        BufalinaCheckBox                matlab.ui.control.CheckBox
        BovinosCheckBox                 matlab.ui.control.CheckBox
        VolverButton_2                  matlab.ui.control.Button
        AvicolaSpinner                  matlab.ui.control.Spinner
        EquinaSpinner                   matlab.ui.control.Spinner
        CaprinaSpinner                  matlab.ui.control.Spinner
        OvinaSpinner                    matlab.ui.control.Spinner
        PorcinaSpinner                  matlab.ui.control.Spinner
        BufalinaSpinner                 matlab.ui.control.Spinner
        BovinosSpinner                  matlab.ui.control.Spinner
        infograficas                    matlab.ui.control.Label
        graficapecuario                 matlab.ui.control.UIAxes
        Tab4                            matlab.ui.container.Tab
        SeleccionarBiomasadenuevoButton  matlab.ui.control.StateButton
        InfoButton_4                    matlab.ui.control.Button
        Image2_5                        matlab.ui.control.Image
        labelVerTemperatura             matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        CalcularButton                  matlab.ui.control.Button
        TemperaturaCEditField           matlab.ui.control.NumericEditField
        TemperaturaCEditFieldLabel      matlab.ui.control.Label
        indiquelatemperaturapromediodellugardeimplementacionLabel  matlab.ui.control.Label
        VolverButton_3                  matlab.ui.control.Button
        Tab5                            matlab.ui.container.Tab
        VolverButton_5                  matlab.ui.control.Button
        InfoButton_5                    matlab.ui.control.Button
        SugerenciaelementosbiodigestorButton  matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        VolumenbiodigestorltEditField_2  matlab.ui.control.NumericEditField
        PotencialenergeticoButton       matlab.ui.control.Button
        VolumenbiodigestorltEditField   matlab.ui.control.NumericEditField
        VolumenbiodigestorltEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Tab6                            matlab.ui.container.Tab
        VolverButton_6                  matlab.ui.control.Button
        InfoButton_6                    matlab.ui.control.Button
        PotencialenergeticoporcategoriasPanel  matlab.ui.container.Panel
        PotencialtotalrecursobiomasaMwhaoEditField  matlab.ui.control.NumericEditField
        PotencialtotalrecursobiomasaMwhaoEditFieldLabel  matlab.ui.control.Label
        PotencialtotalfuentebiomasaMwhaoEditField  matlab.ui.control.NumericEditField
        PotencialtotalfuentebiomasaMwhaoEditFieldLabel  matlab.ui.control.Label
        InicioButton                    matlab.ui.control.Button
        grafica1                        matlab.ui.control.UIAxes
        Tab7                            matlab.ui.container.Tab
        VolverButton_7                  matlab.ui.control.Button
        InfoButton_7                    matlab.ui.control.Button
        Image2_6                        matlab.ui.control.Image
        ElementosconstitutivosdeunbiodigestorLabel  matlab.ui.control.Label
        labelElementosConstitutivosBiodigestor  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        CamaradedescargaltEditField     matlab.ui.control.NumericEditField
        CamaradedescargaltEditFieldLabel  matlab.ui.control.Label
        CamaradedescargaltEditField_2   matlab.ui.control.NumericEditField
        CamaradecargaltEditField        matlab.ui.control.NumericEditField
        CamaradecargaltEditFieldLabel   matlab.ui.control.Label
        CamaradecargaltEditField_2      matlab.ui.control.NumericEditField
        ReactorltEditField              matlab.ui.control.NumericEditField
        ReactorltEditFieldLabel         matlab.ui.control.Label
        ReactorltEditField_2            matlab.ui.control.NumericEditField
        PotencialenergeticoButton_2     matlab.ui.control.Button
    end

    
    properties (Access = private)

        aproxtemp; 
        aproxtemp1; 
        aproxtemp2; 
        unimasa;
        idioma
        Inicioapp
        pecuario
        estiercol
        Informacionapp
    end
    
    methods (Access = private)
        
        function importes(app,event)
            if event.Indices(2)==1 && event.NewData
                datospecuario = app.UITable2.Data;
                cabezas=event.NewData;
                estiercol=datospecuario{event.Indices(1),3};
                biogas=datospecuario{event.Indices(1),7};
                potfuen=cabezas*estiercol*biogas*21600*365;
                datospecuario(event.Indices(1),9)={potfuen};
                dr=datospecuario{event.Indices(1),8};
                potrec=dr*potfuen;
                datospecuario(event.Indices(1),10)={potrec};              
                app.UITable2.Data=datospecuario;
            end
           
            
        end
        
        function guardaridiomas(app,idioma)

            app.SeleccionarBiomasadenuevoButton.Text = buscador(app,idioma, 'SeleccionAgain');
            app.VolverButton_4.Text = buscador(app,idioma, 'botonVolver1');
            app.VolverButton_5.Text = buscador(app,idioma, 'botonVolver1');
            app.VolverButton_6.Text = buscador(app,idioma, 'botonVolver1');
            app.VolverButton_7.Text = buscador(app,idioma, 'botonVolver1');
            app.SalirButton.Text = buscador(app,idioma, 'botonSalir1');
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label.Text = buscador(app, idioma, 'titulo');

            app.BiomasaPecuariayAgricolaButton.Text = buscador(app,idioma, 'botonBiomasaPecuariaAgricola');
            app.SeleccionelafuentedebiomasaquedeseaimplementarLabel.Text = buscador(app,idioma, 'tituloSeleccionFuenteImplementarBiodigestor');
            app.BiomasaAgricolaButton.Text = buscador(app,idioma, 'botonBiomasaAgricola');
            app.BiomasaPecuariaButton.Text = buscador(app,idioma, 'botonBiomasaPecuaria');

            app.UnidadesdemasaButtonGroup.Title = buscador(app,idioma, 'tituloUnidadesBiomasa');
            app.CerrartablaButton.Text = buscador(app,idioma, 'cerrartabla');
            app.VertablacompletaButton.Text = buscador(app,idioma, 'vertabla');
            app.ArrobasaoButton.Text = buscador(app,idioma, 'botonArroba');
            app.TonaoButton.Text = buscador(app,idioma, 'botonTon');
            app.VolverButton.Text = buscador(app,idioma, 'botonVolver');
            app.ContinuarButton.Text = buscador(app,idioma, 'botonContinuar');
            app.SeleccionbiomasapecuariaButton.Text = buscador(app,idioma, 'botonSeleccionarBiomasaPecuaria');
            app.CANTIDADDETONELADASPORAODEBIOMASAAGRICOLALabel.Text = buscador(app,idioma, 'tituloCantidadTonArroba');
            app.ArrozCheckBox.Text = buscador(app,idioma, 'arrozCheck');
            app.AlgodonCheckBox.Text = buscador(app,idioma, 'algodonCheck');
            app.BananoCheckBox.Text = buscador(app,idioma, 'bananoCheck');
            app.PlatanoCheckBox.Text = buscador(app,idioma, 'platanoCheck');
            app.CaaAzucarCheckBox.Text = buscador(app,idioma, 'canaAzucarCheck');
            app.CaaPaneleraCheckBox.Text = buscador(app,idioma, 'canaPaneleraCheck');
            app.CafeCheckBox.Text = buscador(app,idioma, 'cafeCheck');
            app.CocoCheckBox.Text = buscador(app,idioma, 'cocoCheck');
            app.FrijolCheckBox.Text = buscador(app,idioma, 'frijolCheck');
            app.MaizCheckBox.Text = buscador(app,idioma, 'maizCheck');
            app.ManiCheckBox.Text = buscador(app,idioma, 'maniCheck');
            app.PalmaAceiteCheckBox.Text = buscador(app,idioma, 'palmaAceiteCheck');
            app.SoyaCheckBox.Text = buscador(app,idioma, 'soyaCheck');
            app.TrigoCheckBox.Text = buscador(app,idioma, 'trigoCheck');
            app.YucaCheckBox.Text = buscador(app,idioma, 'yucaCheck');

            app.CABEZASDEGANADOLabel.Text = buscador(app,idioma, 'tituloCabezasGanado');
            app.ContinuarButton_2.Text = buscador(app,idioma, 'botonContinuar');
            app.VolverButton_2.Text = buscador(app,idioma, 'botonVolver');
            app.SeleccionbiomasaagricolaButton.Text = buscador(app,idioma, 'botonSeleccionarBiomasaAgricola');
            app.BovinosCheckBox.Text = buscador(app,idioma, 'bovinoCheck');
            app.BufalinaCheckBox.Text = buscador(app,idioma, 'bufalinaCheck');
            app.PorcinaCheckBox.Text = buscador(app,idioma, 'porcinaCheck');
            app.OvinaCheckBox.Text = buscador(app,idioma, 'ovinaCheck');
            app.CaprinaCheckBox.Text = buscador(app,idioma, 'caprinaCheck');
            app.EquinaCheckBox.Text = buscador(app,idioma, 'equinaCheck');
            app.AvicolaCheckBox.Text = buscador(app,idioma, 'avicolaCheck');

            app.CalcularButton.Text = buscador(app,idioma, 'botonCalcular');
            app.TemperaturaCEditFieldLabel.Text = buscador(app,idioma, 'tituloTemperatura');
            app.VolverButton_3.Text = buscador(app,idioma, 'volveralinicio');
            app.indiquelatemperaturapromediodellugardeimplementacionLabel.Text = buscador(app,idioma, 'tituloTemperaturaPromedio');
            app.labelVerTemperatura.Text = buscador(app,idioma, 'labelVerTemperatura');

            app.VolumenbiodigestorltEditFieldLabel.Text = buscador(app,idioma, 'tituloVolumenBiodigestor');
            app.PotencialenergeticoButton.Text = buscador(app,idioma, 'botonPotencialEnergetico');
            app.SugerenciaelementosbiodigestorButton.Text = buscador(app,idioma, 'botonSugerenciaElementosBiodigestor');

            app.PotencialtotalfuentebiomasaMwhaoEditFieldLabel.Text = buscador(app,idioma, 'tituloPotencialTotalFuenteBiomasa');
            app.PotencialtotalrecursobiomasaMwhaoEditFieldLabel.Text = buscador(app,idioma, 'tituloPotencialTotalRecursoBiomasa');
            app.InicioButton.Text = buscador(app,idioma, 'botonInicio');

            app.PotencialenergeticoButton_2.Text = buscador(app,idioma, 'botonPotencialEnergetico');
            app.CamaradedescargaltEditFieldLabel.Text = buscador(app,idioma, 'tituloCamaraDescarga');
            app.CamaradecargaltEditFieldLabel.Text = buscador(app,idioma, 'tituloCamaraCarga');
            app.ReactorltEditFieldLabel.Text = buscador(app,idioma, 'tituloReactor');
            app.ElementosconstitutivosdeunbiodigestorLabel.Text = buscador(app,idioma, 'tituloElementosConstitutivosBiodigestor');
            app.labelElementosConstitutivosBiodigestor.Text = buscador(app,idioma, 'labelElementosConstitutivosBiodigestor');
            app.infograficas.Text = buscador(app,idioma, 'infograficas');

            app.UITable2_2.ColumnName = buscador(app,idioma, 'tablaagricola1');
            app.graficoagricola.YTickLabel = buscador(app,idioma, 'graficaagricolay');
            app.graficoagricola.Title.String = buscador(app,idioma, 'potencialcalorificograficatitulo');
            app.graficoagricola.XLabel.String = buscador(app,idioma, 'potgraficatitulo');
            app.graficoagricola.YLabel.String = buscador(app,idioma, 'cultivosgraficatitulo');

            app.graficapecuario.Title.String = buscador(app,idioma, 'estiercolgraf');
            app.graficapecuario.XTickLabel = buscador(app,idioma, 'xticklabelgraf');
            app.graficapecuario.XLabel.String = buscador(app,idioma, 'xlabelgraf');
            app.graficapecuario.YLabel.String = buscador(app,idioma, 'ylabelgraf');

            app.VertablacompletaButton_2.Text = buscador(app,idioma, 'vertabla1');
            app.CerrartablaButton_2.Text = buscador(app,idioma, 'cerrartabla1');
            app.Biogasm3kgButton.Text = buscador(app,idioma, 'biogasboton2');
            app.EstiercolkgdiaButton.Text = buscador(app,idioma, 'ylabelgraf');
            app.CategoriasButtonGroup.Title = buscador(app,idioma, 'categorias1');

            app.UITable2_3.ColumnName = buscador(app,idioma, 'tablaPecuario');

            app.UITable3.ColumnName = buscador(app,idioma, 'tablaPecuario2');
            app.UITable.ColumnName = buscador(app,idioma, 'tablaPecuario3');

            app.PotencialenergeticoporcategoriasPanel.Title = buscador(app,idioma, 'titulografica4');

            app.grafica1.Title.String = buscador(app,idioma, 'titulografica4');
            app.grafica1.YLabel.String = buscador(app,idioma, 'ylabelgraf');


        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end 

        
        function graficar(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inicioapp, idioma, informacionapp)
            app.idioma = idioma;
            app.Inicioapp = inicioapp;
            if app.idioma == "Español"
                app.EspaolMenu.Checked = "on";
               app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if app.idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end

            app.pecuario=readtable("datos_pecuario.xlsx");
            app.UITable2_3.Data=app.pecuario; 
            app.estiercol=table2array(app.pecuario(:,2));
            bar(app.graficapecuario,app.estiercol,'k');

           agricola=readtable("datos_agricola2.xlsx");
            calorifico=table2array(agricola(:,4));
            bar(app.graficoagricola,calorifico,Horizontal="on");


        end

        % Callback function: BiomasaPecuariaButton, Tab3
        function BiomasaPecuariaButtonPushed(app, event)
        app.TabGroup.SelectedTab = app.Tab3;
        app.SeleccionbiomasaagricolaButton.Enable = "off";
        app.SeleccionbiomasaagricolaButton.Visible = "off";
        app.ContinuarButton_2.Enable = "on";
        app.ContinuarButton_2.Visible = "on";
        app.AvicolaCheckBox.Value = 0;
        app.EquinaCheckBox.Value = 0;
        app.CaprinaCheckBox.Value = 0;
        app.OvinaCheckBox.Value = 0;
        app.PorcinaCheckBox.Value = 0;
        app.BufalinaCheckBox.Value = 0;
        app.BovinosCheckBox.Value = 0;
        app.AvicolaSpinner.Value = 0;
        app.EquinaSpinner.Value = 0;
        app.CaprinaSpinner.Value = 0;
        app.OvinaSpinner.Value = 0;
        app.PorcinaSpinner.Value = 0;
        app.BufalinaSpinner.Value = 0;
        app.BovinosSpinner.Value = 0;
        app.AvicolaSpinner.Enable ="off";
        app.EquinaSpinner.Enable ="off";
        app.CaprinaSpinner.Enable ="off";
        app.OvinaSpinner.Enable ="off";
        app.PorcinaSpinner.Enable ="off";
        app.BufalinaSpinner.Enable ="off";
        app.BovinosSpinner.Enable ="off";
        app.YucaSpinner.Value = 0;
        app.TrigoSpinner.Value = 0;
        app.SoyaSpinner.Value = 0;
        app.PalmaAceiteSpinner.Value = 0;
        app.ManiSpinner.Value = 0;
        app.MaizSpinner.Value = 0;
        app.FrijolSpinner.Value = 0;
        app.CocoSpinner.Value = 0;
        app.CafeSpinner.Value = 0;
        app.CaaPaneleraSpinner.Value = 0;
        app.CaaazucarSpinner.Value = 0;
        app.PlatanoSpinner.Value = 0;
        app.BananoSpinner.Value = 0;
        app.AlgodnSpinner.Value = 0;
        app.Spinner.Value = 0;


        end

        % Callback function: BiomasaAgricolaButton, Tab2
        function BiomasaAgricolaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
            app.SeleccionbiomasapecuariaButton.Enable = "off";
            app.SeleccionbiomasapecuariaButton.Visible = "off";
            app.ContinuarButton.Enable = "on";
            app.ContinuarButton.Visible = "on";

            app.YucaCheckBox.Value = 0;
            app.TrigoCheckBox.Value = 0;
            app.SoyaCheckBox.Value = 0;
            app.PalmaAceiteCheckBox.Value = 0;
            app.ManiCheckBox.Value = 0;
            app.MaizCheckBox.Value = 0;
            app.FrijolCheckBox.Value = 0;
            app.CocoCheckBox.Value = 0;
            app.CafeCheckBox.Value = 0;
            app.CaaPaneleraCheckBox.Value = 0;
            app.CaaAzucarCheckBox.Value = 0;
            app.PlatanoCheckBox.Value = 0;
            app.BananoCheckBox.Value = 0;
            app.AlgodonCheckBox.Value = 0;
            app.ArrozCheckBox.Value = 0;

            app.YucaSpinner.Value = 0;
            app.TrigoSpinner.Value = 0;
            app.SoyaSpinner.Value = 0;
            app.PalmaAceiteSpinner.Value = 0;
            app.ManiSpinner.Value = 0;
            app.MaizSpinner.Value = 0;
            app.FrijolSpinner.Value = 0;
            app.CocoSpinner.Value = 0;
            app.CafeSpinner.Value = 0;
            app.CaaPaneleraSpinner.Value = 0;
            app.CaaazucarSpinner.Value = 0;
            app.PlatanoSpinner.Value = 0;
            app.BananoSpinner.Value = 0;
            app.AlgodnSpinner.Value = 0;
            app.Spinner.Value = 0;

            app.YucaSpinner.Enable ="off";
            app.TrigoSpinner.Enable ="off";
            app.SoyaSpinner.Enable ="off";
            app.PalmaAceiteSpinner.Enable ="off";
            app.ManiSpinner.Enable ="off";
            app.MaizSpinner.Enable ="off";
            app.FrijolSpinner.Enable ="off";
            app.CocoSpinner.Enable ="off";
            app.CafeSpinner.Enable ="off";
            app.CaaPaneleraSpinner.Enable ="off";
            app.CaaazucarSpinner.Enable ="off";
            app.PlatanoSpinner.Enable ="off";
            app.BananoSpinner.Enable ="off";
            app.AlgodnSpinner.Enable ="off";
            app.Spinner.Enable ="off";
            app.AvicolaSpinner.Value = 0;
            app.EquinaSpinner.Value = 0;
            app.CaprinaSpinner.Value = 0;
            app.OvinaSpinner.Value = 0;
            app.PorcinaSpinner.Value = 0;
            app.BufalinaSpinner.Value = 0;
            app.BovinosSpinner.Value = 0;            
        end

        % Callback function: Tab, VolverButton_2
        function VolverButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Callback function
        function ArrozSpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            
        end

        % Value changed function: ArrozCheckBox
        function ArrozCheckBoxValueChanged(app, event)
            value = app.ArrozCheckBox.Value;
            switch value
                case 1
                    app.Spinner.Enable="on";
                case 0
                    app.Spinner.Enable="off";

            end


        end

        % Value changed function: AlgodonCheckBox
        function AlgodonCheckBoxValueChanged(app, event)
            value = app.AlgodonCheckBox.Value;
            switch value
                case 1
                    app.AlgodnSpinner.Enable="on";
                case 0
                    app.AlgodnSpinner.Enable="off";
            end
        end

        % Value changed function: BananoCheckBox
        function BananoCheckBoxValueChanged(app, event)
            value = app.BananoCheckBox.Value;
            switch value
                case 1
                    app.BananoSpinner.Enable="on";
                case 0
                    app.BananoSpinner.Enable="off";
            end
        end

        % Value changed function: PlatanoCheckBox
        function PlatanoCheckBoxValueChanged(app, event)
            value = app.PlatanoCheckBox.Value;
            switch value
                case 1
                    app.PlatanoSpinner.Enable="on";
                case 0
                    app.PlatanoSpinner.Enable="off";
            end
        end

        % Value changed function: CaaAzucarCheckBox
        function CaaAzucarCheckBoxValueChanged(app, event)
            value = app.CaaAzucarCheckBox.Value;
            switch value
                case 1
                    app.CaaazucarSpinner.Enable="on";
                case 0
                    app.CaaazucarSpinner.Enable="off";
            end
        end

        % Value changed function: CaaPaneleraCheckBox
        function CaaPaneleraCheckBoxValueChanged(app, event)
            value = app.CaaPaneleraCheckBox.Value;
            switch value
                case 1
                    app.CaaPaneleraSpinner.Enable="on";
                case 0
                    app.CaaPaneleraSpinner.Enable="off";
            end
        end

        % Value changed function: CafeCheckBox
        function CafeCheckBoxValueChanged(app, event)
            value = app.CafeCheckBox.Value;
            switch value
                case 1
                    app.CafeSpinner.Enable="on";
                case 0
                    app.CafeSpinner.Enable="off";
            end
        end

        % Value changed function: CocoCheckBox
        function CocoCheckBoxValueChanged(app, event)
            value = app.CocoCheckBox.Value;
            switch value
                case 1
                    app.CocoSpinner.Enable="on";
                case 0
                    app.CocoSpinner.Enable="off";
            end
        end

        % Value changed function: FrijolCheckBox
        function FrijolCheckBoxValueChanged(app, event)
            value = app.FrijolCheckBox.Value;
            switch value
                case 1
                    app.FrijolSpinner.Enable="on";
                case 0
                    app.FrijolSpinner.Enable="off";
            end
        end

        % Value changed function: MaizCheckBox
        function MaizCheckBoxValueChanged(app, event)
            value = app.MaizCheckBox.Value;
            switch value
                case 1
                    app.MaizSpinner.Enable="on";
                case 0
                    app.MaizSpinner.Enable="off";
            end
        end

        % Value changed function: ManiCheckBox
        function ManiCheckBoxValueChanged(app, event)
            value = app.ManiCheckBox.Value;
            switch value
                case 1
                    app.ManiSpinner.Enable="on";
                case 0
                    app.ManiSpinner.Enable="off";
            end
        end

        % Value changed function: PalmaAceiteCheckBox
        function PalmaAceiteCheckBoxValueChanged(app, event)
            value = app.PalmaAceiteCheckBox.Value;
            switch value
                case 1
                    app.PalmaAceiteSpinner.Enable="on";
                case 0
                    app.PalmaAceiteSpinner.Enable="off";
            end
        end

        % Value changed function: SoyaCheckBox
        function SoyaCheckBoxValueChanged(app, event)
            value = app.SoyaCheckBox.Value;
            switch value
                case 1
                    app.SoyaSpinner.Enable="on";
                case 0
                    app.SoyaSpinner.Enable="off";
            end
        end

        % Value changed function: TrigoCheckBox
        function TrigoCheckBoxValueChanged(app, event)
            value = app.TrigoCheckBox.Value;
            switch value
                case 1
                    app.TrigoSpinner.Enable="on";
                case 0
                    app.TrigoSpinner.Enable="off";
            end
        end

        % Value changed function: YucaCheckBox
        function YucaCheckBoxValueChanged(app, event)
            value = app.YucaCheckBox.Value;
            switch value
                case 1
                    app.YucaSpinner.Enable="on";
                case 0
                    app.YucaSpinner.Enable="off";
            end
        end

        % Value changed function: BovinosCheckBox
        function BovinosCheckBoxValueChanged(app, event)
            value = app.BovinosCheckBox.Value;
            switch value
                case 1
                    app.BovinosSpinner.Enable="on";
                case 0
                    app.BovinosSpinner.Enable="off";
            end
        end

        % Value changed function: BufalinaCheckBox
        function BufalinaCheckBoxValueChanged(app, event)
            value = app.BufalinaCheckBox.Value;
            switch value
                case 1
                    app.BufalinaSpinner.Enable="on";
                case 0
                    app.BufalinaSpinner.Enable="off";
            end
        end

        % Value changed function: PorcinaCheckBox
        function PorcinaCheckBoxValueChanged(app, event)
            value = app.PorcinaCheckBox.Value;
            switch value
                case 1
                    app.PorcinaSpinner.Enable="on";
                case 0
                    app.PorcinaSpinner.Enable="off";
            end
        end

        % Value changed function: OvinaCheckBox
        function OvinaCheckBoxValueChanged(app, event)
            value = app.OvinaCheckBox.Value;
            switch value
                case 1
                    app.OvinaSpinner.Enable="on";
                case 0
                    app.OvinaSpinner.Enable="off";
            end
        end

        % Value changed function: CaprinaCheckBox
        function CaprinaCheckBoxValueChanged(app, event)
            value = app.CaprinaCheckBox.Value;
            switch value
                case 1
                    app.CaprinaSpinner.Enable="on";
                case 0
                    app.CaprinaSpinner.Enable="off";
            end
        end

        % Value changed function: EquinaCheckBox
        function EquinaCheckBoxValueChanged(app, event)
            value = app.EquinaCheckBox.Value;
            switch value
                case 1
                    app.EquinaSpinner.Enable="on";
                case 0
                    app.EquinaSpinner.Enable="off";
            end
        end

        % Value changed function: AvicolaCheckBox
        function AvicolaCheckBoxValueChanged(app, event)
            value = app.AvicolaCheckBox.Value;
            switch value
                case 1
                    app.AvicolaSpinner.Enable="on";
                case 0
                    app.AvicolaSpinner.Enable="off";
            end
        end

        % Button pushed function: VolverButton_3
        function VolverButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab4;
        end

        % Button pushed function: ContinuarButton_2
        function ContinuarButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab4;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab5;
            if app.idioma == "Español"
              agricola11=readtable("datos_agricola1.xlsx");
              pecuaria11=readtable("datos_pecuario1.xlsx");
           elseif app.idioma == "English"
              agricola11=readtable("datos_agricola1_ingles.xlsx");
              pecuaria11=readtable("datos_pecuario1_ingles.xlsx");
           end
            app.UITable3.Data = agricola11;
            app.UITable.Data = pecuaria11;
             if app.TemperaturaCEditField.Value<=15
                app.aproxtemp=60;
                app.aproxtemp1=120;
                app.aproxtemp2=100;

             elseif app.TemperaturaCEditField.Value>26
                app.aproxtemp=15;
                app.aproxtemp1=70;
                app.aproxtemp2=50;
             else 15>app.TemperaturaCEditField.Value<26;
                app.aproxtemp=25;
                app.aproxtemp1=100;
                app.aproxtemp2=80;
                
             end
            
          
            if app.TonaoButton.Value==1;
                app.unimasa=1;
            else app.ArrobasaoButton.Value==1;
                app.unimasa=0.025;
            end

            producto=(4/3)*(((1/365)*1000*0.83845*4*app.Spinner.Value*app.aproxtemp1*app.ArrozCheckBox.Value)+((1/365)*1000*1.204*3*app.AlgodnSpinner.Value*app.aproxtemp1*app.AlgodonCheckBox.Value)+((1/365)*1000*0.574323*2*app.BananoSpinner.Value*app.aproxtemp1*app.BananoCheckBox.Value)+((1/365)*1000*0.41505*2*app.PlatanoSpinner.Value*app.aproxtemp1*app.PlatanoCheckBox.Value)+((1/365)*1000*2.60926*3*app.CaaazucarSpinner.Value*app.aproxtemp1*app.CaaAzucarCheckBox.Value)+((1/365)*1000*2.94376*3*app.CaaPaneleraSpinner.Value*app.aproxtemp1*app.CaaPaneleraCheckBox.Value)+((1/365)*1000*2.8524*3*app.CafeSpinner.Value*app.aproxtemp1*app.CafeCheckBox.Value)+((1/365)*1000*1.13316*4*app.CocoSpinner.Value*app.aproxtemp1*app.CocoCheckBox.Value)+((1/365)*1000*1.1921*3*app.FrijolSpinner.Value*app.aproxtemp1*app.FrijolCheckBox.Value)+((1/365)*1000*1.03179*3*app.MaizSpinner.Value*app.aproxtemp1*app.MaizCheckBox.Value)+((1/365)*1000*2.1672*4*app.ManiSpinner.Value*app.aproxtemp1*app.ManiCheckBox.Value)+((1/365)*1000*1.07098*3*app.PalmaAceiteSpinner.Value*app.aproxtemp1*app.PalmaAceiteCheckBox.Value)+((1/365)*1000*1.8576*3*app.SoyaSpinner.Value*app.aproxtemp1*app.SoyaCheckBox.Value)+((1/365)*1000*1.9758*3*app.TrigoSpinner.Value*app.aproxtemp1*app.TrigoCheckBox.Value)+((1/365)*1000*0.7568*3*app.YucaSpinner.Value*app.aproxtemp1*app.YucaCheckBox.Value)+(8*3*app.BovinosSpinner.Value*app.aproxtemp*app.BovinosCheckBox.Value)+(12*3*app.BufalinaSpinner.Value*app.aproxtemp*app.BufalinaCheckBox.Value)+(2*3*app.PorcinaSpinner.Value*app.aproxtemp*app.PorcinaCheckBox.Value)+(1*3*app.OvinaSpinner.Value*app.aproxtemp*app.OvinaCheckBox.Value)+(1*3*app.CaprinaSpinner.Value*app.aproxtemp*app.CaprinaCheckBox.Value)+(10*3*app.EquinaSpinner.Value*app.aproxtemp*app.EquinaCheckBox.Value)+(0.08*3*app.AvicolaSpinner.Value*app.aproxtemp*app.AvicolaCheckBox.Value));
            app.VolumenbiodigestorltEditField.Value=producto;
            app.ReactorltEditField.Value=producto;
            app.CamaradecargaltEditField.Value=producto*0.1;
            app.CamaradedescargaltEditField.Value=producto*0.1;


            productos=(4/3)*(((1/365)*1000*0.83845*4*app.Spinner.Value*app.aproxtemp2*app.ArrozCheckBox.Value)+((1/365)*1000*1.204*3*app.AlgodnSpinner.Value*app.aproxtemp2*app.AlgodonCheckBox.Value)+((1/365)*1000*0.574323*2*app.BananoSpinner.Value*app.aproxtemp2*app.BananoCheckBox.Value)+((1/365)*1000*0.41505*2*app.PlatanoSpinner.Value*app.aproxtemp2*app.PlatanoCheckBox.Value)+((1/365)*1000*2.60926*3*app.CaaazucarSpinner.Value*app.aproxtemp2*app.CaaAzucarCheckBox.Value)+((1/365)*1000*2.94376*3*app.CaaPaneleraSpinner.Value*app.aproxtemp2*app.CaaPaneleraCheckBox.Value)+((1/365)*1000*2.8524*3*app.CafeSpinner.Value*app.aproxtemp2*app.CafeCheckBox.Value)+((1/365)*1000*1.13316*4*app.CocoSpinner.Value*app.aproxtemp2*app.CocoCheckBox.Value)+((1/365)*1000*1.1921*3*app.FrijolSpinner.Value*app.aproxtemp2*app.FrijolCheckBox.Value)+((1/365)*1000*1.03179*3*app.MaizSpinner.Value*app.aproxtemp2*app.MaizCheckBox.Value)+((1/365)*1000*2.1672*4*app.ManiSpinner.Value*app.aproxtemp2*app.ManiCheckBox.Value)+((1/365)*1000*1.07098*3*app.PalmaAceiteSpinner.Value*app.aproxtemp2*app.PalmaAceiteCheckBox.Value)+((1/365)*1000*1.8576*3*app.SoyaSpinner.Value*app.aproxtemp2*app.SoyaCheckBox.Value)+((1/365)*1000*1.9758*3*app.TrigoSpinner.Value*app.aproxtemp2*app.TrigoCheckBox.Value)+((1/365)*1000*0.7568*3*app.YucaSpinner.Value*app.aproxtemp2*app.YucaCheckBox.Value)+(8*3*app.BovinosSpinner.Value*app.aproxtemp2*app.BovinosCheckBox.Value)+(12*3*app.BufalinaSpinner.Value*app.aproxtemp2*app.BufalinaCheckBox.Value)+(2*3*app.PorcinaSpinner.Value*app.aproxtemp2*app.PorcinaCheckBox.Value)+(1*3*app.OvinaSpinner.Value*app.aproxtemp2*app.OvinaCheckBox.Value)+(1*3*app.CaprinaSpinner.Value*app.aproxtemp2*app.CaprinaCheckBox.Value)+(10*3*app.EquinaSpinner.Value*app.aproxtemp2*app.EquinaCheckBox.Value)+(0.08*3*app.AvicolaSpinner.Value*app.aproxtemp2*app.AvicolaCheckBox.Value));
            app.VolumenbiodigestorltEditField_2.Value=productos;
            app.ReactorltEditField_2.Value=productos;
            app.CamaradecargaltEditField_2.Value=productos*0.1;
            app.CamaradedescargaltEditField_2.Value=productos*0.1;


            producto2=((app.unimasa*277.777*10^(-6))*((2.35*0.275*13023*app.Spinner.Value*app.ArrozCheckBox.Value)+(0.2*0.961*15074*app.Spinner.Value*app.ArrozCheckBox.Value)+(1.4*0.86*14600*app.AlgodnSpinner.Value*app.AlgodonCheckBox.Value)+(1.59*0.057*7568.9*app.BananoSpinner.Value*app.BananoCheckBox.Value)+(7.95*0.066*8501.9*app.BananoSpinner.Value*app.BananoCheckBox.Value)+(0.24*0.169*10409.8*app.BananoSpinner.Value*app.BananoCheckBox.Value)+(1*0.057*7564.7*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+(5*0.066*8501.9*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+(0.15*0.187*9405.6*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+(3.26*0.317*15413.9*app.CaaazucarSpinner.Value*app.CaaAzucarCheckBox.Value)+(2.68*0.588*18643.9*app.CaaazucarSpinner.Value*app.CaaAzucarCheckBox.Value)+(3.75*0.588*18643.9*app.CaaPaneleraSpinner.Value*app.CaaPaneleraCheckBox.Value)+(2.53*0.292*16765.3*app.CaaPaneleraSpinner.Value*app.CaaPaneleraCheckBox.Value)+(2.13*0.201*17819.7*app.CafeSpinner.Value*app.CafeCheckBox.Value)+(0.21*0.931*18535.1*app.CafeSpinner.Value*app.CafeCheckBox.Value)+(3.02*0.738*18342.7*app.CafeSpinner.Value*app.CafeCheckBox.Value)+(0.84*0.919*21500*app.CocoSpinner.Value*app.CocoCheckBox.Value)+(0.42*0.86*20900*app.CocoSpinner.Value*app.CocoCheckBox.Value)+(1.31*0.91*14165*app.FrijolSpinner.Value*app.FrijolCheckBox.Value)+(0.93*0.685*14346.9*app.MaizSpinner.Value*app.MaizCheckBox.Value)+(0.27*0.734*14183.8*app.MaizSpinner.Value*app.MaizCheckBox.Value)+(0.21*0.936*16213*app.MaizSpinner.Value*app.MaizCheckBox.Value)+(2.52*0.86*20100*app.ManiSpinner.Value*app.ManiCheckBox.Value)+(0.22*0.833*16685.8*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+(0.63*0.694*17882.4*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+(1.06*0.425*16823.9*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+(2.16*0.86*17345*app.SoyaSpinner.Value*app.SoyaCheckBox.Value)+(2.22*0.89*15970*app.TrigoSpinner.Value*app.TrigoCheckBox.Value)+(0.88*0.86*18045*app.YucaSpinner.Value*app.YucaCheckBox.Value))+((21600*365*8*0.04*app.BovinosSpinner.Value*app.BovinosCheckBox.Value)+(21600*365*12*0.04*app.BufalinaSpinner.Value*app.BufalinaCheckBox.Value)+(21600*365*2*0.07*app.PorcinaSpinner.Value*app.PorcinaCheckBox.Value)+(21600*365*1*0.05*app.OvinaSpinner.Value*app.OvinaCheckBox.Value)+(21600*365*0.05*1*app.CaprinaSpinner.Value*app.CaprinaCheckBox.Value)+(21600*365*10*0.04*app.EquinaSpinner.Value*app.EquinaCheckBox.Value)+(21600*365*0.08*0.06*app.AvicolaSpinner.Value*app.AvicolaCheckBox.Value))*(277.777*10^(-9)));
            app.PotencialtotalfuentebiomasaMwhaoEditField.Value=producto2

            producto3=((app.unimasa*277.777*10^(-6))*((0.2*0.961*15074*app.Spinner.Value*1*app.ArrozCheckBox.Value)+(2.35*0.275*13023*app.Spinner.Value*0.4*app.ArrozCheckBox.Value)+(1.4*0.86*14600*app.AlgodnSpinner.Value*0.4*app.AlgodonCheckBox.Value)+(1.59*0.057*7568.9*app.BananoSpinner.Value*0.5*app.BananoCheckBox.Value)+(7.95*0.066*8501.9*app.BananoSpinner.Value*0.5*app.BananoCheckBox.Value)+(0.24*0.169*10409.8*app.BananoSpinner.Value*1*app.BananoCheckBox.Value)+(1*0.057*7564.7*app.PlatanoSpinner.Value*0.5*app.PlatanoCheckBox.Value)+(5*0.066*8501.9*app.PlatanoSpinner.Value*0.5*app.PlatanoCheckBox.Value)+(0.15*0.187*9405.6*app.PlatanoSpinner.Value*1*app.PlatanoCheckBox.Value)+(3.26*0.317*15413.9*app.CaaazucarSpinner.Value*0.65*app.CaaAzucarCheckBox.Value)+(2.68*0.588*18643.9*app.CaaazucarSpinner.Value*1*app.CaaAzucarCheckBox.Value)+(3.75*0.588*18643.9*app.CaaPaneleraSpinner.Value*1*app.CaaPaneleraCheckBox.Value)+(2.53*0.292*16765.3*app.CaaPaneleraSpinner.Value*0.65*app.CaaPaneleraCheckBox.Value)+(2.13*0.201*17819.7*app.CafeSpinner.Value*1*app.CafeCheckBox.Value)+(0.21*0.931*18535.1*app.CafeSpinner.Value*1*app.CafeCheckBox.Value)+(3.02*0.738*18342.7*app.CafeSpinner.Value*0.5*app.CafeCheckBox.Value)+(0.84*0.919*21500*app.CocoSpinner.Value*1*app.CocoCheckBox.Value)+(0.42*0.86*20900*app.CocoSpinner.Value*1*app.CocoCheckBox.Value)+(1.31*0.91*14165*app.FrijolSpinner.Value*0.4*app.FrijolCheckBox.Value)+(0.93*0.685*14346.9*app.MaizSpinner.Value*0.4*app.MaizCheckBox.Value)+(0.27*0.734*14183.8*app.MaizSpinner.Value*0.4*app.MaizCheckBox.Value)+(0.21*0.936*16213*app.MaizSpinner.Value*0.4*app.MaizCheckBox.Value)+(2.52*0.86*20100*app.ManiSpinner.Value*1*app.ManiCheckBox.Value)+(0.22*0.833*16685.8*app.PalmaAceiteSpinner.Value*1*app.PalmaAceiteCheckBox.Value)+(0.63*0.694*17882.4*app.PalmaAceiteSpinner.Value*1*app.PalmaAceiteCheckBox.Value)+(1.06*0.425*16823.9*app.PalmaAceiteSpinner.Value*1*app.PalmaAceiteCheckBox.Value)+(2.16*0.86*17345*app.SoyaSpinner.Value*0.3*app.SoyaCheckBox.Value)+(2.22*0.89*15970*app.TrigoSpinner.Value*0.4*app.TrigoCheckBox.Value)+(0.88*0.86*18045*app.YucaSpinner.Value*0.4*app.YucaCheckBox.Value))+((21600*365*8*0.04*app.BovinosSpinner.Value*0.014*app.BovinosCheckBox.Value)+(21600*365*12*0.04*app.BufalinaSpinner.Value*0.014*app.BufalinaCheckBox.Value)+(21600*365*2*0.07*app.PorcinaSpinner.Value*0.657*app.PorcinaCheckBox.Value)+(21600*365*1*0.05*app.OvinaSpinner.Value*0.040*app.OvinaCheckBox.Value)+(21600*365*0.05*1*app.CaprinaSpinner.Value*0.02*app.CaprinaCheckBox.Value)+(21600*365*10*0.04*app.EquinaSpinner.Value*0.1*app.EquinaCheckBox.Value)+(21600*365*0.08*0.06*app.AvicolaSpinner.Value*0.962*app.AvicolaCheckBox.Value))*(app.unimasa*277.777*10^(-9)));
            app.PotencialtotalrecursobiomasaMwhaoEditField.Value=producto3


        end

        % Callback function
        function UITableCellEdit(app, event)
           indices = event.Indices;
           newData = event.NewData;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            
             if app.TemperaturaCEditField.Value<=15
                app.aproxtemp=60;
             elseif app.TemperaturaCEditField.Value>26
                app.aproxtemp=15;
             else 15>app.TemperaturaCEditField.Value<26;
                app.aproxtemp=25;
                
             end

            Value = app.VolumenbiodigestorltEditField.Value;
            
            app.VolumenbiodigestorltEditField.Value=producto;
            app.VolumenbiodigestorltEditField_2.Value=productos;


        end

        % Value changed function: TemperaturaCEditField
        function TemperaturaCEditFieldValueChanged(app, event)
            value = app.TemperaturaCEditField.Value;
        end

        % Value changed function: VolumenbiodigestorltEditField
        function VolumenbiodigestorltEditFieldValueChanged2(app, event)
            value = app.VolumenbiodigestorltEditField.Value;
            app.VolumenbiodigestorltEditField.Value=producto;

             
        end

        % Button pushed function: PotencialenergeticoButton, 
        % ...and 1 other component
        function PotencialenergeticoButtonPushed(app, event)
            
            app.TabGroup.SelectedTab = app.Tab6;
         if app.idioma == "Español"
            datosburbuja=[((app.unimasa*277.777*10^(-6))*2.35*0.275*13023*app.Spinner.Value*app.ArrozCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.2*0.961*15074*app.Spinner.Value*app.ArrozCheckBox.Value),((app.unimasa*277.777*10^(-6))*1.4*0.86*14600*app.AlgodnSpinner.Value*app.AlgodonCheckBox.Value),((app.unimasa*277.777*10^(-6))*1.59*0.057*7568.9*app.BananoSpinner.Value*app.BananoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*7.95*0.066*8501.9*app.BananoSpinner.Value*app.BananoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.24*0.169*10409.8*app.BananoSpinner.Value*app.BananoCheckBox.Value),((app.unimasa*277.777*10^(-6))*1*0.057*7564.7*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*5*0.066*8501.9*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.15*0.187*9405.6*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value),((app.unimasa*277.777*10^(-6))*3.26*0.317*15413.9*app.CaaazucarSpinner.Value*app.CaaAzucarCheckBox.Value)+((app.unimasa*277.777*10^(-6))*2.68*0.588*18643.9*app.CaaazucarSpinner.Value*app.CaaAzucarCheckBox.Value),((app.unimasa*277.777*10^(-6))*3.75*0.588*18643.9*app.CaaPaneleraSpinner.Value*app.CaaPaneleraCheckBox.Value)+((app.unimasa*277.777*10^(-6))*2.53*0.292*16765.3*app.CaaPaneleraSpinner.Value*app.CaaPaneleraCheckBox.Value),((app.unimasa*277.777*10^(-6))*2.13*0.201*17819.7*app.CafeSpinner.Value*app.CafeCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.21*0.931*18535.1*app.CafeSpinner.Value*app.CafeCheckBox.Value)+((app.unimasa*277.777*10^(-6))*3.02*0.738*18342.7*app.CafeSpinner.Value*app.CafeCheckBox.Value),((app.unimasa*277.777*10^(-6))*0.84*0.919*21500*app.CocoSpinner.Value*app.CocoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.42*0.86*20900*app.CocoSpinner.Value*app.CocoCheckBox.Value),((app.unimasa*277.777*10^(-6))*1.31*0.91*14165*app.FrijolSpinner.Value*app.FrijolCheckBox.Value),((app.unimasa*277.777*10^(-6))*0.93*0.685*14346.9*app.MaizSpinner.Value*app.MaizCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.27*0.734*14183.8*app.MaizSpinner.Value*app.MaizCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.21*0.936*16213*app.MaizSpinner.Value*app.MaizCheckBox.Value),((app.unimasa*277.777*10^(-6))*2.52*0.86*20100*app.ManiSpinner.Value*app.ManiCheckBox.Value),((app.unimasa*277.777*10^(-6))*0.22*0.833*16685.8*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.63*0.694*17882.4*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+((app.unimasa*277.777*10^(-6))*1.06*0.425*16823.9*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value),((app.unimasa*277.777*10^(-6))*2.16*0.86*17345*app.SoyaSpinner.Value*app.SoyaCheckBox.Value),((app.unimasa*277.777*10^(-6))*2.22*0.89*15970*app.TrigoSpinner.Value*app.TrigoCheckBox.Value),((app.unimasa*277.777*10^(-6))*0.88*0.86*18045*app.YucaSpinner.Value*app.YucaCheckBox.Value),(277.777*10^(-6)*21.6*365*8*0.04*app.BovinosSpinner.Value*app.BovinosCheckBox.Value),(277.777*10^(-6)*21.6*365*12*0.04*app.BufalinaSpinner.Value*app.BufalinaCheckBox.Value),(277.777*10^(-6)*21.6*365*2*0.07*app.PorcinaSpinner.Value*app.PorcinaCheckBox.Value),(277.777*10^(-6)*21.6*365*1*0.05*app.OvinaSpinner.Value*app.OvinaCheckBox.Value),(277.777*10^(-6)*21.6*365*0.05*1*app.CaprinaSpinner.Value*app.CaprinaCheckBox.Value),(277.777*10^(-6)*21.6*365*10*0.04*app.EquinaSpinner.Value*app.EquinaCheckBox.Value),(277.777*10^(-6)*21.6*365*0.08*0.06*app.AvicolaSpinner.Value*app.AvicolaCheckBox.Value)];
            app.grafica1.XTickLabel={'Fuente','Recurso';};
            categorias=["Arroz" "Algodon" "Banano" "Platano" "Caña azucar" "Caña Panelera" "Cafe" "Coco" "Frijol" "Maiz" "Mani" "PalmaAceite" "Soya" "Trigo" "Yuca" "Bovinos" "Bufalina" "Porcina" "Ovina" "Caprina" "Equina" "Avicola"];
            grupos=["Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Agricola" "Pecuario" "Pecuario" "Pecuario" "Pecuario" "Pecuario" "Pecuario" "Pecuario"];
         else app.idioma == "English"
            datosburbuja=[((app.unimasa*277.777*10^(-6))*2.35*0.275*13023*app.Spinner.Value*app.ArrozCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.2*0.961*15074*app.Spinner.Value*app.ArrozCheckBox.Value),((app.unimasa*277.777*10^(-6))*1.4*0.86*14600*app.AlgodnSpinner.Value*app.AlgodonCheckBox.Value),((app.unimasa*277.777*10^(-6))*1.59*0.057*7568.9*app.BananoSpinner.Value*app.BananoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*7.95*0.066*8501.9*app.BananoSpinner.Value*app.BananoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.24*0.169*10409.8*app.BananoSpinner.Value*app.BananoCheckBox.Value),((app.unimasa*277.777*10^(-6))*1*0.057*7564.7*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*5*0.066*8501.9*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.15*0.187*9405.6*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value),((app.unimasa*277.777*10^(-6))*3.26*0.317*15413.9*app.CaaazucarSpinner.Value*app.CaaAzucarCheckBox.Value)+((app.unimasa*277.777*10^(-6))*2.68*0.588*18643.9*app.CaaazucarSpinner.Value*app.CaaAzucarCheckBox.Value),((app.unimasa*277.777*10^(-6))*3.75*0.588*18643.9*app.CaaPaneleraSpinner.Value*app.CaaPaneleraCheckBox.Value)+((app.unimasa*277.777*10^(-6))*2.53*0.292*16765.3*app.CaaPaneleraSpinner.Value*app.CaaPaneleraCheckBox.Value),((app.unimasa*277.777*10^(-6))*2.13*0.201*17819.7*app.CafeSpinner.Value*app.CafeCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.21*0.931*18535.1*app.CafeSpinner.Value*app.CafeCheckBox.Value)+((app.unimasa*277.777*10^(-6))*3.02*0.738*18342.7*app.CafeSpinner.Value*app.CafeCheckBox.Value),((app.unimasa*277.777*10^(-6))*0.84*0.919*21500*app.CocoSpinner.Value*app.CocoCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.42*0.86*20900*app.CocoSpinner.Value*app.CocoCheckBox.Value),((app.unimasa*277.777*10^(-6))*1.31*0.91*14165*app.FrijolSpinner.Value*app.FrijolCheckBox.Value),((app.unimasa*277.777*10^(-6))*0.93*0.685*14346.9*app.MaizSpinner.Value*app.MaizCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.27*0.734*14183.8*app.MaizSpinner.Value*app.MaizCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.21*0.936*16213*app.MaizSpinner.Value*app.MaizCheckBox.Value),((app.unimasa*277.777*10^(-6))*2.52*0.86*20100*app.ManiSpinner.Value*app.ManiCheckBox.Value),((app.unimasa*277.777*10^(-6))*0.22*0.833*16685.8*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+((app.unimasa*277.777*10^(-6))*0.63*0.694*17882.4*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+((app.unimasa*277.777*10^(-6))*1.06*0.425*16823.9*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value),((app.unimasa*277.777*10^(-6))*2.16*0.86*17345*app.SoyaSpinner.Value*app.SoyaCheckBox.Value),((app.unimasa*277.777*10^(-6))*2.22*0.89*15970*app.TrigoSpinner.Value*app.TrigoCheckBox.Value),((app.unimasa*277.777*10^(-6))*0.88*0.86*18045*app.YucaSpinner.Value*app.YucaCheckBox.Value),(277.777*10^(-6)*21.6*365*8*0.04*app.BovinosSpinner.Value*app.BovinosCheckBox.Value),(277.777*10^(-6)*21.6*365*12*0.04*app.BufalinaSpinner.Value*app.BufalinaCheckBox.Value),(277.777*10^(-6)*21.6*365*2*0.07*app.PorcinaSpinner.Value*app.PorcinaCheckBox.Value),(277.777*10^(-6)*21.6*365*1*0.05*app.OvinaSpinner.Value*app.OvinaCheckBox.Value),(277.777*10^(-6)*21.6*365*0.05*1*app.CaprinaSpinner.Value*app.CaprinaCheckBox.Value),(277.777*10^(-6)*21.6*365*10*0.04*app.EquinaSpinner.Value*app.EquinaCheckBox.Value),(277.777*10^(-6)*21.6*365*0.08*0.06*app.AvicolaSpinner.Value*app.AvicolaCheckBox.Value)];
            categorias=["Rice" "Cotton" "Banana" "Plantain" "Sugarcane" "Panelera Cane" "Coffee" "Coconut" "Bean" "Corn" "Peanut" "Oil palm" "Soy" "Wheat" "Manioc" "Bovine" "Buffalo" "Porcine" "Sheep" "Goat" "Equine" "Poultry"];
            grupos=["Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "Agricultural" "livestock" "livestock" "livestock" "livestock" "livestock" "livestock" "livestock"];
            app.grafica1.XTickLabel={'Source','Resource';};
         end
            bubblecloud(app.PotencialenergeticoporcategoriasPanel,datosburbuja,categorias,grupos);
         
            producto2=((app.unimasa*277.777*10^(-6))*((2.35*0.275*13023*app.Spinner.Value*app.ArrozCheckBox.Value)+(0.2*0.961*15074*app.Spinner.Value*app.ArrozCheckBox.Value)+(1.4*0.86*14600*app.AlgodnSpinner.Value*app.AlgodonCheckBox.Value)+(1.59*0.057*7568.9*app.BananoSpinner.Value*app.BananoCheckBox.Value)+(7.95*0.066*8501.9*app.BananoSpinner.Value*app.BananoCheckBox.Value)+(0.24*0.169*10409.8*app.BananoSpinner.Value*app.BananoCheckBox.Value)+(1*0.057*7564.7*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+(5*0.066*8501.9*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+(0.15*0.187*9405.6*app.PlatanoSpinner.Value*app.PlatanoCheckBox.Value)+(3.26*0.317*15413.9*app.CaaazucarSpinner.Value*app.CaaAzucarCheckBox.Value)+(2.68*0.588*18643.9*app.CaaazucarSpinner.Value*app.CaaAzucarCheckBox.Value)+(3.75*0.588*18643.9*app.CaaPaneleraSpinner.Value*app.CaaPaneleraCheckBox.Value)+(2.53*0.292*16765.3*app.CaaPaneleraSpinner.Value*app.CaaPaneleraCheckBox.Value)+(2.13*0.201*17819.7*app.CafeSpinner.Value*app.CafeCheckBox.Value)+(0.21*0.931*18535.1*app.CafeSpinner.Value*app.CafeCheckBox.Value)+(3.02*0.738*18342.7*app.CafeSpinner.Value*app.CafeCheckBox.Value)+(0.84*0.919*21500*app.CocoSpinner.Value*app.CocoCheckBox.Value)+(0.42*0.86*20900*app.CocoSpinner.Value*app.CocoCheckBox.Value)+(1.31*0.91*14165*app.FrijolSpinner.Value*app.FrijolCheckBox.Value)+(0.93*0.685*14346.9*app.MaizSpinner.Value*app.MaizCheckBox.Value)+(0.27*0.734*14183.8*app.MaizSpinner.Value*app.MaizCheckBox.Value)+(0.21*0.936*16213*app.MaizSpinner.Value*app.MaizCheckBox.Value)+(2.52*0.86*20100*app.ManiSpinner.Value*app.ManiCheckBox.Value)+(0.22*0.833*16685.8*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+(0.63*0.694*17882.4*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+(1.06*0.425*16823.9*app.PalmaAceiteSpinner.Value*app.PalmaAceiteCheckBox.Value)+(2.16*0.86*17345*app.SoyaSpinner.Value*app.SoyaCheckBox.Value)+(2.22*0.89*15970*app.TrigoSpinner.Value*app.TrigoCheckBox.Value)+(0.88*0.86*18045*app.YucaSpinner.Value*app.YucaCheckBox.Value))+((21600*365*8*0.04*app.BovinosSpinner.Value*app.BovinosCheckBox.Value)+(21600*365*12*0.04*app.BufalinaSpinner.Value*app.BufalinaCheckBox.Value)+(21600*365*2*0.07*app.PorcinaSpinner.Value*app.PorcinaCheckBox.Value)+(21600*365*1*0.05*app.OvinaSpinner.Value*app.OvinaCheckBox.Value)+(21600*365*0.05*1*app.CaprinaSpinner.Value*app.CaprinaCheckBox.Value)+(21600*365*10*0.04*app.EquinaSpinner.Value*app.EquinaCheckBox.Value)+(21600*365*0.08*0.06*app.AvicolaSpinner.Value*app.AvicolaCheckBox.Value))*(277.777*10^(-9)));
            producto3=((app.unimasa*277.777*10^(-6))*((0.2*0.961*15074*app.Spinner.Value*1*app.ArrozCheckBox.Value)+(2.35*0.275*13023*app.Spinner.Value*0.4*app.ArrozCheckBox.Value)+(1.4*0.86*14600*app.AlgodnSpinner.Value*0.4*app.AlgodonCheckBox.Value)+(1.59*0.057*7568.9*app.BananoSpinner.Value*0.5*app.BananoCheckBox.Value)+(7.95*0.066*8501.9*app.BananoSpinner.Value*0.5*app.BananoCheckBox.Value)+(0.24*0.169*10409.8*app.BananoSpinner.Value*1*app.BananoCheckBox.Value)+(1*0.057*7564.7*app.PlatanoSpinner.Value*0.5*app.PlatanoCheckBox.Value)+(5*0.066*8501.9*app.PlatanoSpinner.Value*0.5*app.PlatanoCheckBox.Value)+(0.15*0.187*9405.6*app.PlatanoSpinner.Value*1*app.PlatanoCheckBox.Value)+(3.26*0.317*15413.9*app.CaaazucarSpinner.Value*0.65*app.CaaAzucarCheckBox.Value)+(2.68*0.588*18643.9*app.CaaazucarSpinner.Value*1*app.CaaAzucarCheckBox.Value)+(3.75*0.588*18643.9*app.CaaPaneleraSpinner.Value*1*app.CaaPaneleraCheckBox.Value)+(2.53*0.292*16765.3*app.CaaPaneleraSpinner.Value*0.65*app.CaaPaneleraCheckBox.Value)+(2.13*0.201*17819.7*app.CafeSpinner.Value*1*app.CafeCheckBox.Value)+(0.21*0.931*18535.1*app.CafeSpinner.Value*1*app.CafeCheckBox.Value)+(3.02*0.738*18342.7*app.CafeSpinner.Value*0.5*app.CafeCheckBox.Value)+(0.84*0.919*21500*app.CocoSpinner.Value*1*app.CocoCheckBox.Value)+(0.42*0.86*20900*app.CocoSpinner.Value*1*app.CocoCheckBox.Value)+(1.31*0.91*14165*app.FrijolSpinner.Value*0.4*app.FrijolCheckBox.Value)+(0.93*0.685*14346.9*app.MaizSpinner.Value*0.4*app.MaizCheckBox.Value)+(0.27*0.734*14183.8*app.MaizSpinner.Value*0.4*app.MaizCheckBox.Value)+(0.21*0.936*16213*app.MaizSpinner.Value*0.4*app.MaizCheckBox.Value)+(2.52*0.86*20100*app.ManiSpinner.Value*1*app.ManiCheckBox.Value)+(0.22*0.833*16685.8*app.PalmaAceiteSpinner.Value*1*app.PalmaAceiteCheckBox.Value)+(0.63*0.694*17882.4*app.PalmaAceiteSpinner.Value*1*app.PalmaAceiteCheckBox.Value)+(1.06*0.425*16823.9*app.PalmaAceiteSpinner.Value*1*app.PalmaAceiteCheckBox.Value)+(2.16*0.86*17345*app.SoyaSpinner.Value*0.3*app.SoyaCheckBox.Value)+(2.22*0.89*15970*app.TrigoSpinner.Value*0.4*app.TrigoCheckBox.Value)+(0.88*0.86*18045*app.YucaSpinner.Value*0.4*app.YucaCheckBox.Value))+((21600*365*8*0.04*app.BovinosSpinner.Value*0.014*app.BovinosCheckBox.Value)+(21600*365*12*0.04*app.BufalinaSpinner.Value*0.014*app.BufalinaCheckBox.Value)+(21600*365*2*0.07*app.PorcinaSpinner.Value*0.657*app.PorcinaCheckBox.Value)+(21600*365*1*0.05*app.OvinaSpinner.Value*0.040*app.OvinaCheckBox.Value)+(21600*365*0.05*1*app.CaprinaSpinner.Value*0.02*app.CaprinaCheckBox.Value)+(21600*365*10*0.04*app.EquinaSpinner.Value*0.1*app.EquinaCheckBox.Value)+(21600*365*0.08*0.06*app.AvicolaSpinner.Value*0.962*app.AvicolaCheckBox.Value))*(app.unimasa*277.777*10^(-9)));
            X=[0 1];
            explode=[producto2 producto3];
            bar(app.grafica1,X,explode,'grouped','cyan');
            

        end

        % Cell edit callback: UITable2_3
        function UITable2_2CellEdit(app, event)
            importes(app,event)
            
        end

        % Callback function
        function VolumenbiodigestoragricolaltEditFieldValueChanged(app, event)
            value = app.VolumenbiodigestoragricolaltEditField.Value;

        end

        % Callback function
        function VolumenbiodigestoragricolaltEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: BiomasaPecuariayAgricolaButton
        function BiomasaPecuariayAgricolaButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.Tab2;
             app.SeleccionbiomasapecuariaButton.Visible="on";
             app.SeleccionbiomasapecuariaButton.Enable="on";
             app.SeleccionbiomasaagricolaButton.Visible="on";
             app.SeleccionbiomasaagricolaButton.Enable="on";
             app.ContinuarButton.Visible="off";
             app.ContinuarButton.Enable="off";
             app.VolumenbiodigestorltEditField_2.Visible="on";
             app.VolumenbiodigestorltEditField.Visible="off";
            
             app.ReactorltEditField_2.Visible="on";
             app.ReactorltEditField.Visible="off";
             app.CamaradecargaltEditField_2.Visible="on";
             app.CamaradecargaltEditField.Visible="off";
             app.CamaradedescargaltEditField_2.Visible="on";
             app.CamaradedescargaltEditField.Visible="off";

             app.AvicolaCheckBox.Value = 0;
             app.EquinaCheckBox.Value = 0;
             app.CaprinaCheckBox.Value = 0;
             app.OvinaCheckBox.Value = 0;
             app.PorcinaCheckBox.Value = 0;
             app.BufalinaCheckBox.Value = 0;
             app.BovinosCheckBox.Value = 0;
             app.AvicolaSpinner.Value = 0;
             app.EquinaSpinner.Value = 0;
             app.CaprinaSpinner.Value = 0;
             app.OvinaSpinner.Value = 0;
             app.PorcinaSpinner.Value = 0;
             app.BufalinaSpinner.Value = 0;
             app.BovinosSpinner.Value = 0;
             app.AvicolaSpinner.Enable ="off";
             app.EquinaSpinner.Enable ="off";
             app.CaprinaSpinner.Enable ="off";
             app.OvinaSpinner.Enable ="off";
             app.PorcinaSpinner.Enable ="off";
             app.BufalinaSpinner.Enable ="off";
             app.BovinosSpinner.Enable ="off";
             app.YucaCheckBox.Value = 0;
             app.TrigoCheckBox.Value = 0;
             app.SoyaCheckBox.Value = 0;
             app.PalmaAceiteCheckBox.Value = 0;
             app.ManiCheckBox.Value = 0;
             app.MaizCheckBox.Value = 0;
             app.FrijolCheckBox.Value = 0;
             app.CocoCheckBox.Value = 0;
             app.CafeCheckBox.Value = 0;
             app.CaaPaneleraCheckBox.Value = 0;
             app.CaaAzucarCheckBox.Value = 0;
             app.PlatanoCheckBox.Value = 0;
             app.BananoCheckBox.Value = 0;
             app.AlgodonCheckBox.Value = 0;
             app.ArrozCheckBox.Value = 0;

             app.YucaSpinner.Value = 0;
             app.TrigoSpinner.Value = 0;
             app.SoyaSpinner.Value = 0;
             app.PalmaAceiteSpinner.Value = 0;
             app.ManiSpinner.Value = 0;
             app.MaizSpinner.Value = 0;
             app.FrijolSpinner.Value = 0;
             app.CocoSpinner.Value = 0;
             app.CafeSpinner.Value = 0;
             app.CaaPaneleraSpinner.Value = 0;
             app.CaaazucarSpinner.Value = 0;
             app.PlatanoSpinner.Value = 0;
             app.BananoSpinner.Value = 0;
             app.AlgodnSpinner.Value = 0;
             app.Spinner.Value = 0;

             app.YucaSpinner.Enable ="off";
             app.TrigoSpinner.Enable ="off";
             app.SoyaSpinner.Enable ="off";
             app.PalmaAceiteSpinner.Enable ="off";
             app.ManiSpinner.Enable ="off";
             app.MaizSpinner.Enable ="off";
             app.FrijolSpinner.Enable ="off";
             app.CocoSpinner.Enable ="off";
             app.CafeSpinner.Enable ="off";
             app.CaaPaneleraSpinner.Enable ="off";
             app.CaaazucarSpinner.Enable ="off";
             app.PlatanoSpinner.Enable ="off";
             app.BananoSpinner.Enable ="off";
             app.AlgodnSpinner.Enable ="off";
             app.Spinner.Enable ="off";

        end

        % Button pushed function: SeleccionbiomasapecuariaButton
        function SeleccionbiomasapecuariaButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.Tab3;
        end

        % Button pushed function: SeleccionbiomasaagricolaButton
        function SeleccionbiomasaagricolaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Value changed function: VolumenbiodigestorltEditField_2
        function VolumenbiodigestorltEditField_2ValueChanged(app, event)
            value = app.VolumenbiodigestorltEditField_2.Value;
            app.VolumenbiodigestorltEditField_2.Value=productos;

        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)

            delete(app)


        end

        % Value changed function: PotencialtotalfuentebiomasaMwhaoEditField
        function potencialtotalfuente(app, event)
            value = app.PotencialtotalfuentebiomasaMwhaoEditField.Value;
            app.PotencialtotalfuentebiomasaMwhaoEditField.Value=producto2;
            
        end

        % Value changed function: 
        % PotencialtotalrecursobiomasaMwhaoEditField
        function potencialtotalrecurso(app, event)
            value = app.PotencialtotalrecursobiomasaMwhaoEditField.Value;
                    app.PotencialtotalrecursobiomasaMwhaoEditField.Value=producto3;
        end

        % Callback function
        function unidadesmasa(app, event)
       
            
        end

        % Button pushed function: SugerenciaelementosbiodigestorButton
        function SugerenciaelementosbiodigestorButtonPushed(app, event)
        app.TabGroup.SelectedTab = app.Tab7;

        end

        % Image clicked function: Image
        function ImageClicked(app, event)

            imshow("mapa_temp.jpeg",InitialMagnification=250);
            
        end

        % Value changed function: ReactorltEditField
        function ReactorltEditFieldValueChanged(app, event)

        end

        % Value changed function: CamaradecargaltEditField
        function CamaradecargaltEditFieldValueChanged(app, event)

        end

        % Value changed function: CamaradedescargaltEditField
        function CamaradedescargaltEditFieldValueChanged(app, event)

        end

        % Value changed function: ReactorltEditField_2
        function ReactorltEditField_2ValueChanged(app, event)

        end

        % Value changed function: CamaradecargaltEditField_2
        function CamaradecargaltEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: CamaradedescargaltEditField_2
        function CamaradedescargaltEditField_2ValueChanged(app, event)

        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";
            agricola2=readtable("datos_agricola_ingles.xlsx");
            app.UITable2_2.Data=agricola2;
            pecuario10=readtable("datos_pecuario_ingles.xlsx");
            app.UITable2_3.Data=pecuario10;
            agricola11=readtable("datos_agricola1_ingles.xlsx");
            pecuaria11=readtable("datos_pecuario1_ingles.xlsx");

            app.UITable3.Data = agricola11;
            app.UITable.Data = pecuaria11;


        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";
            agricola2=readtable("datos_agricola.xlsx");
            app.UITable2_2.Data=agricola2;
            pecuario10=readtable("datos_pecuario.xlsx");
            app.UITable2_3.Data=pecuario10;
            agricola11=readtable("datos_agricola1.xlsx");
            pecuaria11=readtable("datos_pecuario1.xlsx");

            app.UITable3.Data = agricola11;
            app.UITable.Data = pecuaria11;


        end

        % Selection changed function: CategoriasButtonGroup
        function CategoriasButtonGroupSelectionChanged(app, event)
            selectedButton = app.CategoriasButtonGroup.SelectedObject;
            app.pecuario=readtable("datos_pecuario.xlsx");
            app.UITable2_3.Data=app.pecuario; 


            col1=app.EstiercolkgdiaButton.Value;
            col2=app.MSButton.Value;
            col3=app.MOSButton.Value;
            col4=app.CNButton.Value;
            col5=app.Biogasm3kgButton.Value;
            col6=app.DRButton.Value;
            app.estiercol=table2array(app.pecuario(:,2));
            ms=table2array(app.pecuario(:,3));
            mos=table2array(app.pecuario(:,4));
            cn=table2array(app.pecuario(:,5));
            biogas=table2array(app.pecuario(:,6));
            dr=table2array(app.pecuario(:,7));
            
            if col1==1 && app.idioma == "Español"
                app.graficapecuario.Title.String='Estiercol (kg/dia)';
                bar(app.graficapecuario,app.estiercol,'k');
                app.graficapecuario.XTickLabel ={'Bovinos','Bufalina','Porcina','Ovina','Caprina','Equina','Avicola'};
                app.graficapecuario.XLabel.String='Especies';
                app.graficapecuario.YLabel.String='Estiercol (kg/dia)';
            elseif col2==1 && app.idioma == "Español"
                app.graficapecuario.Title.String='%Materia seca';
                bar(app.graficapecuario,ms,'y');
                app.graficapecuario.XTickLabel ={'Bovinos','Bufalina','Porcina','Ovina','Caprina','Equina','Avicola'};
                app.graficapecuario.XLabel.String='Especies';
                app.graficapecuario.YLabel.String='%Materia seca';
            elseif col3==1 && app.idioma == "Español"
                app.graficapecuario.Title.String='%Materia organica seca';
                bar(app.graficapecuario,mos,'r');
                app.graficapecuario.XTickLabel ={'Bovinos','Bufalina','Porcina','Ovina','Caprina','Equina','Avicola'};
                app.graficapecuario.XLabel.String='Especies';
                app.graficapecuario.YLabel.String='%Materia organica seca';
            elseif col4==1 && app.idioma == "Español"
                app.graficapecuario.Title.String='Relacion carbono nitrogeno';
                bar(app.graficapecuario,cn,'c');
                app.graficapecuario.XTickLabel ={'Bovinos','Bufalina','Porcina','Ovina','Caprina','Equina','Avicola'};
                app.graficapecuario.XLabel.String='Especies';
                app.graficapecuario.YLabel.String='C/N';
            elseif col5==1 && app.idioma == "Español"
                app.graficapecuario.Title.String='Biogas producido(m^3/kg)';
                bar(app.graficapecuario,biogas,'g');
                app.graficapecuario.XTickLabel ={'Bovinos','Bufalina','Porcina','Ovina','Caprina','Equina','Avicola'};
                app.graficapecuario.XLabel.String='Especies';
                app.graficapecuario.YLabel.String='Biogas producido(m^3/kg)';
            elseif col6==1 && app.idioma == "Español"
                app.graficapecuario.Title.String='Disponibilidad del recurso';
                bar(app.graficapecuario,dr,'b');
                app.graficapecuario.XTickLabel ={'Bovinos','Bufalina','Porcina','Ovina','Caprina','Equina','Avicola'};
                app.graficapecuario.XLabel.String='Especies';
                app.graficapecuario.YLabel.String='DR';
            elseif col1==1 && app.idioma == "English"
                app.graficapecuario.Title.String='Dung (kg/day)';
                bar(app.graficapecuario,app.estiercol,'k');
                app.graficapecuario.XTickLabel ={'Bovine','Bufalina','porcine','sheep','Goat','Equine','Poultry'};
                app.graficapecuario.XLabel.String='Species';
                app.graficapecuario.YLabel.String='Dung (kg/day)';
            elseif col2==1 && app.idioma == "English"
                app.graficapecuario.Title.String='%Dry material';
                bar(app.graficapecuario,ms,'y');
                app.graficapecuario.XTickLabel ={'Bovine','Bufalina','porcine','sheep','Goat','Equine','Poultry'};
                app.graficapecuario.XLabel.String='Species';
                app.graficapecuario.YLabel.String='%Dry material';
            elseif col3==1 && app.idioma == "English"
                app.graficapecuario.Title.String='%dry organic matter';
                bar(app.graficapecuario,mos,'r');
                app.graficapecuario.XTickLabel ={'Bovine','Bufalina','porcine','sheep','Goat','Equine','Poultry'};
                app.graficapecuario.XLabel.String='Species';
                app.graficapecuario.YLabel.String='%dry organic matter';
            elseif col4==1 && app.idioma == "English"
                app.graficapecuario.Title.String='Carbon Nitrogen Relation';
                bar(app.graficapecuario,cn,'c');
                app.graficapecuario.XTickLabel ={'Bovine','Bufalina','porcine','sheep','Goat','Equine','Poultry'};
                app.graficapecuario.XLabel.String='Species';
                app.graficapecuario.YLabel.String='C/N';
            elseif col5==1 && app.idioma == "English"
                app.graficapecuario.Title.String='Biogas produced(m^3/kg)';
                bar(app.graficapecuario,biogas,'g');
                app.graficapecuario.XTickLabel ={'Bovine','Bufalina','porcine','sheep','Goat','Equine','Poultry'};
                app.graficapecuario.XLabel.String='Species';
                app.graficapecuario.YLabel.String='Biogas produced(m^3/kg)';
            elseif col6==1 && app.idioma == "English"
                app.graficapecuario.Title.String='Resource availability';
                bar(app.graficapecuario,dr,'b');
                app.graficapecuario.XTickLabel ={'Bovine','Bufalina','porcine','sheep','Goat','Equine','Poultry'};
                app.graficapecuario.XLabel.String='Species';
                app.graficapecuario.YLabel.String='DR';
            else
                app.graficapecuario.cla;


            end
            
        end

        % Button pushed function: InfoButton, InfoButton_2, InfoButton_3, 
        % ...and 4 other components
        function InformacionButtonPushed(app, event)
            app.Informacionapp = informacion(app,app.idioma);
        end

        % Button pushed function: VolverButton_4
        function VolverButtonInicio(app, event)
             delete(app)
        end

        % Button pushed function: SalirButton
        function SalirButon(app, event)
            delete(app)
        end

        % Button pushed function: VolverButton_5
        function volverboton5(app, event)
            app.TabGroup.SelectedTab = app.Tab4;
        end

        % Button pushed function: VolverButton_6
        function volverboton6(app, event)
            app.TabGroup.SelectedTab = app.Tab5;
        end

        % Button pushed function: VolverButton_7
        function volverboton7(app, event)
            app.TabGroup.SelectedTab = app.Tab5;
        end

        % Button pushed function: VertablacompletaButton
        function vertablaagricola(app, event)
           if app.idioma == "Español"
              agricola2=readtable("datos_agricola.xlsx");
           elseif app.idioma == "English"
              agricola2=readtable("datos_agricola_ingles.xlsx");
           end
           app.UITable2_2.Data=agricola2;
           app.UITable2_2.Visible="on";
        end

        % Button pushed function: CerrartablaButton
        function cerrartabla(app, event)
          app.UITable2_2.Visible="off";
        end

        % Button pushed function: CerrartablaButton_2
        function cerrartabla2(app, event)
          app.UITable2_3.Visible="off";
        end

        % Button pushed function: VertablacompletaButton_2
        function vertablapecuaria(app, event)
           if app.idioma == "Español"
           pecuario10=readtable("datos_pecuario.xlsx");
           elseif app.idioma == "English"
           pecuario10=readtable("datos_pecuario_ingles.xlsx");
           end
           app.UITable2_3.Data=pecuario10; 
           app.UITable2_3.Visible="on";
        end

        % Callback function
        function volverButtonTem(app, event)
          app.TabGroup.SelectedTab = app.Tab2;
        end

        % Value changed function: SeleccionarBiomasadenuevoButton
        function seleccionarbiomasaagain(app, event)
         app.TabGroup.SelectedTab = app.Tab2;
         app.SeleccionbiomasapecuariaButton.Visible="on";
         app.SeleccionbiomasapecuariaButton.Enable="on";
         app.SeleccionbiomasaagricolaButton.Visible="on";
         app.SeleccionbiomasaagricolaButton.Enable="on";
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IdiomaLanguageMenu = uimenu(app.UIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Text = 'Español';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab.ButtonDownFcn = createCallbackFcn(app, @VolverButton_2Pushed, true);
            app.BiomasaPecuariaButton = uibutton(app.Tab, 'push');
            app.BiomasaPecuariaButton.ButtonPushedFcn = createCallbackFcn(app, @BiomasaPecuariaButtonPushed, true);
            app.BiomasaPecuariaButton.Text = 'Biomasa Pecuaria';
            app.BiomasaPecuariaButton.Icon = fullfile(pathToMLAPP, 'pecuario.png');
            app.BiomasaAgricolaButton = uibutton(app.Tab, 'push');
            app.BiomasaAgricolaButton.ButtonPushedFcn = createCallbackFcn(app, @BiomasaAgricolaButtonPushed, true);
            app.BiomasaAgricolaButton.Text = 'Biomasa Agricola';
            app.BiomasaAgricolaButton.Icon = fullfile(pathToMLAPP, 'agricola.png');
            app.SeleccionelafuentedebiomasaquedeseaimplementarLabel = uilabel(app.Tab);
            app.SeleccionelafuentedebiomasaquedeseaimplementarLabel.Text = {'Seleccione la fuente de biomasa que desea implementar'; '(biomasa pecuaria y biomasa agricola)'};
            app.BiomasaPecuariayAgricolaButton = uibutton(app.Tab, 'push');
            app.BiomasaPecuariayAgricolaButton.ButtonPushedFcn = createCallbackFcn(app, @BiomasaPecuariayAgricolaButtonPushed, true);
            app.BiomasaPecuariayAgricolaButton.Text = 'Biomasa Pecuaria y Agricola';
            app.BiomasaPecuariayAgricolaButton.Icon = fullfile(pathToMLAPP, 'agricolaypecuario.png');
            app.Image2_2 = uiimage(app.Tab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label = uilabel(app.Tab);
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label.Text = {'Analizador del Recurso de Biomasa'; '(Biomass analyser 1.0)'};
            app.Image_2 = uiimage(app.Tab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'UDistrital.png');
            app.Image_3 = uiimage(app.Tab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'images', 'ingelectrica.png');
            app.Image_4 = uiimage(app.Tab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'images', 'lifae.png');
            app.InfoButton = uibutton(app.Tab, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.InfoButton.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VolverButton_4 = uibutton(app.Tab, 'push');
            app.VolverButton_4.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonInicio, true);
            app.VolverButton_4.Text = 'Volver';
            app.VolverButton_4.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.SalirButton = uibutton(app.Tab, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButon, true);
            app.SalirButton.Text = 'Salir';
            app.SalirButton.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.ButtonDownFcn = createCallbackFcn(app, @BiomasaAgricolaButtonPushed, true);
            app.graficoagricola = uiaxes(app.Tab2);
            title(app.graficoagricola, 'Title')
            xlabel(app.graficoagricola, 'X')
            ylabel(app.graficoagricola, 'Y')
            zlabel(app.graficoagricola, 'Z')
            app.graficoagricola.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.graficoagricola.YTickLabel = {'arroz'; 'algodon'; 'banano'; 'platano'; 'caña azucar'; 'caña panelera'; 'cafe'; 'coco'; 'frijol'; 'maiz'; 'mani'; 'palma aceite'; 'soya'; 'trigo'; 'yuca'};
            app.graficoagricola.ZTick = [0 0.5 1];
            app.SpinnerLabel = uilabel(app.Tab2);
            app.SpinnerLabel.Enable = 'off';
            app.SpinnerLabel.Text = '';
            app.Spinner = uispinner(app.Tab2);
            app.Spinner.Limits = [0 Inf];
            app.Spinner.Enable = 'off';
            app.AlgodnSpinner = uispinner(app.Tab2);
            app.AlgodnSpinner.Limits = [0 Inf];
            app.AlgodnSpinner.Enable = 'off';
            app.BananoSpinner = uispinner(app.Tab2);
            app.BananoSpinner.Limits = [0 Inf];
            app.BananoSpinner.Enable = 'off';
            app.PlatanoSpinner = uispinner(app.Tab2);
            app.PlatanoSpinner.Limits = [0 Inf];
            app.PlatanoSpinner.Enable = 'off';
            app.CaaazucarSpinner = uispinner(app.Tab2);
            app.CaaazucarSpinner.Limits = [0 Inf];
            app.CaaazucarSpinner.Enable = 'off';
            app.CaaPaneleraSpinner = uispinner(app.Tab2);
            app.CaaPaneleraSpinner.Limits = [0 Inf];
            app.CaaPaneleraSpinner.Enable = 'off';
            app.CafeSpinner = uispinner(app.Tab2);
            app.CafeSpinner.Limits = [0 Inf];
            app.CafeSpinner.Enable = 'off';
            app.CocoSpinner = uispinner(app.Tab2);
            app.CocoSpinner.Limits = [0 Inf];
            app.CocoSpinner.Enable = 'off';
            app.FrijolSpinner = uispinner(app.Tab2);
            app.FrijolSpinner.Limits = [0 Inf];
            app.FrijolSpinner.Enable = 'off';
            app.MaizSpinner = uispinner(app.Tab2);
            app.MaizSpinner.Limits = [0 Inf];
            app.MaizSpinner.Enable = 'off';
            app.ManiSpinner = uispinner(app.Tab2);
            app.ManiSpinner.Limits = [0 Inf];
            app.ManiSpinner.Enable = 'off';
            app.PalmaAceiteSpinner = uispinner(app.Tab2);
            app.PalmaAceiteSpinner.Limits = [0 Inf];
            app.PalmaAceiteSpinner.Enable = 'off';
            app.SoyaSpinner = uispinner(app.Tab2);
            app.SoyaSpinner.Limits = [0 Inf];
            app.SoyaSpinner.Enable = 'off';
            app.TrigoSpinner = uispinner(app.Tab2);
            app.TrigoSpinner.Limits = [0 Inf];
            app.TrigoSpinner.Enable = 'off';
            app.YucaSpinner = uispinner(app.Tab2);
            app.YucaSpinner.Limits = [0 Inf];
            app.YucaSpinner.Enable = 'off';
            app.VolverButton = uibutton(app.Tab2, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.VolverButton.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.ArrozCheckBox = uicheckbox(app.Tab2);
            app.ArrozCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArrozCheckBoxValueChanged, true);
            app.ArrozCheckBox.Text = 'Arroz';
            app.AlgodonCheckBox = uicheckbox(app.Tab2);
            app.AlgodonCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlgodonCheckBoxValueChanged, true);
            app.AlgodonCheckBox.Text = 'Algodon';
            app.BananoCheckBox = uicheckbox(app.Tab2);
            app.BananoCheckBox.ValueChangedFcn = createCallbackFcn(app, @BananoCheckBoxValueChanged, true);
            app.BananoCheckBox.Text = 'Banano';
            app.PlatanoCheckBox = uicheckbox(app.Tab2);
            app.PlatanoCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlatanoCheckBoxValueChanged, true);
            app.PlatanoCheckBox.Text = 'Platano';
            app.CaaAzucarCheckBox = uicheckbox(app.Tab2);
            app.CaaAzucarCheckBox.ValueChangedFcn = createCallbackFcn(app, @CaaAzucarCheckBoxValueChanged, true);
            app.CaaAzucarCheckBox.Text = 'Caña Azucar';
            app.CaaPaneleraCheckBox = uicheckbox(app.Tab2);
            app.CaaPaneleraCheckBox.ValueChangedFcn = createCallbackFcn(app, @CaaPaneleraCheckBoxValueChanged, true);
            app.CaaPaneleraCheckBox.Text = 'Caña Panelera';
            app.CafeCheckBox = uicheckbox(app.Tab2);
            app.CafeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CafeCheckBoxValueChanged, true);
            app.CafeCheckBox.Text = 'Cafe';
            app.CocoCheckBox = uicheckbox(app.Tab2);
            app.CocoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CocoCheckBoxValueChanged, true);
            app.CocoCheckBox.Text = 'Coco';
            app.FrijolCheckBox = uicheckbox(app.Tab2);
            app.FrijolCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrijolCheckBoxValueChanged, true);
            app.FrijolCheckBox.Text = 'Frijol';
            app.MaizCheckBox = uicheckbox(app.Tab2);
            app.MaizCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaizCheckBoxValueChanged, true);
            app.MaizCheckBox.Text = 'Maiz';
            app.ManiCheckBox = uicheckbox(app.Tab2);
            app.ManiCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManiCheckBoxValueChanged, true);
            app.ManiCheckBox.Text = 'Mani';
            app.PalmaAceiteCheckBox = uicheckbox(app.Tab2);
            app.PalmaAceiteCheckBox.ValueChangedFcn = createCallbackFcn(app, @PalmaAceiteCheckBoxValueChanged, true);
            app.PalmaAceiteCheckBox.Text = 'Palma Aceite';
            app.SoyaCheckBox = uicheckbox(app.Tab2);
            app.SoyaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SoyaCheckBoxValueChanged, true);
            app.SoyaCheckBox.Text = 'Soya';
            app.TrigoCheckBox = uicheckbox(app.Tab2);
            app.TrigoCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrigoCheckBoxValueChanged, true);
            app.TrigoCheckBox.Text = 'Trigo';
            app.YucaCheckBox = uicheckbox(app.Tab2);
            app.YucaCheckBox.ValueChangedFcn = createCallbackFcn(app, @YucaCheckBoxValueChanged, true);
            app.YucaCheckBox.Text = 'Yuca';
            app.CANTIDADDETONELADASPORAODEBIOMASAAGRICOLALabel = uilabel(app.Tab2);
            app.CANTIDADDETONELADASPORAODEBIOMASAAGRICOLALabel.Text = 'CANTIDAD DE TONELADAS POR AÑO DE BIOMASA AGRICOLA';
            app.SeleccionbiomasapecuariaButton = uibutton(app.Tab2, 'push');
            app.SeleccionbiomasapecuariaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionbiomasapecuariaButtonPushed, true);
            app.SeleccionbiomasapecuariaButton.Enable = 'off';
            app.SeleccionbiomasapecuariaButton.Visible = 'off';
            app.SeleccionbiomasapecuariaButton.Text = 'Seleccion biomasa pecuaria';
            app.SeleccionbiomasapecuariaButton.Icon = fullfile(pathToMLAPP, 'pecuario.png');
            app.ContinuarButton = uibutton(app.Tab2, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.ContinuarButton.Icon = fullfile(pathToMLAPP, 'adelante.png');
            app.UITable2_2 = uitable(app.Tab2);
            app.UITable2_2.ColumnName = {'Cultivo'; 'Tipo de residuo'; 'Fraccion de residuo generado'; 'Potencial calorifico inferior[KJ/Kg]'; 'Fraccion seca de la biomasa'; 'Disponibilidad del recurso'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = [false false false false false false false];
            app.UITable2_2.Visible = 'off';
            app.UnidadesdemasaButtonGroup = uibuttongroup(app.Tab2);
            app.UnidadesdemasaButtonGroup.Title = 'Unidades de masa';
            app.TonaoButton = uiradiobutton(app.UnidadesdemasaButtonGroup);
            app.TonaoButton.Text = '[Ton/año]';
            app.TonaoButton.Value = true;
            app.ArrobasaoButton = uiradiobutton(app.UnidadesdemasaButtonGroup);
            app.ArrobasaoButton.Text = '[Arrobas/año]';
            app.Image2_3 = uiimage(app.Tab2);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.InfoButton_2 = uibutton(app.Tab2, 'push');
            app.InfoButton_2.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_2.Text = 'Info';
            app.InfoButton_2.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VertablacompletaButton = uibutton(app.Tab2, 'push');
            app.VertablacompletaButton.ButtonPushedFcn = createCallbackFcn(app, @vertablaagricola, true);
            app.VertablacompletaButton.Text = 'Ver tabla completa';
            app.CerrartablaButton = uibutton(app.Tab2, 'push');
            app.CerrartablaButton.ButtonPushedFcn = createCallbackFcn(app, @cerrartabla, true);
            app.CerrartablaButton.Text = 'Cerrar tabla';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab3.ButtonDownFcn = createCallbackFcn(app, @BiomasaPecuariaButtonPushed, true);
            app.graficapecuario = uiaxes(app.Tab3);
            title(app.graficapecuario, 'Title')
            xlabel(app.graficapecuario, 'X')
            ylabel(app.graficapecuario, 'Y')
            zlabel(app.graficapecuario, 'Z')
            app.infograficas = uilabel(app.Tab3);
            app.infograficas.Text = {'Seleccione cada una '; 'de las categorias las '; 'cuales seran graficadas'; ' segun su seleccion'};
            app.BovinosSpinner = uispinner(app.Tab3);
            app.BovinosSpinner.Limits = [0 Inf];
            app.BovinosSpinner.RoundFractionalValues = 'on';
            app.BovinosSpinner.ValueDisplayFormat = '%.0f';
            app.BovinosSpinner.Enable = 'off';
            app.BufalinaSpinner = uispinner(app.Tab3);
            app.BufalinaSpinner.Limits = [0 Inf];
            app.BufalinaSpinner.RoundFractionalValues = 'on';
            app.BufalinaSpinner.ValueDisplayFormat = '%.0f';
            app.BufalinaSpinner.Enable = 'off';
            app.PorcinaSpinner = uispinner(app.Tab3);
            app.PorcinaSpinner.Limits = [0 Inf];
            app.PorcinaSpinner.RoundFractionalValues = 'on';
            app.PorcinaSpinner.ValueDisplayFormat = '%.0f';
            app.PorcinaSpinner.Enable = 'off';
            app.OvinaSpinner = uispinner(app.Tab3);
            app.OvinaSpinner.Limits = [0 Inf];
            app.OvinaSpinner.RoundFractionalValues = 'on';
            app.OvinaSpinner.ValueDisplayFormat = '%.0f';
            app.OvinaSpinner.Enable = 'off';
            app.CaprinaSpinner = uispinner(app.Tab3);
            app.CaprinaSpinner.Limits = [0 Inf];
            app.CaprinaSpinner.RoundFractionalValues = 'on';
            app.CaprinaSpinner.ValueDisplayFormat = '%.0f';
            app.CaprinaSpinner.Enable = 'off';
            app.EquinaSpinner = uispinner(app.Tab3);
            app.EquinaSpinner.Limits = [0 Inf];
            app.EquinaSpinner.RoundFractionalValues = 'on';
            app.EquinaSpinner.ValueDisplayFormat = '%.0f';
            app.EquinaSpinner.Enable = 'off';
            app.AvicolaSpinner = uispinner(app.Tab3);
            app.AvicolaSpinner.Limits = [0 Inf];
            app.AvicolaSpinner.RoundFractionalValues = 'on';
            app.AvicolaSpinner.ValueDisplayFormat = '%.0f';
            app.AvicolaSpinner.Enable = 'off';
            app.VolverButton_2 = uibutton(app.Tab3, 'push');
            app.VolverButton_2.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_2Pushed, true);
            app.VolverButton_2.Text = 'Volver';
            app.VolverButton_2.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.BovinosCheckBox = uicheckbox(app.Tab3);
            app.BovinosCheckBox.ValueChangedFcn = createCallbackFcn(app, @BovinosCheckBoxValueChanged, true);
            app.BovinosCheckBox.Text = 'Bovinos';
            app.BufalinaCheckBox = uicheckbox(app.Tab3);
            app.BufalinaCheckBox.ValueChangedFcn = createCallbackFcn(app, @BufalinaCheckBoxValueChanged, true);
            app.BufalinaCheckBox.Text = 'Bufalina';
            app.PorcinaCheckBox = uicheckbox(app.Tab3);
            app.PorcinaCheckBox.ValueChangedFcn = createCallbackFcn(app, @PorcinaCheckBoxValueChanged, true);
            app.PorcinaCheckBox.Text = 'Porcina';
            app.OvinaCheckBox = uicheckbox(app.Tab3);
            app.OvinaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OvinaCheckBoxValueChanged, true);
            app.OvinaCheckBox.Text = 'Ovina';
            app.CaprinaCheckBox = uicheckbox(app.Tab3);
            app.CaprinaCheckBox.ValueChangedFcn = createCallbackFcn(app, @CaprinaCheckBoxValueChanged, true);
            app.CaprinaCheckBox.Text = 'Caprina';
            app.EquinaCheckBox = uicheckbox(app.Tab3);
            app.EquinaCheckBox.ValueChangedFcn = createCallbackFcn(app, @EquinaCheckBoxValueChanged, true);
            app.EquinaCheckBox.Text = 'Equina';
            app.AvicolaCheckBox = uicheckbox(app.Tab3);
            app.AvicolaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AvicolaCheckBoxValueChanged, true);
            app.AvicolaCheckBox.Text = 'Avicola';
            app.CABEZASDEGANADOLabel = uilabel(app.Tab3);
            app.CABEZASDEGANADOLabel.Text = 'CABEZAS DE GANADO ';
            app.ContinuarButton_2 = uibutton(app.Tab3, 'push');
            app.ContinuarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButton_2Pushed, true);
            app.ContinuarButton_2.Text = 'Continuar';
            app.ContinuarButton_2.Icon = fullfile(pathToMLAPP, 'adelante.png');
            app.SeleccionbiomasaagricolaButton = uibutton(app.Tab3, 'push');
            app.SeleccionbiomasaagricolaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionbiomasaagricolaButtonPushed, true);
            app.SeleccionbiomasaagricolaButton.Enable = 'off';
            app.SeleccionbiomasaagricolaButton.Visible = 'off';
            app.SeleccionbiomasaagricolaButton.Text = 'Seleccion biomasa agricola';
            app.SeleccionbiomasaagricolaButton.Icon = fullfile(pathToMLAPP, 'agricola.png');
            app.UITable2_3 = uitable(app.Tab3);
            app.UITable2_3.ColumnName = {'Especie'; 'Estiercol(Kg/d)'; '%MS'; '%MOS'; 'C/N'; 'Biogas(m^3 /kg)'; 'DR'};
            app.UITable2_3.RowName = {};
            app.UITable2_3.ColumnEditable = [false false false false false false false];
            app.UITable2_3.CellEditCallback = createCallbackFcn(app, @UITable2_2CellEdit, true);
            app.UITable2_3.Visible = 'off';
            app.Image2_4 = uiimage(app.Tab3);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.InfoButton_3 = uibutton(app.Tab3, 'push');
            app.InfoButton_3.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_3.Text = 'Info';
            app.InfoButton_3.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VertablacompletaButton_2 = uibutton(app.Tab3, 'push');
            app.VertablacompletaButton_2.ButtonPushedFcn = createCallbackFcn(app, @vertablapecuaria, true);
            app.VertablacompletaButton_2.Text = 'Ver tabla completa';
            app.CerrartablaButton_2 = uibutton(app.Tab3, 'push');
            app.CerrartablaButton_2.ButtonPushedFcn = createCallbackFcn(app, @cerrartabla2, true);
            app.CerrartablaButton_2.Text = 'Cerrar tabla';
            app.CategoriasButtonGroup = uibuttongroup(app.Tab3);
            app.CategoriasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CategoriasButtonGroupSelectionChanged, true);
            app.CategoriasButtonGroup.Title = 'Categorias ';
            app.EstiercolkgdiaButton = uiradiobutton(app.CategoriasButtonGroup);
            app.EstiercolkgdiaButton.Text = 'Estiercol(kg/dia)';
            app.EstiercolkgdiaButton.Value = true;
            app.MSButton = uiradiobutton(app.CategoriasButtonGroup);
            app.MSButton.Text = '%MS';
            app.MOSButton = uiradiobutton(app.CategoriasButtonGroup);
            app.MOSButton.Text = '%MOS';
            app.CNButton = uiradiobutton(app.CategoriasButtonGroup);
            app.CNButton.Text = 'C/N';
            app.Biogasm3kgButton = uiradiobutton(app.CategoriasButtonGroup);
            app.Biogasm3kgButton.Text = 'Biogas(m^3 /kg)';
            app.DRButton = uiradiobutton(app.CategoriasButtonGroup);
            app.DRButton.Text = 'DR';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.VolverButton_3 = uibutton(app.Tab4, 'push');
            app.VolverButton_3.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_3Pushed, true);
            app.VolverButton_3.Text = 'Volver';
            app.VolverButton_3.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.indiquelatemperaturapromediodellugardeimplementacionLabel = uilabel(app.Tab4);
            app.indiquelatemperaturapromediodellugardeimplementacionLabel.Text = 'Indique  la temperatura promedio del lugar de implementacion del biodigestor';
            app.TemperaturaCEditFieldLabel = uilabel(app.Tab4);
            app.TemperaturaCEditFieldLabel.Text = 'Temperatura(°C)';
            app.TemperaturaCEditField = uieditfield(app.Tab4, 'numeric');
            app.TemperaturaCEditField.Limits = [0 60];
            app.TemperaturaCEditField.ValueDisplayFormat = '%.1f';
            app.TemperaturaCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturaCEditFieldValueChanged, true);
            app.CalcularButton = uibutton(app.Tab4, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.CalcularButton.Icon = fullfile(pathToMLAPP, 'calcular.png');
            app.Image = uiimage(app.Tab4);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'mapa_temporiginal.jpg');
            app.Image2 = uiimage(app.Tab4);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'temperatura.png');
            app.labelVerTemperatura = uilabel(app.Tab4);
            app.labelVerTemperatura.Text = {'En caso de no conocer la Temperatura promedio (°C) '; 'del lugar donde se realizara el analisis, abra la imagen '; 'del mapa de temperatura promedio en colombia y '; 'busque el lugar determinado.'};
            app.Image2_5 = uiimage(app.Tab4);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.InfoButton_4 = uibutton(app.Tab4, 'push');
            app.InfoButton_4.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_4.Text = 'Info';
            app.InfoButton_4.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.SeleccionarBiomasadenuevoButton = uibutton(app.Tab4, 'state');
            app.SeleccionarBiomasadenuevoButton.ValueChangedFcn = createCallbackFcn(app, @seleccionarbiomasaagain, true);
            app.SeleccionarBiomasadenuevoButton.Text = {'Seleccionar '; 'Biomasa de nuevo'};
            app.SeleccionarBiomasadenuevoButton.Icon = fullfile(pathToMLAPP, 'agricolaypecuario.png');
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UITable = uitable(app.Tab5);
            app.UITable.ColumnName = {'produccion de estiercol por cabeza'; 'Kg/dia'};
            app.UITable.RowName = {};
            app.VolumenbiodigestorltEditFieldLabel = uilabel(app.Tab5);
            app.VolumenbiodigestorltEditFieldLabel.Text = 'Volumen biodigestor (lt)';
            app.VolumenbiodigestorltEditField = uieditfield(app.Tab5, 'numeric');
            app.VolumenbiodigestorltEditField.ValueDisplayFormat = '%.0f';
            app.VolumenbiodigestorltEditField.ValueChangedFcn = createCallbackFcn(app, @VolumenbiodigestorltEditFieldValueChanged2, true);
            app.VolumenbiodigestorltEditField.Editable = 'off';
            app.PotencialenergeticoButton = uibutton(app.Tab5, 'push');
            app.PotencialenergeticoButton.ButtonPushedFcn = createCallbackFcn(app, @PotencialenergeticoButtonPushed, true);
            app.PotencialenergeticoButton.Text = 'Potencial energetico';
            app.PotencialenergeticoButton.Icon = fullfile(pathToMLAPP, 'potencial_energetico.png');
            app.VolumenbiodigestorltEditField_2 = uieditfield(app.Tab5, 'numeric');
            app.VolumenbiodigestorltEditField_2.ValueDisplayFormat = '%.0f';
            app.VolumenbiodigestorltEditField_2.ValueChangedFcn = createCallbackFcn(app, @VolumenbiodigestorltEditField_2ValueChanged, true);
            app.VolumenbiodigestorltEditField_2.Editable = 'off';
            app.VolumenbiodigestorltEditField_2.Visible = 'off';
            app.UITable3 = uitable(app.Tab5);
            app.UITable3.ColumnName = {'tipo de cultivo'; 'desechos por cada tonelada de cultivo (ton/año)'};
            app.UITable3.RowName = {};
            app.SugerenciaelementosbiodigestorButton = uibutton(app.Tab5, 'push');
            app.SugerenciaelementosbiodigestorButton.ButtonPushedFcn = createCallbackFcn(app, @SugerenciaelementosbiodigestorButtonPushed, true);
            app.SugerenciaelementosbiodigestorButton.Text = {'Sugerencia elementos'; ' biodigestor'};
            app.SugerenciaelementosbiodigestorButton.Icon = fullfile(pathToMLAPP, 'biogas.png');
            app.InfoButton_5 = uibutton(app.Tab5, 'push');
            app.InfoButton_5.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_5.Text = 'Info';
            app.InfoButton_5.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VolverButton_5 = uibutton(app.Tab5, 'push');
            app.VolverButton_5.ButtonPushedFcn = createCallbackFcn(app, @volverboton5, true);
            app.VolverButton_5.Text = 'Volver';
            app.VolverButton_5.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.grafica1 = uiaxes(app.Tab6);
            title(app.grafica1, 'Potencial energetico fuente y recurso ')
            ylabel(app.grafica1, 'Mwh/año')
            zlabel(app.grafica1, 'Z')
            app.InicioButton = uibutton(app.Tab6, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.InicioButton.Icon = fullfile(pathToMLAPP, 'inicio.png');
            app.PotencialtotalfuentebiomasaMwhaoEditFieldLabel = uilabel(app.Tab6);
            app.PotencialtotalfuentebiomasaMwhaoEditFieldLabel.Text = 'Potencial total fuente biomasa(Mwh/año)';
            app.PotencialtotalfuentebiomasaMwhaoEditField = uieditfield(app.Tab6, 'numeric');
            app.PotencialtotalfuentebiomasaMwhaoEditField.ValueDisplayFormat = '%.3f';
            app.PotencialtotalfuentebiomasaMwhaoEditField.ValueChangedFcn = createCallbackFcn(app, @potencialtotalfuente, true);
            app.PotencialtotalfuentebiomasaMwhaoEditField.Editable = 'off';
            app.PotencialtotalrecursobiomasaMwhaoEditFieldLabel = uilabel(app.Tab6);
            app.PotencialtotalrecursobiomasaMwhaoEditFieldLabel.Text = 'Potencial total recurso biomasa(Mwh/año)';
            app.PotencialtotalrecursobiomasaMwhaoEditField = uieditfield(app.Tab6, 'numeric');
            app.PotencialtotalrecursobiomasaMwhaoEditField.ValueDisplayFormat = '%.3f';
            app.PotencialtotalrecursobiomasaMwhaoEditField.ValueChangedFcn = createCallbackFcn(app, @potencialtotalrecurso, true);
            app.PotencialtotalrecursobiomasaMwhaoEditField.Editable = 'off';
            app.PotencialenergeticoporcategoriasPanel = uipanel(app.Tab6);
            app.PotencialenergeticoporcategoriasPanel.Title = 'Potencial energetico por categorias';
            app.InfoButton_6 = uibutton(app.Tab6, 'push');
            app.InfoButton_6.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_6.Text = 'Info';
            app.InfoButton_6.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VolverButton_6 = uibutton(app.Tab6, 'push');
            app.VolverButton_6.ButtonPushedFcn = createCallbackFcn(app, @volverboton6, true);
            app.VolverButton_6.Text = 'Volver';
            app.VolverButton_6.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = 'Tab7';
            app.PotencialenergeticoButton_2 = uibutton(app.Tab7, 'push');
            app.PotencialenergeticoButton_2.ButtonPushedFcn = createCallbackFcn(app, @PotencialenergeticoButtonPushed, true);
            app.PotencialenergeticoButton_2.Text = 'Potencial energetico';
            app.PotencialenergeticoButton_2.Icon = fullfile(pathToMLAPP, 'potencial_energetico.png');
            app.ReactorltEditField_2 = uieditfield(app.Tab7, 'numeric');
            app.ReactorltEditField_2.ValueDisplayFormat = '%.0f';
            app.ReactorltEditField_2.ValueChangedFcn = createCallbackFcn(app, @ReactorltEditField_2ValueChanged, true);
            app.ReactorltEditField_2.Editable = 'off';
            app.ReactorltEditField_2.Visible = 'off';
            app.ReactorltEditFieldLabel = uilabel(app.Tab7);
            app.ReactorltEditFieldLabel.Text = 'Reactor (lt)';
            app.ReactorltEditField = uieditfield(app.Tab7, 'numeric');
            app.ReactorltEditField.ValueDisplayFormat = '%.0f';
            app.ReactorltEditField.ValueChangedFcn = createCallbackFcn(app, @ReactorltEditFieldValueChanged, true);
            app.ReactorltEditField.Editable = 'off';
            app.CamaradecargaltEditField_2 = uieditfield(app.Tab7, 'numeric');
            app.CamaradecargaltEditField_2.ValueDisplayFormat = '%.3f';
            app.CamaradecargaltEditField_2.ValueChangedFcn = createCallbackFcn(app, @CamaradecargaltEditField_2ValueChanged, true);
            app.CamaradecargaltEditField_2.Editable = 'off';
            app.CamaradecargaltEditField_2.Visible = 'off';
            app.CamaradecargaltEditFieldLabel = uilabel(app.Tab7);
            app.CamaradecargaltEditFieldLabel.Text = 'Camara de carga (lt)';
            app.CamaradecargaltEditField = uieditfield(app.Tab7, 'numeric');
            app.CamaradecargaltEditField.ValueDisplayFormat = '%.3f';
            app.CamaradecargaltEditField.ValueChangedFcn = createCallbackFcn(app, @CamaradecargaltEditFieldValueChanged, true);
            app.CamaradecargaltEditField.Editable = 'off';
            app.CamaradedescargaltEditField_2 = uieditfield(app.Tab7, 'numeric');
            app.CamaradedescargaltEditField_2.ValueDisplayFormat = '%.3f';
            app.CamaradedescargaltEditField_2.ValueChangedFcn = createCallbackFcn(app, @CamaradedescargaltEditField_2ValueChanged, true);
            app.CamaradedescargaltEditField_2.Editable = 'off';
            app.CamaradedescargaltEditField_2.Visible = 'off';
            app.CamaradedescargaltEditFieldLabel = uilabel(app.Tab7);
            app.CamaradedescargaltEditFieldLabel.Text = 'Camara de descarga (lt)';
            app.CamaradedescargaltEditField = uieditfield(app.Tab7, 'numeric');
            app.CamaradedescargaltEditField.ValueDisplayFormat = '%.3f';
            app.CamaradedescargaltEditField.ValueChangedFcn = createCallbackFcn(app, @CamaradedescargaltEditFieldValueChanged, true);
            app.CamaradedescargaltEditField.Editable = 'off';
            app.Image3 = uiimage(app.Tab7);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'biodigestor.jpeg');
            app.labelElementosConstitutivosBiodigestor = uilabel(app.Tab7);
            app.labelElementosConstitutivosBiodigestor.Text = {'El sistema consta de cinco partes:'; ''; 'Cámara de carga. Se trata de un pequeño depósito donde se coloca la materia'; ' orgánica que alimenta el reactor.'; 'Reactor. Es el digestor propiamente dicho, un tanque alargado que se sitúa bajo tierra.'; ' Allí es donde se descomponen los desechos, conectado a las cámaras de carga y de'; ' descarga por dos extremos diferentes.'; 'Cámara de descarga. Colocada a menor altura que la cámara de carga, recibe los'; 'residuos provenientes del reactor, los cuales pueden utilizarse como abono'; ' y acondicionador del suelo.'; 'Cubierta de plástico. Se coloca por encima del reactor y cumple dos funciones: '; 'impedir la entrada de aire al interior y evitar la fuga del gas producido.'; 'Tubería. Conduce el gas que se produce en el reactor. Cuenta con una válvula de'; ' seguridad que impide que la presión al interior de la cubierta plástica rebase los '; 'niveles permitidos. Este dispositivo también expulsa el agua que se condensa en'; ' la tubería.'; 'Llave de paso. Regula la salida del gas.'};
            app.ElementosconstitutivosdeunbiodigestorLabel = uilabel(app.Tab7);
            app.ElementosconstitutivosdeunbiodigestorLabel.Text = 'Elementos constitutivos de un biodigestor';
            app.Image2_6 = uiimage(app.Tab7);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.InfoButton_7 = uibutton(app.Tab7, 'push');
            app.InfoButton_7.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_7.Text = 'Info';
            app.InfoButton_7.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VolverButton_7 = uibutton(app.Tab7, 'push');
            app.VolverButton_7.ButtonPushedFcn = createCallbackFcn(app, @volverboton7, true);
            app.VolverButton_7.Text = 'Volver';
            app.VolverButton_7.Icon = fullfile(pathToMLAPP, 'atras.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proceso_Biodigestor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end