classdef Proceso_gasificador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IdiomaLanguageMenu             matlab.ui.container.Menu
        EnglishMenu                    matlab.ui.container.Menu
        EspaolMenu                     matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        SalirButton                    matlab.ui.control.Button
        VolverButton_2                 matlab.ui.control.Button
        InfoButton                     matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        AnalizadordelRecursodeBiomasaBiomassanalyser10Label  matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        BiomasaForestalButton          matlab.ui.control.Button
        SeleccionelafuentedebiomasaquedeseaimplementarLabel  matlab.ui.control.Label
        Tab2                           matlab.ui.container.Tab
        InfoButton_2                   matlab.ui.control.Button
        PotencialenergeticoButton      matlab.ui.control.Button
        HUMEDADAPROXIMADAKcalKgLabel   matlab.ui.control.Label
        AcerpseudoplatanusButtonGroup  matlab.ui.container.ButtonGroup
        Button_32                      matlab.ui.control.RadioButton
        Button_31                      matlab.ui.control.RadioButton
        AbedulButtonGroup              matlab.ui.container.ButtonGroup
        Button_30                      matlab.ui.control.RadioButton
        Button_29                      matlab.ui.control.RadioButton
        HayaButtonGroup                matlab.ui.container.ButtonGroup
        Button_28                      matlab.ui.control.RadioButton
        Button_27                      matlab.ui.control.RadioButton
        RobleButtonGroup               matlab.ui.container.ButtonGroup
        Button_26                      matlab.ui.control.RadioButton
        Button_25                      matlab.ui.control.RadioButton
        QuercuscerrisButtonGroup       matlab.ui.container.ButtonGroup
        Button_24                      matlab.ui.control.RadioButton
        Button_23                      matlab.ui.control.RadioButton
        AlnusglutinosaButtonGroup      matlab.ui.container.ButtonGroup
        Button_22                      matlab.ui.control.RadioButton
        Button_21                      matlab.ui.control.RadioButton
        AlnusincanaButtonGroup         matlab.ui.container.ButtonGroup
        Button_20                      matlab.ui.control.RadioButton
        Button_19                      matlab.ui.control.RadioButton
        FresnoButtonGroup              matlab.ui.container.ButtonGroup
        Button_18                      matlab.ui.control.RadioButton
        Button_17                      matlab.ui.control.RadioButton
        CarpeButtonGroup               matlab.ui.container.ButtonGroup
        Button_16                      matlab.ui.control.RadioButton
        Button_15                      matlab.ui.control.RadioButton
        OlmoButtonGroup                matlab.ui.container.ButtonGroup
        Button_14                      matlab.ui.control.RadioButton
        Button_13                      matlab.ui.control.RadioButton
        SalixalbaButtonGroup           matlab.ui.container.ButtonGroup
        Button_12                      matlab.ui.control.RadioButton
        Button_11                      matlab.ui.control.RadioButton
        AbetorojoButtonGroup           matlab.ui.container.ButtonGroup
        Button_10                      matlab.ui.control.RadioButton
        Button_9                       matlab.ui.control.RadioButton
        PinosilvestreButtonGroup       matlab.ui.container.ButtonGroup
        Button_8                       matlab.ui.control.RadioButton
        Button_7                       matlab.ui.control.RadioButton
        PinusstrobusButtonGroup        matlab.ui.container.ButtonGroup
        Button_6                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
        PinabeteButtonGroup            matlab.ui.container.ButtonGroup
        Button_4                       matlab.ui.control.RadioButton
        Button_3                       matlab.ui.control.RadioButton
        CarbonButtonGroup              matlab.ui.container.ButtonGroup
        Button_2                       matlab.ui.control.RadioButton
        Button                         matlab.ui.control.RadioButton
        CarbonvegetalCheckBox          matlab.ui.control.CheckBox
        CarbonvegetalSpinner           matlab.ui.control.Spinner
        CalcularButton                 matlab.ui.control.Button
        CANTIDADDEBIOMASAFORESTALENKILOGRAMOSLabel  matlab.ui.control.Label
        PinabeteCheckBox               matlab.ui.control.CheckBox
        PinusstrobusCheckBox           matlab.ui.control.CheckBox
        PinosilvestreCheckBox          matlab.ui.control.CheckBox
        AbetorojoCheckBox              matlab.ui.control.CheckBox
        SalixalbaCheckBox              matlab.ui.control.CheckBox
        OlmoCheckBox                   matlab.ui.control.CheckBox
        CarpeCheckBox                  matlab.ui.control.CheckBox
        FresnoCheckBox                 matlab.ui.control.CheckBox
        AlnusincanaCheckBox            matlab.ui.control.CheckBox
        AlnusglutinosaCheckBox         matlab.ui.control.CheckBox
        QuercuscerrisCheckBox          matlab.ui.control.CheckBox
        RobleCheckBox                  matlab.ui.control.CheckBox
        HayaCheckBox                   matlab.ui.control.CheckBox
        AbedulCheckBox                 matlab.ui.control.CheckBox
        AcerpseudoplatanusCheckBox     matlab.ui.control.CheckBox
        VolverButton                   matlab.ui.control.Button
        PinabeteSpinner                matlab.ui.control.Spinner
        PinusstrobusSpinner            matlab.ui.control.Spinner
        PinosilvestreSpinner           matlab.ui.control.Spinner
        AbetorojoSpinner               matlab.ui.control.Spinner
        SalixalbaSpinner               matlab.ui.control.Spinner
        OlmoSpinner                    matlab.ui.control.Spinner
        CarpeSpinner                   matlab.ui.control.Spinner
        FresnoSpinner                  matlab.ui.control.Spinner
        AlnusincanaSpinner             matlab.ui.control.Spinner
        AlnusglutinosaSpinner          matlab.ui.control.Spinner
        QuercuscerrisSpinner           matlab.ui.control.Spinner
        RobleSpinner                   matlab.ui.control.Spinner
        HayaSpinner                    matlab.ui.control.Spinner
        AbedulSpinner                  matlab.ui.control.Spinner
        AcerpseudoplatanusSpinner      matlab.ui.control.Spinner
        grafica1                       matlab.ui.control.UIAxes
        Tab3                           matlab.ui.container.Tab
        TiposdegasificadoresButton     matlab.ui.control.Button
        VolverButton_3                 matlab.ui.control.Button
        CerrartablaButton              matlab.ui.control.Button
        VertablacompletaButton         matlab.ui.control.Button
        InfoButton_3                   matlab.ui.control.Button
        Image2_2                       matlab.ui.control.Image
        InicioButton                   matlab.ui.control.Button
        PotencialenergeticototalkwhEditField  matlab.ui.control.NumericEditField
        PotencialenergeticototalkwhEditFieldLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        grafica2                       matlab.ui.control.UIAxes
        Tab4                           matlab.ui.container.Tab
        infogas                        matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        TIPOSDEGASIFICADORESLabel      matlab.ui.control.Label
        VolverButton_4                 matlab.ui.control.Button
        InfoButton_4                   matlab.ui.control.Button
        Image2_3                       matlab.ui.control.Image
        InicioButton_2                 matlab.ui.control.Button
    end

    
    properties (Access = private)

        acerkcal;          
        abekcal;
        hayakcal;
        roblekcal;
        quekcal;
        algkcal;
        alikcal;
        freskcal;
        carpekcal;
        olmokcal;
        salixkcal;
        rojokcal;
        pinoskcal;
        pinuskcal;
        pinakcal;
        carkcal;
        aproxtemp1; % Description
        aproxtemp2; % Description
        sal;
        idioma
        Inicioapp
        Informacionapp
    end
    
    methods (Access = private)
        function guardaridiomas(app,idioma)

            app.TIPOSDEGASIFICADORESLabel.Text = buscador(app,idioma, 'titulotipogasificador');
            app.infogas.Text = buscador(app, idioma, 'textogasificador');
            app.SeleccionelafuentedebiomasaquedeseaimplementarLabel.Text = buscador(app,idioma, 'tituloSeleccionFuenteImplementarGasificador');
            app.BiomasaForestalButton.Text = buscador(app, idioma, 'botonBiomasaForestal');
            app.HUMEDADAPROXIMADAKcalKgLabel.Text = buscador(app, idioma, 'tituloHumedadAprox');
            app.CarbonvegetalCheckBox.Text = buscador(app, idioma, 'carbonVegetalCheck');
            app.CalcularButton.Text = buscador(app, idioma, 'botonCalcular');
            app.CANTIDADDEBIOMASAFORESTALENKILOGRAMOSLabel.Text = buscador(app, idioma, 'tituloCantidadBiomasaKg');
            app.VolverButton.Text = buscador(app, idioma, 'botonVolver');
            app.PotencialenergeticoButton.Text = buscador(app, idioma, 'botonPotencialEnergetico');
            app.PotencialenergeticototalkwhEditFieldLabel.Text = buscador(app, idioma, 'tituloPotencialEnergeticoForestal');
            app.InicioButton.Text = buscador(app, idioma, 'botonInicio');
            app.AcerpseudoplatanusCheckBox.Text = buscador(app, idioma, 'acerPseudoplatanusCheck');  
            app.AbedulCheckBox.Text = buscador(app, idioma, 'abedulCheck');  
            app.HayaCheckBox.Text = buscador(app, idioma, 'hayaCheck');  
            app.RobleCheckBox.Text = buscador(app, idioma, 'robleCheck');  
            app.QuercuscerrisCheckBox.Text = buscador(app, idioma, 'quercusCerrisCheck');  
            app.AlnusglutinosaCheckBox.Text = buscador(app, idioma, 'alnusGlutinosaCheck');  
            app.AlnusincanaCheckBox.Text = buscador(app, idioma, 'alnusIncanaCheck');  
            app.FresnoCheckBox.Text = buscador(app, idioma, 'fresnoCheck');  
            app.CarpeCheckBox.Text = buscador(app, idioma, 'carpeCheck');  
            app.OlmoCheckBox.Text = buscador(app, idioma, 'olmoCheck');  
            app.SalixalbaCheckBox.Text = buscador(app, idioma, 'salixAlbaCheck');  
            app.AbetorojoCheckBox.Text = buscador(app, idioma, 'abetoRojoCheck');  
            app.PinosilvestreCheckBox.Text = buscador(app, idioma, 'pinoSilvestreCheck');  
            app.PinusstrobusCheckBox.Text = buscador(app, idioma, 'pinoStrobusCheck');  
            app.PinabeteCheckBox.Text = buscador(app, idioma, 'pinabeteCheck');

            app.grafica1.Title.String= buscador(app,idioma, 'TituloGraficaforestal');
            app.grafica1.XLabel.String= buscador(app,idioma, 'Xgraficatitulo');
            app.grafica1.YLabel.String= buscador(app,idioma, 'YgraficaTitulo');
            app.grafica1.XTickLabel= buscador(app,idioma, 'tablaForestal');
            app.grafica2.XTickLabel= buscador(app,idioma, 'tablaForestal');
            app.grafica2.Title.String=buscador(app,idioma, 'HumedadPotencial');
            app.grafica2.XLabel.String=buscador(app,idioma, 'Xgraficatitulo');

            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label.Text= buscador(app,idioma, 'titulo');

            app.VolverButton_3.Text = buscador(app,idioma, 'botonVolver1');
            app.VolverButton_2.Text = buscador(app,idioma, 'botonVolver1');
            app.SalirButton.Text = buscador(app,idioma, 'botonSalir1');


            app.CerrartablaButton.Text = buscador(app,idioma, 'cerrartabla');
            app.VertablacompletaButton.Text = buscador(app,idioma, 'vertabla');

            app.UITable.ColumnName  = buscador(app,idioma, 'tablahumedad');


        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end    


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inicioapp, idioma, informacionapp)
            app.idioma = idioma;
            app.Inicioapp = inicioapp;
            if app.idioma == "Español"
                app.EspaolMenu.Checked = "on";
               app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);

            end
            if app.idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);

            end

            forestal=readtable("datos_forestal.xlsx");
            app.UITable.Data=forestal;            
            humedad10=table2array(forestal(:,2:3));

            bar(app.grafica2,humedad10);

            app.grafica2.YLabel.String='kcal/kg';
            app.grafica2.XTick=(1:17);
            if app.idioma == "Español"
                legend(app.grafica2,'humedad 0-10%','humedad 15-20.5%');
            else app.idioma == "English"
                legend(app.grafica2,'humidity 0-10%','humidity 15-20.5%');        
            end

        end

        % Callback function
        function BiomasaPecuariaButtonPushed(app, event)
        app.TabGroup.SelectedTab = app.Tab3;
            
        end

        % Button down function: Tab2
        function BiomasaAgricolaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Button down function: Tab
        function VolverButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Callback function
        function ArrozSpinnerValueChanged(app, event)
                  
        end

        % Value changed function: AcerpseudoplatanusCheckBox
        function AcerpseudoplatanusCheckBoxValueChanged(app, event)
            value = app.AcerpseudoplatanusCheckBox.Value;
            switch value
                case 1
                    app.AcerpseudoplatanusSpinner.Enable="on";
                    app.AcerpseudoplatanusButtonGroup.Enable="on";
                case 0
                    app.AcerpseudoplatanusSpinner.Enable="off";
                    app.AcerpseudoplatanusButtonGroup.Enable="off";

            end


        end

        % Value changed function: AbedulCheckBox
        function AbedulCheckBoxValueChanged(app, event)
            value = app.AbedulCheckBox.Value;
            switch value
                case 1
                    app.AbedulSpinner.Enable="on";
                    app.AbedulButtonGroup.Enable="on";
                case 0
                    app.AbedulSpinner.Enable="off";
                    app.AbedulButtonGroup.Enable="off";
            end
        end

        % Value changed function: HayaCheckBox
        function HayaCheckBoxValueChanged(app, event)
            value = app.HayaCheckBox.Value;
            switch value
                case 1
                    app.HayaSpinner.Enable="on";
                    app.HayaButtonGroup.Enable="on";
                case 0
                    app.HayaSpinner.Enable="off";
                    app.HayaButtonGroup.Enable="off";
            end
        end

        % Value changed function: RobleCheckBox
        function RobleCheckBoxValueChanged(app, event)
            value = app.RobleCheckBox.Value;
            switch value
                case 1
                    app.RobleSpinner.Enable="on";
                    app.RobleButtonGroup.Enable="on";
                case 0
                    app.RobleSpinner.Enable="off";
                    app.RobleButtonGroup.Enable="off";
            end
        end

        % Value changed function: QuercuscerrisCheckBox
        function QuercuscerrisCheckBoxValueChanged(app, event)
            value = app.QuercuscerrisCheckBox.Value;
            switch value
                case 1
                    app.QuercuscerrisSpinner.Enable="on";
                    app.QuercuscerrisButtonGroup.Enable="on";
                case 0
                    app.QuercuscerrisSpinner.Enable="off";
                    app.QuercuscerrisButtonGroup.Enable="off";
            end
        end

        % Value changed function: AlnusglutinosaCheckBox
        function AlnusglutinosaCheckBoxValueChanged(app, event)
            value = app.AlnusglutinosaCheckBox.Value;
            switch value
                case 1
                    app.AlnusglutinosaSpinner.Enable="on";
                    app.AlnusglutinosaButtonGroup.Enable="on";
                case 0
                    app.AlnusglutinosaSpinner.Enable="off";
                    app.AlnusglutinosaButtonGroup.Enable="off";
            end
        end

        % Value changed function: AlnusincanaCheckBox
        function AlnusincanaCheckBoxValueChanged(app, event)
            value = app.AlnusincanaCheckBox.Value;
            switch value
                case 1
                    app.AlnusincanaSpinner.Enable="on";
                    app.AlnusincanaButtonGroup.Enable="on";
                case 0
                    app.AlnusincanaSpinner.Enable="off";
                    app.AlnusincanaButtonGroup.Enable="off";
            end
        end

        % Value changed function: FresnoCheckBox
        function FresnoCheckBoxValueChanged(app, event)
            value = app.FresnoCheckBox.Value;
            switch value
                case 1
                    app.FresnoSpinner.Enable="on";
                    app.FresnoButtonGroup.Enable="on";
                case 0
                    app.FresnoSpinner.Enable="off";
                    app.FresnoButtonGroup.Enable="off";
            end
        end

        % Value changed function: CarpeCheckBox
        function CarpeCheckBoxValueChanged(app, event)
            value = app.CarpeCheckBox.Value;
            switch value
                case 1
                    app.CarpeSpinner.Enable="on";
                    app.CarpeButtonGroup.Enable="on";
                case 0
                    app.CarpeSpinner.Enable="off";
                    app.CarpeButtonGroup.Enable="off";
            end
        end

        % Value changed function: OlmoCheckBox
        function OlmoCheckBoxValueChanged(app, event)
            value = app.OlmoCheckBox.Value;
            switch value
                case 1
                    app.OlmoSpinner.Enable="on";
                    app.OlmoButtonGroup.Enable="on";
                case 0
                    app.OlmoSpinner.Enable="off";
                    app.OlmoButtonGroup.Enable="off";
            end
        end

        % Value changed function: SalixalbaCheckBox
        function SalixalbaCheckBoxValueChanged(app, event)
            value = app.SalixalbaCheckBox.Value;
            switch value
                case 1
                    app.SalixalbaSpinner.Enable="on";
                    app.SalixalbaButtonGroup.Enable="on";
                case 0
                    app.SalixalbaSpinner.Enable="off";
                    app.SalixalbaButtonGroup.Enable="off";
            end
        end

        % Value changed function: AbetorojoCheckBox
        function AbetorojoCheckBoxValueChanged(app, event)
            value = app.AbetorojoCheckBox.Value;
            switch value
                case 1
                    app.AbetorojoSpinner.Enable="on";
                    app.AbetorojoButtonGroup.Enable="on";
                case 0
                    app.AbetorojoSpinner.Enable="off";
                    app.AbetorojoButtonGroup.Enable="off";
            end
        end

        % Value changed function: PinosilvestreCheckBox
        function PinosilvestreCheckBoxValueChanged(app, event)
            value = app.PinosilvestreCheckBox.Value;
            switch value
                case 1
                    app.PinosilvestreSpinner.Enable="on";
                    app.PinosilvestreButtonGroup.Enable="on";
                case 0
                    app.PinosilvestreSpinner.Enable="off";
                    app.PinosilvestreButtonGroup.Enable="off";
            end
        end

        % Value changed function: PinusstrobusCheckBox
        function PinusstrobusCheckBoxValueChanged(app, event)
            value = app.PinusstrobusCheckBox.Value;
            switch value
                case 1
                    app.PinusstrobusSpinner.Enable="on";
                    app.PinusstrobusButtonGroup.Enable="on";
                case 0
                    app.PinusstrobusSpinner.Enable="off";
                    app.PinusstrobusButtonGroup.Enable="off";
            end
        end

        % Value changed function: PinabeteCheckBox
        function PinabeteCheckBoxValueChanged(app, event)
            value = app.PinabeteCheckBox.Value;
            switch value
                case 1
                    app.PinabeteSpinner.Enable="on";
                    app.PinabeteButtonGroup.Enable="on";
                case 0
                    app.PinabeteSpinner.Enable="off";
                    app.PinabeteButtonGroup.Enable="off";
            end
        end

        % Callback function
        function BovinosCheckBoxValueChanged(app, event)
     
        end

        % Callback function
        function BufalinaCheckBoxValueChanged(app, event)

        end

        % Callback function
        function PorcinaCheckBoxValueChanged(app, event)
         
        end

        % Callback function
        function OvinaCheckBoxValueChanged(app, event)
        
        end

        % Callback function
        function CaprinaCheckBoxValueChanged(app, event)
       
        end

        % Callback function
        function EquinaCheckBoxValueChanged(app, event)
     
            
        end

        % Callback function
        function AvicolaCheckBoxValueChanged(app, event)
        
        end

        % Callback function
        function VolverButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed2(app, event)


             if app.Button_32.Value ==1
                app.acerkcal=3540;             
             else app.Button_31.Value =1;
                  app.acerkcal=4183;                
             end
            
             if app.Button_30.Value ==1
                app.abekcal=3715;             
             else app.Button_29.Value =1;
                  app.abekcal=4506;                
             end

             if app.Button_28.Value ==1
                app.hayakcal=3461;             
             else app.Button_27.Value =1;
                  app.hayakcal=4187;                
             end

             if app.Button_26.Value ==1
                app.roblekcal=3676;             
             else app.Button_25.Value =1;
                  app.roblekcal=4356;                
             end

             if app.Button_24.Value ==1
                app.quekcal=3445;             
             else app.Button_23.Value =1;
                  app.quekcal=4216;                
             end

             if app.Button_22.Value ==1
                app.algkcal=3676;             
             else app.Button_21.Value =1;
                  app.algkcal=4316;                
             end

             if app.Button_20.Value ==1
                app.alikcal=3505;             
             else app.Button_19.Value =1;
                  app.alikcal=4123;                
             end

             if app.Button_18.Value ==1
                app.freskcal=3617;             
             else app.Button_17.Value =1;
                  app.freskcal=4255;                
             end

             if app.Button_16.Value ==1
                app.carpekcal=3409;             
             else app.Button_15.Value =1;
                  app.carpekcal=4062;                
             end

             if app.Button_14.Value ==1
                app.olmokcal=3511;             
             else app.Button_13.Value =1;
                  app.olmokcal=4419;                
             end

             if app.Button_12.Value ==1
                app.salixkcal=3561;             
             else app.Button_11.Value =1;
                  app.salixkcal=4264;                
             end

             if app.Button_10.Value ==1
                app.rojokcal=3900;             
             else app.Button_9.Value =1;
                  app.rojokcal=4726;                
             end

             if app.Button_8.Value ==1
                app.pinoskcal=3818;             
             else app.Button_7.Value =1;
                  app.pinoskcal=5066;                
             end

             if app.Button_6.Value ==1
                app.pinuskcal=4054;             
             else app.Button_5.Value =1;
                  app.pinuskcal=4876;                
             end

             if app.Button_4.Value ==1
                app.pinakcal=3866;             
             else app.Button_3.Value =1;
                  app.pinakcal=4654;                
             end

             if app.Button_2.Value ==1
                app.carkcal=8015;             
             else app.Button.Value =1;
                  app.carkcal=8015;                
             end

            sumatoria=[(0.00116*app.AcerpseudoplatanusSpinner.Value*app.acerkcal*app.AcerpseudoplatanusCheckBox.Value),(0.00116*app.AbedulSpinner.Value*app.abekcal*app.AbedulCheckBox.Value),(0.00116*app.HayaSpinner.Value*app.hayakcal*app.HayaCheckBox.Value),(0.00116*app.RobleSpinner.Value*app.roblekcal*app.RobleCheckBox.Value),(0.00116*app.QuercuscerrisSpinner.Value*app.quekcal*app.QuercuscerrisCheckBox.Value),(0.00116*app.AlnusglutinosaSpinner.Value*app.algkcal*app.AlnusglutinosaCheckBox.Value),(0.00116*app.AlnusincanaSpinner.Value*app.alikcal*app.AlnusincanaCheckBox.Value),(0.00116*app.FresnoSpinner.Value*app.freskcal*app.FresnoCheckBox.Value),(0.00116*app.CarpeSpinner.Value*app.carpekcal*app.CarpeCheckBox.Value),(0.00116*app.OlmoSpinner.Value*app.olmokcal*app.OlmoCheckBox.Value),(0.00116*app.SalixalbaSpinner.Value*app.salixkcal*app.SalixalbaCheckBox.Value),(0.00116*app.AbetorojoSpinner.Value*app.rojokcal*app.AbetorojoCheckBox.Value),(0.00116*app.PinosilvestreSpinner.Value*app.pinoskcal*app.PinosilvestreCheckBox.Value),(0.00116*app.PinusstrobusSpinner.Value*app.pinuskcal*app.PinusstrobusCheckBox.Value),(0.00116*app.PinabeteSpinner.Value*app.pinakcal*app.PinabeteCheckBox.Value),(0.00116*app.CarbonvegetalSpinner.Value*app.carkcal*app.CarbonvegetalCheckBox.Value)];

            bar(app.grafica1,sumatoria,'g');

            app.grafica1.XTick=(1:17);

            app.PotencialenergeticoButton.Enable="on";
            app.PotencialenergeticoButton.Visible="on";

        end

        % Callback function
        function ContinuarButton_2Pushed(app, event)
     
        end

        % Callback function
        function CalcularButtonPushed(app, event)

        end

        % Callback function
        function UITableCellEdit(app, event)
           indices = event.Indices;
           newData = event.NewData;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
         
        end

        % Callback function
        function PotencialenergeticototalEditFieldValueChanged2(app, event)
                     
        end

        % Callback function
        function PotencialenergeticoButtonPushed(app, event)
            
        end

        % Callback function
        function UITable2CellEdit(app, event)
            importes(app,event)
            
        end

        % Callback function
        function VolumenbiodigestoragricolaltEditFieldValueChanged(app, event)
          
        end

        % Callback function
        function VolumenbiodigestoragricolaltEditFieldValueChanging(app, event)
                   
        end

        % Button pushed function: BiomasaForestalButton
        function BiomasaForestalButtonPushed(app, event)

             app.TabGroup.SelectedTab = app.Tab2;app.CalcularButton.Visible="on";
             app.TabGroup.SelectedTab = app.Tab2;app.CalcularButton.Enable="on";
             app.AcerpseudoplatanusSpinner.Value = 0;
             app.AbedulSpinner.Value = 0;
             app.HayaSpinner.Value = 0;
             app.RobleSpinner.Value = 0;
             app.QuercuscerrisSpinner.Value = 0;
             app.AlnusglutinosaSpinner.Value = 0;
             app.AlnusincanaSpinner.Value = 0;
             app.FresnoSpinner.Value = 0;
             app.CarpeSpinner.Value = 0;
             app.OlmoSpinner.Value = 0;
             app.SalixalbaSpinner.Value = 0;
             app.AbetorojoSpinner.Value = 0;
             app.PinosilvestreSpinner.Value = 0;
             app.PinusstrobusSpinner.Value = 0;
             app.PinabeteSpinner.Value = 0;
             app.CarbonvegetalSpinner.Value = 0;
             app.AcerpseudoplatanusSpinner.Enable ="off";
             app.AbedulSpinner.Enable ="off";
             app.HayaSpinner.Enable ="off";
             app.RobleSpinner.Enable ="off";
             app.QuercuscerrisSpinner.Enable ="off";
             app.AlnusglutinosaSpinner.Enable ="off";
             app.AlnusincanaSpinner.Enable ="off";
             app.FresnoSpinner.Enable ="off";
             app.CarpeSpinner.Enable ="off";
             app.OlmoSpinner.Enable ="off";
             app.SalixalbaSpinner.Enable ="off";
             app.AbetorojoSpinner.Enable ="off";
             app.PinosilvestreSpinner.Enable ="off";
             app.PinusstrobusSpinner.Enable ="off";
             app.PinabeteSpinner.Enable ="off";
             app.CarbonvegetalSpinner.Enable ="off";
             app.AcerpseudoplatanusCheckBox.Value = 0;
             app.AbedulCheckBox.Value = 0;
             app.HayaCheckBox.Value = 0;
             app.RobleCheckBox.Value = 0;
             app.QuercuscerrisCheckBox.Value = 0;
             app.AlnusglutinosaCheckBox.Value = 0;
             app.AlnusincanaCheckBox.Value = 0;
             app.FresnoCheckBox.Value = 0;
             app.CarpeCheckBox.Value = 0;
             app.OlmoCheckBox.Value = 0;
             app.SalixalbaCheckBox.Value = 0;
             app.AbetorojoCheckBox.Value = 0;
             app.PinosilvestreCheckBox.Value = 0;
             app.PinusstrobusCheckBox.Value = 0;
             app.PinabeteCheckBox.Value = 0;
             app.CarbonvegetalCheckBox.Value = 0;
             app.PotencialenergeticoButton.Visible="off";
             app.AcerpseudoplatanusButtonGroup.Enable ="off";
             app.AbedulButtonGroup.Enable ="off";
             app.HayaButtonGroup.Enable ="off";
             app.RobleButtonGroup.Enable ="off";
             app.QuercuscerrisButtonGroup.Enable ="off";
             app.AlnusglutinosaButtonGroup.Enable ="off";
             app.AlnusincanaButtonGroup.Enable ="off";
             app.FresnoButtonGroup.Enable ="off";
             app.CarpeButtonGroup.Enable ="off";
             app.OlmoButtonGroup.Enable ="off";
             app.SalixalbaButtonGroup.Enable ="off";
             app.AbetorojoButtonGroup.Enable ="off";
             app.PinosilvestreButtonGroup.Enable ="off";
             app.PinusstrobusButtonGroup.Enable ="off";
             app.PinabeteButtonGroup.Enable ="off";
             app.CarbonButtonGroup.Enable ="off";

             sumatoria=[(0.00116*app.AcerpseudoplatanusSpinner.Value*app.acerkcal*app.AcerpseudoplatanusCheckBox.Value),(0.00116*app.AbedulSpinner.Value*app.abekcal*app.AbedulCheckBox.Value),(0.00116*app.HayaSpinner.Value*app.hayakcal*app.HayaCheckBox.Value),(0.00116*app.RobleSpinner.Value*app.roblekcal*app.RobleCheckBox.Value),(0.00116*app.QuercuscerrisSpinner.Value*app.quekcal*app.QuercuscerrisCheckBox.Value),(0.00116*app.AlnusglutinosaSpinner.Value*app.algkcal*app.AlnusglutinosaCheckBox.Value),(0.00116*app.AlnusincanaSpinner.Value*app.alikcal*app.AlnusincanaCheckBox.Value),(0.00116*app.FresnoSpinner.Value*app.freskcal*app.FresnoCheckBox.Value),(0.00116*app.CarpeSpinner.Value*app.carpekcal*app.CarpeCheckBox.Value),(0.00116*app.OlmoSpinner.Value*app.olmokcal*app.OlmoCheckBox.Value),(0.00116*app.SalixalbaSpinner.Value*app.salixkcal*app.SalixalbaCheckBox.Value),(0.00116*app.AbetorojoSpinner.Value*app.rojokcal*app.AbetorojoCheckBox.Value),(0.00116*app.PinosilvestreSpinner.Value*app.pinoskcal*app.PinosilvestreCheckBox.Value),(0.00116*app.PinusstrobusSpinner.Value*app.pinuskcal*app.PinusstrobusCheckBox.Value),(0.00116*app.PinabeteSpinner.Value*app.pinakcal*app.PinabeteCheckBox.Value),(0.00116*app.CarbonvegetalSpinner.Value*app.carkcal*app.CarbonvegetalCheckBox.Value)];
             bar(app.grafica1,sumatoria,'g');
             app.grafica1.XTick=(1:17);

        end

        % Callback function
        function seleccionbiomasapecuariaButtonPushed(app, event)
           
        end

        % Callback function
        function seleccionbiomasaagricolaButtonPushed(app, event)
         
        end

        % Callback function
        function VolumenbiodigestorltEditField_2ValueChanged(app, event)
         
        end

        % Callback function
        function InicioButtonPushed(app, event)
            
        end

        % Value changed function: CarbonvegetalCheckBox
        function Carbon(app, event)
            value = app.CarbonvegetalCheckBox.Value;
            switch value
                case 1
                    app.CarbonvegetalSpinner.Enable="on";
                    app.CarbonButtonGroup.Enable="on";
                case 0
                    app.CarbonvegetalSpinner.Enable="off";
                    app.CarbonButtonGroup.Enable="off";
            end
        end

        % Callback function
        function prueba(app, event)
          

        end

        % Value changed function: PotencialenergeticototalkwhEditField
        function PotencialenergeticototalkwhEditFieldValueChanged3(app, event)

                    app.PotencialenergeticototalkwhEditField.Value=sumatoria2;
            
        end

        % Button pushed function: InicioButton, InicioButton_2
        function InicioButtonPushed2(app, event)

            delete(app)
        end

        % Button pushed function: PotencialenergeticoButton
        function PotencialenergeticoButtonPushed2(app, event)

            app.TabGroup.SelectedTab = app.Tab3;
            sumatoria2=((0.00116*app.AcerpseudoplatanusSpinner.Value*app.acerkcal*app.AcerpseudoplatanusCheckBox.Value)+(0.00116*app.AbedulSpinner.Value*app.abekcal*app.AbedulCheckBox.Value)+(0.00116*app.HayaSpinner.Value*app.hayakcal*app.HayaCheckBox.Value)+(0.00116*app.RobleSpinner.Value*app.roblekcal*app.RobleCheckBox.Value)+(0.00116*app.QuercuscerrisSpinner.Value*app.quekcal*app.QuercuscerrisCheckBox.Value)+(0.00116*app.AlnusglutinosaSpinner.Value*app.algkcal*app.AlnusglutinosaCheckBox.Value)+(0.00116*app.AlnusincanaSpinner.Value*app.alikcal*app.AlnusincanaCheckBox.Value)+(0.00116*app.FresnoSpinner.Value*app.freskcal*app.FresnoCheckBox.Value)+(0.00116*app.CarpeSpinner.Value*app.carpekcal*app.CarpeCheckBox.Value)+(0.00116*app.OlmoSpinner.Value*app.olmokcal*app.OlmoCheckBox.Value)+(0.00116*app.SalixalbaSpinner.Value*app.salixkcal*app.SalixalbaCheckBox.Value)+(0.00116*app.AbetorojoSpinner.Value*app.rojokcal*app.AbetorojoCheckBox.Value)+(0.00116*app.PinosilvestreSpinner.Value*app.pinoskcal*app.PinosilvestreCheckBox.Value)+(0.00116*app.PinusstrobusSpinner.Value*app.pinuskcal*app.PinusstrobusCheckBox.Value)+(0.00116*app.PinabeteSpinner.Value*app.pinakcal*app.PinabeteCheckBox.Value)+(0.00116*app.CarbonvegetalSpinner.Value*app.carkcal*app.CarbonvegetalCheckBox.Value));           
            app.PotencialenergeticototalkwhEditField.Value=sumatoria2;
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";    
            forestal2=readtable("datos_forestal_ingles.xlsx");
            app.UITable.Data=forestal2;
        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";
            forestal2=readtable("datos_forestal.xlsx");
            app.UITable.Data=forestal2;
        end

        % Button pushed function: InfoButton, InfoButton_2, InfoButton_3, 
        % ...and 1 other component
        function InformacionButtonPushed(app, event)
            app.Informacionapp = informacion(app,app.idioma);
        end

        % Button pushed function: VolverButton_2
        function volverboton(app, event)
            delete(app)
        end

        % Button pushed function: SalirButton
        function salirboton(app, event)
            delete(app)
        end

        % Button pushed function: VertablacompletaButton
        function vertabla(app, event)
           if app.idioma == "Español"
              forestal2=readtable("datos_forestal.xlsx");
           elseif app.idioma == "English"
              forestal2=readtable("datos_forestal_ingles.xlsx");
           end

            app.UITable.Data=forestal2; 
            app.UITable.Visible="on";

        end

        % Button pushed function: CerrartablaButton
        function cerrartabla(app, event)
          app.UITable.Visible="off";
        end

        % Button pushed function: VolverButton_3
        function volverboton3(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Callback function
        function i(app, event)
            
        end

        % Button pushed function: VolverButton_4
        function vol(app, event)
            app.TabGroup.SelectedTab = app.Tab3;
        end

        % Button pushed function: TiposdegasificadoresButton
        function TiposdegasificadoresButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab4;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IdiomaLanguageMenu = uimenu(app.UIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Text = 'Español';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab.ButtonDownFcn = createCallbackFcn(app, @VolverButton_2Pushed, true);
            app.SeleccionelafuentedebiomasaquedeseaimplementarLabel = uilabel(app.Tab);
            app.SeleccionelafuentedebiomasaquedeseaimplementarLabel.Text = {'Seleccione la fuente de biomasa que desea implementar'; '(caso gasificador)'};
            app.BiomasaForestalButton = uibutton(app.Tab, 'push');
            app.BiomasaForestalButton.ButtonPushedFcn = createCallbackFcn(app, @BiomasaForestalButtonPushed, true);
            app.BiomasaForestalButton.Text = 'Biomasa Forestal';
            app.BiomasaForestalButton.Icon = fullfile(pathToMLAPP, 'forestal.png');
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'UDistrital.png');
            app.Image_2 = uiimage(app.Tab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'ingelectrica.png');
            app.Image_3 = uiimage(app.Tab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'images', 'lifae.png');
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label = uilabel(app.Tab);
            app.AnalizadordelRecursodeBiomasaBiomassanalyser10Label.Text = {'Analizador del Recurso de Biomasa'; '(Biomass analyser 1.0)'};
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.InfoButton = uibutton(app.Tab, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.InfoButton.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VolverButton_2 = uibutton(app.Tab, 'push');
            app.VolverButton_2.ButtonPushedFcn = createCallbackFcn(app, @volverboton, true);
            app.VolverButton_2.Text = 'Volver';
            app.VolverButton_2.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.SalirButton = uibutton(app.Tab, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton.Text = 'Salir';
            app.SalirButton.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.ButtonDownFcn = createCallbackFcn(app, @BiomasaAgricolaButtonPushed, true);
            app.grafica1 = uiaxes(app.Tab2);
            title(app.grafica1, 'Title')
            xlabel(app.grafica1, 'Tipos de madera')
            ylabel(app.grafica1, 'Poder calorico (Kcal/Kg)')
            zlabel(app.grafica1, 'Z')
            app.grafica1.XLim = [0 17];
            app.grafica1.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17];
            app.grafica1.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'};
            app.grafica1.ZTick = [0 0.5 10];
            app.AcerpseudoplatanusSpinner = uispinner(app.Tab2);
            app.AcerpseudoplatanusSpinner.Limits = [0 Inf];
            app.AcerpseudoplatanusSpinner.Enable = 'off';
            app.AbedulSpinner = uispinner(app.Tab2);
            app.AbedulSpinner.Limits = [0 Inf];
            app.AbedulSpinner.Enable = 'off';
            app.HayaSpinner = uispinner(app.Tab2);
            app.HayaSpinner.Limits = [0 Inf];
            app.HayaSpinner.Enable = 'off';
            app.RobleSpinner = uispinner(app.Tab2);
            app.RobleSpinner.Limits = [0 Inf];
            app.RobleSpinner.Enable = 'off';
            app.QuercuscerrisSpinner = uispinner(app.Tab2);
            app.QuercuscerrisSpinner.Limits = [0 Inf];
            app.QuercuscerrisSpinner.Enable = 'off';
            app.AlnusglutinosaSpinner = uispinner(app.Tab2);
            app.AlnusglutinosaSpinner.Limits = [0 Inf];
            app.AlnusglutinosaSpinner.Enable = 'off';
            app.AlnusincanaSpinner = uispinner(app.Tab2);
            app.AlnusincanaSpinner.Limits = [0 Inf];
            app.AlnusincanaSpinner.Enable = 'off';
            app.FresnoSpinner = uispinner(app.Tab2);
            app.FresnoSpinner.Limits = [0 Inf];
            app.FresnoSpinner.Enable = 'off';
            app.CarpeSpinner = uispinner(app.Tab2);
            app.CarpeSpinner.Limits = [0 Inf];
            app.CarpeSpinner.Enable = 'off';
            app.OlmoSpinner = uispinner(app.Tab2);
            app.OlmoSpinner.Limits = [0 Inf];
            app.OlmoSpinner.Enable = 'off';
            app.SalixalbaSpinner = uispinner(app.Tab2);
            app.SalixalbaSpinner.Limits = [0 Inf];
            app.SalixalbaSpinner.Enable = 'off';
            app.AbetorojoSpinner = uispinner(app.Tab2);
            app.AbetorojoSpinner.Limits = [0 Inf];
            app.AbetorojoSpinner.Enable = 'off';
            app.PinosilvestreSpinner = uispinner(app.Tab2);
            app.PinosilvestreSpinner.Limits = [0 Inf];
            app.PinosilvestreSpinner.Enable = 'off';
            app.PinusstrobusSpinner = uispinner(app.Tab2);
            app.PinusstrobusSpinner.Limits = [0 Inf];
            app.PinusstrobusSpinner.Enable = 'off';
            app.PinabeteSpinner = uispinner(app.Tab2);
            app.PinabeteSpinner.Limits = [0 Inf];
            app.PinabeteSpinner.Enable = 'off';
            app.VolverButton = uibutton(app.Tab2, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.VolverButton.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.AcerpseudoplatanusCheckBox = uicheckbox(app.Tab2);
            app.AcerpseudoplatanusCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcerpseudoplatanusCheckBoxValueChanged, true);
            app.AcerpseudoplatanusCheckBox.Text = '1.Acer pseudoplatanus';
            app.AbedulCheckBox = uicheckbox(app.Tab2);
            app.AbedulCheckBox.ValueChangedFcn = createCallbackFcn(app, @AbedulCheckBoxValueChanged, true);
            app.AbedulCheckBox.Text = 'Abedul';
            app.HayaCheckBox = uicheckbox(app.Tab2);
            app.HayaCheckBox.ValueChangedFcn = createCallbackFcn(app, @HayaCheckBoxValueChanged, true);
            app.HayaCheckBox.Text = 'Haya';
            app.RobleCheckBox = uicheckbox(app.Tab2);
            app.RobleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RobleCheckBoxValueChanged, true);
            app.RobleCheckBox.Text = 'Roble';
            app.QuercuscerrisCheckBox = uicheckbox(app.Tab2);
            app.QuercuscerrisCheckBox.ValueChangedFcn = createCallbackFcn(app, @QuercuscerrisCheckBoxValueChanged, true);
            app.QuercuscerrisCheckBox.Text = 'Quercus cerris';
            app.AlnusglutinosaCheckBox = uicheckbox(app.Tab2);
            app.AlnusglutinosaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlnusglutinosaCheckBoxValueChanged, true);
            app.AlnusglutinosaCheckBox.Text = 'Alnus glutinosa';
            app.AlnusincanaCheckBox = uicheckbox(app.Tab2);
            app.AlnusincanaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlnusincanaCheckBoxValueChanged, true);
            app.AlnusincanaCheckBox.Text = 'Alnus incana';
            app.FresnoCheckBox = uicheckbox(app.Tab2);
            app.FresnoCheckBox.ValueChangedFcn = createCallbackFcn(app, @FresnoCheckBoxValueChanged, true);
            app.FresnoCheckBox.Text = 'Fresno';
            app.CarpeCheckBox = uicheckbox(app.Tab2);
            app.CarpeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CarpeCheckBoxValueChanged, true);
            app.CarpeCheckBox.Text = 'Carpe';
            app.OlmoCheckBox = uicheckbox(app.Tab2);
            app.OlmoCheckBox.ValueChangedFcn = createCallbackFcn(app, @OlmoCheckBoxValueChanged, true);
            app.OlmoCheckBox.Text = 'Olmo';
            app.SalixalbaCheckBox = uicheckbox(app.Tab2);
            app.SalixalbaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SalixalbaCheckBoxValueChanged, true);
            app.SalixalbaCheckBox.Text = 'Salix alba';
            app.AbetorojoCheckBox = uicheckbox(app.Tab2);
            app.AbetorojoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AbetorojoCheckBoxValueChanged, true);
            app.AbetorojoCheckBox.Text = 'Abeto rojo';
            app.PinosilvestreCheckBox = uicheckbox(app.Tab2);
            app.PinosilvestreCheckBox.ValueChangedFcn = createCallbackFcn(app, @PinosilvestreCheckBoxValueChanged, true);
            app.PinosilvestreCheckBox.Text = 'Pino silvestre';
            app.PinusstrobusCheckBox = uicheckbox(app.Tab2);
            app.PinusstrobusCheckBox.ValueChangedFcn = createCallbackFcn(app, @PinusstrobusCheckBoxValueChanged, true);
            app.PinusstrobusCheckBox.Text = 'Pinus strobus';
            app.PinabeteCheckBox = uicheckbox(app.Tab2);
            app.PinabeteCheckBox.ValueChangedFcn = createCallbackFcn(app, @PinabeteCheckBoxValueChanged, true);
            app.PinabeteCheckBox.Text = 'Pinabete';
            app.CANTIDADDEBIOMASAFORESTALENKILOGRAMOSLabel = uilabel(app.Tab2);
            app.CANTIDADDEBIOMASAFORESTALENKILOGRAMOSLabel.Text = 'CANTIDAD DE BIOMASA FORESTAL EN KILOGRAMOS';
            app.CalcularButton = uibutton(app.Tab2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed2, true);
            app.CalcularButton.Text = 'Calcular';
            app.CalcularButton.Icon = fullfile(pathToMLAPP, 'calcular.png');
            app.CarbonvegetalSpinner = uispinner(app.Tab2);
            app.CarbonvegetalSpinner.Limits = [0 Inf];
            app.CarbonvegetalSpinner.Enable = 'off';
            app.CarbonvegetalCheckBox = uicheckbox(app.Tab2);
            app.CarbonvegetalCheckBox.ValueChangedFcn = createCallbackFcn(app, @Carbon, true);
            app.CarbonvegetalCheckBox.Text = 'Carbon vegetal ';
            app.CarbonButtonGroup = uibuttongroup(app.Tab2);
            app.CarbonButtonGroup.Enable = 'off';
            app.Button = uiradiobutton(app.CarbonButtonGroup);
            app.Button.Text = '0-10%';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.CarbonButtonGroup);
            app.Button_2.Text = '15-20,5%';
            app.PinabeteButtonGroup = uibuttongroup(app.Tab2);
            app.PinabeteButtonGroup.Enable = 'off';
            app.Button_3 = uiradiobutton(app.PinabeteButtonGroup);
            app.Button_3.Text = '0-10%';
            app.Button_3.Value = true;
            app.Button_4 = uiradiobutton(app.PinabeteButtonGroup);
            app.Button_4.Text = '15-20,5%';
            app.PinusstrobusButtonGroup = uibuttongroup(app.Tab2);
            app.PinusstrobusButtonGroup.Enable = 'off';
            app.Button_5 = uiradiobutton(app.PinusstrobusButtonGroup);
            app.Button_5.Text = '0-10%';
            app.Button_5.Value = true;
            app.Button_6 = uiradiobutton(app.PinusstrobusButtonGroup);
            app.Button_6.Text = '15-20,5%';
            app.PinosilvestreButtonGroup = uibuttongroup(app.Tab2);
            app.PinosilvestreButtonGroup.Enable = 'off';
            app.Button_7 = uiradiobutton(app.PinosilvestreButtonGroup);
            app.Button_7.Text = '0-10%';
            app.Button_7.Value = true;
            app.Button_8 = uiradiobutton(app.PinosilvestreButtonGroup);
            app.Button_8.Text = '15-20,5%';
            app.AbetorojoButtonGroup = uibuttongroup(app.Tab2);
            app.AbetorojoButtonGroup.Enable = 'off';
            app.Button_9 = uiradiobutton(app.AbetorojoButtonGroup);
            app.Button_9.Text = '0-10%';
            app.Button_9.Value = true;
            app.Button_10 = uiradiobutton(app.AbetorojoButtonGroup);
            app.Button_10.Text = '15-20,5%';
            app.SalixalbaButtonGroup = uibuttongroup(app.Tab2);
            app.SalixalbaButtonGroup.Enable = 'off';
            app.Button_11 = uiradiobutton(app.SalixalbaButtonGroup);
            app.Button_11.Text = '0-10%';
            app.Button_11.Value = true;
            app.Button_12 = uiradiobutton(app.SalixalbaButtonGroup);
            app.Button_12.Text = '15-20,5%';
            app.OlmoButtonGroup = uibuttongroup(app.Tab2);
            app.OlmoButtonGroup.Enable = 'off';
            app.Button_13 = uiradiobutton(app.OlmoButtonGroup);
            app.Button_13.Text = '0-10%';
            app.Button_13.Value = true;
            app.Button_14 = uiradiobutton(app.OlmoButtonGroup);
            app.Button_14.Text = '15-20,5%';
            app.CarpeButtonGroup = uibuttongroup(app.Tab2);
            app.CarpeButtonGroup.Enable = 'off';
            app.Button_15 = uiradiobutton(app.CarpeButtonGroup);
            app.Button_15.Text = '0-10%';
            app.Button_15.Value = true;
            app.Button_16 = uiradiobutton(app.CarpeButtonGroup);
            app.Button_16.Text = '15-20,5%';
            app.FresnoButtonGroup = uibuttongroup(app.Tab2);
            app.FresnoButtonGroup.Enable = 'off';
            app.Button_17 = uiradiobutton(app.FresnoButtonGroup);
            app.Button_17.Text = '0-10%';
            app.Button_17.Value = true;
            app.Button_18 = uiradiobutton(app.FresnoButtonGroup);
            app.Button_18.Text = '15-20,5%';
            app.AlnusincanaButtonGroup = uibuttongroup(app.Tab2);
            app.AlnusincanaButtonGroup.Enable = 'off';
            app.Button_19 = uiradiobutton(app.AlnusincanaButtonGroup);
            app.Button_19.Text = '0-10%';
            app.Button_19.Value = true;
            app.Button_20 = uiradiobutton(app.AlnusincanaButtonGroup);
            app.Button_20.Text = '15-20,5%';
            app.AlnusglutinosaButtonGroup = uibuttongroup(app.Tab2);
            app.AlnusglutinosaButtonGroup.Enable = 'off';
            app.Button_21 = uiradiobutton(app.AlnusglutinosaButtonGroup);
            app.Button_21.Text = '0-10%';
            app.Button_21.Value = true;
            app.Button_22 = uiradiobutton(app.AlnusglutinosaButtonGroup);
            app.Button_22.Text = '15-20,5%';
            app.QuercuscerrisButtonGroup = uibuttongroup(app.Tab2);
            app.QuercuscerrisButtonGroup.Enable = 'off';
            app.Button_23 = uiradiobutton(app.QuercuscerrisButtonGroup);
            app.Button_23.Text = '0-10%';
            app.Button_23.Value = true;
            app.Button_24 = uiradiobutton(app.QuercuscerrisButtonGroup);
            app.Button_24.Text = '15-20,5%';
            app.RobleButtonGroup = uibuttongroup(app.Tab2);
            app.RobleButtonGroup.Enable = 'off';
            app.Button_25 = uiradiobutton(app.RobleButtonGroup);
            app.Button_25.Text = '0-10%';
            app.Button_25.Value = true;
            app.Button_26 = uiradiobutton(app.RobleButtonGroup);
            app.Button_26.Text = '15-20,5%';
            app.HayaButtonGroup = uibuttongroup(app.Tab2);
            app.HayaButtonGroup.Enable = 'off';
            app.Button_27 = uiradiobutton(app.HayaButtonGroup);
            app.Button_27.Text = '0-10%';
            app.Button_27.Value = true;
            app.Button_28 = uiradiobutton(app.HayaButtonGroup);
            app.Button_28.Text = '15-20,5%';
            app.AbedulButtonGroup = uibuttongroup(app.Tab2);
            app.AbedulButtonGroup.Enable = 'off';
            app.Button_29 = uiradiobutton(app.AbedulButtonGroup);
            app.Button_29.Text = '0-10%';
            app.Button_29.Value = true;
            app.Button_30 = uiradiobutton(app.AbedulButtonGroup);
            app.Button_30.Text = '15-20,5%';
            app.AcerpseudoplatanusButtonGroup = uibuttongroup(app.Tab2);
            app.AcerpseudoplatanusButtonGroup.Enable = 'off';
            app.Button_31 = uiradiobutton(app.AcerpseudoplatanusButtonGroup);
            app.Button_31.Text = '0-10%';
            app.Button_31.Value = true;
            app.Button_32 = uiradiobutton(app.AcerpseudoplatanusButtonGroup);
            app.Button_32.Text = '15-20,5%';
            app.HUMEDADAPROXIMADAKcalKgLabel = uilabel(app.Tab2);
            app.HUMEDADAPROXIMADAKcalKgLabel.Text = 'HUMEDAD APROXIMADA(Kcal/Kg)';
            app.PotencialenergeticoButton = uibutton(app.Tab2, 'push');
            app.PotencialenergeticoButton.ButtonPushedFcn = createCallbackFcn(app, @PotencialenergeticoButtonPushed2, true);
            app.PotencialenergeticoButton.Enable = 'off';
            app.PotencialenergeticoButton.Visible = 'off';
            app.PotencialenergeticoButton.Text = 'Potencial energetico';
            app.PotencialenergeticoButton.Icon = fullfile(pathToMLAPP, 'potencial_energetico.png');
            app.InfoButton_2 = uibutton(app.Tab2, 'push');
            app.InfoButton_2.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_2.Text = 'Info';
            app.InfoButton_2.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.grafica2 = uiaxes(app.Tab3);
            title(app.grafica2, 'Title')
            xlabel(app.grafica2, 'X')
            ylabel(app.grafica2, 'Y')
            zlabel(app.grafica2, 'Z')
            app.UITable = uitable(app.Tab3);
            app.UITable.ColumnName = {'Tipo de madera'; 'Porcentaje humedad 0-10%(Kcal/Kg)'; 'Porcentaje humedad 15-20.5%(Kcal/Kg)'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.PotencialenergeticototalkwhEditFieldLabel = uilabel(app.Tab3);
            app.PotencialenergeticototalkwhEditFieldLabel.Text = 'Potencial energetico total kwh';
            app.PotencialenergeticototalkwhEditField = uieditfield(app.Tab3, 'numeric');
            app.PotencialenergeticototalkwhEditField.ValueDisplayFormat = '%.3f';
            app.PotencialenergeticototalkwhEditField.ValueChangedFcn = createCallbackFcn(app, @PotencialenergeticototalkwhEditFieldValueChanged3, true);
            app.PotencialenergeticototalkwhEditField.Editable = 'off';
            app.InicioButton = uibutton(app.Tab3, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed2, true);
            app.InicioButton.Text = 'Inicio';
            app.InicioButton.Icon = fullfile(pathToMLAPP, 'inicio.png');
            app.Image2_2 = uiimage(app.Tab3);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.InfoButton_3 = uibutton(app.Tab3, 'push');
            app.InfoButton_3.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_3.Text = 'Info';
            app.InfoButton_3.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VertablacompletaButton = uibutton(app.Tab3, 'push');
            app.VertablacompletaButton.ButtonPushedFcn = createCallbackFcn(app, @vertabla, true);
            app.VertablacompletaButton.Text = 'Ver tabla completa';
            app.CerrartablaButton = uibutton(app.Tab3, 'push');
            app.CerrartablaButton.ButtonPushedFcn = createCallbackFcn(app, @cerrartabla, true);
            app.CerrartablaButton.Text = 'Cerrar tabla';
            app.VolverButton_3 = uibutton(app.Tab3, 'push');
            app.VolverButton_3.ButtonPushedFcn = createCallbackFcn(app, @volverboton3, true);
            app.VolverButton_3.Text = 'Volver';
            app.VolverButton_3.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.TiposdegasificadoresButton = uibutton(app.Tab3, 'push');
            app.TiposdegasificadoresButton.ButtonPushedFcn = createCallbackFcn(app, @TiposdegasificadoresButtonPushed, true);
            app.TiposdegasificadoresButton.Text = 'Tipos de gasificadores';
            app.TiposdegasificadoresButton.Icon = fullfile(pathToMLAPP, 'biogas.png');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab3';
            app.InicioButton_2 = uibutton(app.Tab4, 'push');
            app.InicioButton_2.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed2, true);
            app.InicioButton_2.Text = 'Inicio';
            app.InicioButton_2.Icon = fullfile(pathToMLAPP, 'inicio.png');
            app.Image2_3 = uiimage(app.Tab4);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'images', 'logo-2B.png');
            app.InfoButton_4 = uibutton(app.Tab4, 'push');
            app.InfoButton_4.ButtonPushedFcn = createCallbackFcn(app, @InformacionButtonPushed, true);
            app.InfoButton_4.Text = 'Info';
            app.InfoButton_4.Icon = fullfile(pathToMLAPP, 'pregunta.png');
            app.VolverButton_4 = uibutton(app.Tab4, 'push');
            app.VolverButton_4.ButtonPushedFcn = createCallbackFcn(app, @vol, true);
            app.VolverButton_4.Text = 'Volver';
            app.VolverButton_4.Icon = fullfile(pathToMLAPP, 'atras.png');
            app.TIPOSDEGASIFICADORESLabel = uilabel(app.Tab4);
            app.TIPOSDEGASIFICADORESLabel.Text = 'TIPOS DE GASIFICADORES';
            app.Image4 = uiimage(app.Tab4);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'fluidizado.png');
            app.Image5 = uiimage(app.Tab4);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'images', 'ascendente.png');
            app.Image6 = uiimage(app.Tab4);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'images', 'descendente.jpeg');
            app.infogas = uilabel(app.Tab4);
            app.infogas.Text = {'Los gasificadores son dispositivos que convierten materiales sólidos en gas combustible a través de procesos termoquímicos. Los gasificadores fijos mantienen un lecho de'; ' sólidos inmóvil, exponiéndolos al agente oxidante en una única dirección. Los gasificadores fluidizados, en cambio, emplean partículas inertes para crear un lecho que se '; 'comporta como un fluido, permitiendo una conversión eficiente. Los gasificadores ascendentes hacen que el material sólido se mueva hacia arriba en un flujo ascendente '; 'de gas, mientras que los descendentes tienen un flujo descendente de material sólido, con el agente oxidante ingresando desde arriba. Cada tipo de gasificador tiene '; 'aplicaciones específicas en la producción de gas de síntesis, energía y productos químicos.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proceso_gasificador(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end